classdef ck_rc3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ClusterDefinitionTab            matlab.ui.container.Tab
        exemplarydatafileLabel          matlab.ui.control.Label
        exemplarydatafileEditField      matlab.ui.control.EditField
        exemplarydatadirButton          matlab.ui.control.Button
        DefineClusterButton             matlab.ui.control.Button
        DefineNetworkButton             matlab.ui.control.Button
        deletenetworkdefinitionsfromClusterstructButton  matlab.ui.control.Button
        removedeselectclustersfromallsubjectsButton  matlab.ui.control.Button
        selectClustermatingroupdirectoryLabel  matlab.ui.control.Label
        DataimportTab                   matlab.ui.container.Tab
        g1dirdataimportButton           matlab.ui.control.Button
        g2dirdataimportButton           matlab.ui.control.Button
        g1dirdataimportListBox          matlab.ui.control.ListBox
        g2dirdataimportListBox          matlab.ui.control.ListBox
        rootdirgroup1EditField_2Label   matlab.ui.control.Label
        g1dirdataimportEditField        matlab.ui.control.EditField
        rootdirgroup2EditField_2Label   matlab.ui.control.Label
        g2dirdataimportEditField        matlab.ui.control.EditField
        subdirnameEditField_2Label      matlab.ui.control.Label
        g1dataimportsubdirnameEditField  matlab.ui.control.EditField
        OptionsforvoxelextractionLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        extractallvoxelinClusterCheckBox  matlab.ui.control.CheckBox
        savedasClusteriYLabel           matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        extractconditionalvoxelCheckBox  matlab.ui.control.CheckBox
        savedasClussteriYc1Label        matlab.ui.control.Label
        graymatterprobgreaterasEditFieldLabel  matlab.ui.control.Label
        graymatterprobgreaterasEditField  matlab.ui.control.NumericEditField
        whitematterproblessthenEditFieldLabel  matlab.ui.control.Label
        whitematterproblessthenEditField  matlab.ui.control.NumericEditField
        CSFproblessthenEditFieldLabel   matlab.ui.control.Label
        CSFproblessthenEditField        matlab.ui.control.NumericEditField
        graymatterfilenameEditFieldLabel  matlab.ui.control.Label
        graymatterfilenameEditField     matlab.ui.control.EditField
        whitematterfilenameEditFieldLabel  matlab.ui.control.Label
        whitematterfilenameEditField    matlab.ui.control.EditField
        csffilenameEditFieldLabel       matlab.ui.control.Label
        csffilenameEditField            matlab.ui.control.EditField
        Lamp                            matlab.ui.control.Lamp
        testforfilepresenceButton       matlab.ui.control.Button
        extract2conditionalvoxelCheckBox  matlab.ui.control.CheckBox
        graymatterprobgreaterasEditField_2Label  matlab.ui.control.Label
        graymatterprobgreateras2EditField  matlab.ui.control.NumericEditField
        whitematterproblessthenEditField_2Label  matlab.ui.control.Label
        whitematterproblessthen2EditField  matlab.ui.control.NumericEditField
        CSFproblessthenEditField_2Label  matlab.ui.control.Label
        CSFproblessthen2EditField       matlab.ui.control.NumericEditField
        savedasClussteriYc2Label        matlab.ui.control.Label
        updatedataimportButton          matlab.ui.control.Button
        filefilterLabel                 matlab.ui.control.Label
        dataimportfilefilterEditField   matlab.ui.control.EditField
        DatapropertiesPanel             matlab.ui.container.Panel
        TRinsecEditFieldLabel           matlab.ui.control.Label
        TREditField                     matlab.ui.control.NumericEditField
        lpfEditFieldLabel               matlab.ui.control.Label
        lpfEditField                    matlab.ui.control.NumericEditField
        hpfEditFieldLabel               matlab.ui.control.Label
        hpfEditField                    matlab.ui.control.NumericEditField
        addsuffixtofoldernameButton     matlab.ui.control.Button
        Button_select_subdir            matlab.ui.control.Button
        extractclustersPanel            matlab.ui.container.Panel
        GOButton                        matlab.ui.control.Button
        appendextractedclusterstoexistingClustermatPanel  matlab.ui.container.Panel
        usedifferentClustermatCheckBox  matlab.ui.control.CheckBox
        sel_diff_clustermatimportButton  matlab.ui.control.Button
        ClustermatEditFieldLabel        matlab.ui.control.Label
        diffClustermatEditField         matlab.ui.control.EditField
        CheckBox_append_cluster         matlab.ui.control.CheckBox
        Panel_3                         matlab.ui.container.Panel
        Button_check_cluster_subfolder  matlab.ui.control.Button
        datafoundLampLabel              matlab.ui.control.Label
        datafoundLamp                   matlab.ui.control.Lamp
        Button_add_cluster_subfolder    matlab.ui.control.Button
        subfolderEditFieldLabel         matlab.ui.control.Label
        subfolder4surfEditField         matlab.ui.control.EditField
        ClustermatfilefilterLabel       matlab.ui.control.Label
        ffilter4surfEditField           matlab.ui.control.EditField
        subdirnameEditField_2Label_2    matlab.ui.control.Label
        g2dataimportsubdirnameEditField  matlab.ui.control.EditField
        connectivityestimationTab       matlab.ui.container.Tab
        estimateconnectivityButton      matlab.ui.control.Button
        estimationoptionsPanel          matlab.ui.container.Panel
        Cluster2VoxelCheckBox           matlab.ui.control.CheckBox
        Cluster2ClusterCheckBox         matlab.ui.control.CheckBox
        Voxel2VoxelCheckBox             matlab.ui.control.CheckBox
        C1maskthresholdCheckBox         matlab.ui.control.CheckBox
        thresholdEditFieldLabel         matlab.ui.control.Label
        thresholdEditField              matlab.ui.control.NumericEditField
        smoothingCheckBox               matlab.ui.control.CheckBox
        kernelEditFieldLabel            matlab.ui.control.Label
        kernelEditField                 matlab.ui.control.EditField
        MethodsPanel                    matlab.ui.container.Panel
        synchronizationPanel            matlab.ui.container.Panel
        readmesynchronizationButton     matlab.ui.control.Button
        histogramEditField_2Label       matlab.ui.control.Label
        histogramsynchronizationEditField  matlab.ui.control.NumericEditField
        complexityPanel                 matlab.ui.container.Panel
        windowlengthEditFieldLabel      matlab.ui.control.Label
        windowlengthcomplexityEditField  matlab.ui.control.NumericEditField
        similaritymeasureEditFieldLabel  matlab.ui.control.Label
        similaritymeasurecomplexityEditField  matlab.ui.control.NumericEditField
        readmecomplexityButton          matlab.ui.control.Button
        transinformationPanel           matlab.ui.container.Panel
        readmetransinformationButton    matlab.ui.control.Button
        histogramEditFieldLabel         matlab.ui.control.Label
        histogramEditField              matlab.ui.control.NumericEditField
        timelagEditFieldLabel           matlab.ui.control.Label
        timelagEditField                matlab.ui.control.NumericEditField
        complexitySwitch                matlab.ui.control.ToggleSwitch
        correlationsPanel               matlab.ui.container.Panel
        coherencePanel                  matlab.ui.container.Panel
        readmecoherenceButton           matlab.ui.control.Button
        partialcoherencePanel           matlab.ui.container.Panel
        readmepartialcoherenceButton    matlab.ui.control.Button
        synchronizationSwitch           matlab.ui.control.ToggleSwitch
        transinformationSwitch          matlab.ui.control.ToggleSwitch
        partialcoherenceSwitch          matlab.ui.control.ToggleSwitch
        coherenceSwitch                 matlab.ui.control.ToggleSwitch
        correlationSwitch               matlab.ui.control.ToggleSwitch
        grangerSwitch                   matlab.ui.control.ToggleSwitch
        GrangercausalitybigPanel        matlab.ui.container.Panel
        GrangercausalityPanel           matlab.ui.container.Panel
        conditionalGCinthetimedomainCheckBox  matlab.ui.control.CheckBox
        additionalpreprocessingPanel    matlab.ui.container.Panel
        grangerdetrenddataCheckBox      matlab.ui.control.CheckBox
        grangerremovetemporalmeanCheckBox  matlab.ui.control.CheckBox
        grangerremovetemporalmeananddividebystdCheckBox  matlab.ui.control.CheckBox
        grangerdifferencingtimeseriesCheckBox  matlab.ui.control.CheckBox
        iterationEditFieldLabel         matlab.ui.control.Label
        grangeriterationEditField       matlab.ui.control.NumericEditField
        readmegrangerpreprocessingButton  matlab.ui.control.Button
        estimategrangerautonomyCheckBox  matlab.ui.control.CheckBox
        conditionalGCinfrequencydomainCheckBox  matlab.ui.control.CheckBox
        readmegrangerpreprocessingButton_2  matlab.ui.control.Button
        CGCtimeparameterPanel           matlab.ui.container.Panel
        numberoflagsEditFieldLabel      matlab.ui.control.Label
        grangernumberoflagsEditField    matlab.ui.control.NumericEditField
        pEditFieldLabel                 matlab.ui.control.Label
        grangerpEditField               matlab.ui.control.NumericEditField
        frequencyparameterPanel         matlab.ui.container.Panel
        frequencyiterationstepEditFieldLabel  matlab.ui.control.Label
        grangerfrequencyiterationstepEditField  matlab.ui.control.NumericEditField
        frequencyEditFieldLabel         matlab.ui.control.Label
        grangerfrequencyEditField       matlab.ui.control.EditField
        selectedClusterListBoxLabel     matlab.ui.control.Label
        grangerselectedClusterListBox   matlab.ui.control.ListBox
        usedataPanel                    matlab.ui.container.Panel
        conuseYCheckBox                 matlab.ui.control.CheckBox
        conuseYc1CheckBox               matlab.ui.control.CheckBox
        conuseYc2CheckBox               matlab.ui.control.CheckBox
        sb_connTab                      matlab.ui.container.Tab
        GrangerPanel                    matlab.ui.container.Panel
        estimateGCButton                matlab.ui.control.Button
        performGCanalysisusingsb_gen_connectivityfunctionLabel  matlab.ui.control.Label
        GCmodelorderEditFieldLabel      matlab.ui.control.Label
        GCmodelorderEditField           matlab.ui.control.NumericEditField
        splitYeveryscanEditFieldLabel   matlab.ui.control.Label
        splitYeveryscanEditField        matlab.ui.control.NumericEditField
        CheckBox                        matlab.ui.control.CheckBox
        iButton_4                       matlab.ui.control.Button
        TRENTOOLTransferEntropyPanel    matlab.ui.container.Panel
        estimateTEButton                matlab.ui.control.Button
        performGCanalysisusingsb_gen_connectivityfunctionLabel_2  matlab.ui.control.Label
        uvecscansEditFieldLabel         matlab.ui.control.Label
        uvecscansEditField              matlab.ui.control.EditField
        TRLabel                         matlab.ui.control.Label
        EditField_TR_TEsb               matlab.ui.control.NumericEditField
        ustepsLabel                     matlab.ui.control.Label
        EditField_ustep_TEsb            matlab.ui.control.NumericEditField
        CheckBox_2                      matlab.ui.control.CheckBox
        splitYeveryscanEditField_2Label  matlab.ui.control.Label
        splitYeveryscanEditField_TE     matlab.ui.control.NumericEditField
        iButton_2                       matlab.ui.control.Button
        inspectclusterPanel             matlab.ui.container.Panel
        inspectclusterButton            matlab.ui.control.Button
        clusternamessbListBoxLabel      matlab.ui.control.Label
        clusternamessbListBox           matlab.ui.control.ListBox
        dataListBoxLabel                matlab.ui.control.Label
        dataListBox                     matlab.ui.control.ListBox
        ASDFTEPanel                     matlab.ui.container.Panel
        ASDFTEButton                    matlab.ui.control.Button
        TRLabel_2                       matlab.ui.control.Label
        EditField_TR_TEsb_2             matlab.ui.control.NumericEditField
        uvecscansEditField_2Label       matlab.ui.control.Label
        uvecscansEditField_2            matlab.ui.control.EditField
        peakcutoffLabel                 matlab.ui.control.Label
        EditField_peakcutoff_TEsb_3     matlab.ui.control.NumericEditField
        viewindidvidualresultsCheckBoxsb  matlab.ui.control.CheckBox
        iButton                         matlab.ui.control.Button
        TransferentropyLeeetal2011Panel  matlab.ui.container.Panel
        TEButton_schreiber              matlab.ui.control.Button
        parameteru_minu_maxlwEditFieldLabel  matlab.ui.control.Label
        parameteru_minu_maxlwEditField  matlab.ui.control.EditField
        viewindidvidualresultsCheckBoxsb_2  matlab.ui.control.CheckBox
        ProbabilitydensityestimationDropDownLabel  matlab.ui.control.Label
        ProbabilitydensityestimationDropDown  matlab.ui.control.DropDown
        iButton_3                       matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        datareductionTab                matlab.ui.container.Tab
        deleteifpossiblePanel           matlab.ui.container.Panel
        YCheckBox                       matlab.ui.control.CheckBox
        Yc1CheckBox                     matlab.ui.control.CheckBox
        Yc2CheckBox                     matlab.ui.control.CheckBox
        newprefixforClustermatfilesEditFieldLabel  matlab.ui.control.Label
        newprefixforClustermatfilesEditField  matlab.ui.control.EditField
        reduceClusterdataButton         matlab.ui.control.Button
        ExplanationTextAreaLabel        matlab.ui.control.Label
        ExplanationTextArea             matlab.ui.control.TextArea
        mergeselected2SubjecttooneSubjectButton  matlab.ui.control.Button
        prefixEditFieldLabel            matlab.ui.control.Label
        prefixmergeEditField            matlab.ui.control.EditField
        visualizationTab                matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        Cluster2Cluster                 matlab.ui.container.Tab
        loadeddataPanel                 matlab.ui.container.Panel
        C2CloaddataButton               matlab.ui.control.Button
        C2CloaddataLamp                 matlab.ui.control.Lamp
        group1ListBoxLabel              matlab.ui.control.Label
        C2Cgroup1ListBox                matlab.ui.control.ListBox
        group2ListBoxLabel              matlab.ui.control.Label
        C2Cgroup2ListBox                matlab.ui.control.ListBox
        C2CnormalizeCheckBox            matlab.ui.control.CheckBox
        C2CestimateClusterstatisticButton  matlab.ui.control.Button
        C2CstatisticButtonGroup         matlab.ui.container.ButtonGroup
        C2CtwosampleButton              matlab.ui.control.ToggleButton
        C2CpairedButton                 matlab.ui.control.ToggleButton
        C2CestimatedataLamp             matlab.ui.control.Lamp
        C2CDatatouseButtonGroup         matlab.ui.container.ButtonGroup
        C2CYButton                      matlab.ui.control.RadioButton
        C2CYc1Button                    matlab.ui.control.RadioButton
        C2CYc2Button                    matlab.ui.control.RadioButton
        C2CestimateClusterstatisticButton_2  matlab.ui.control.Button
        C2CloaddataButton_2             matlab.ui.control.Button
        C2CshownetworksButton           matlab.ui.control.Button
        sbviewresultsPanel              matlab.ui.container.Panel
        connectivityresultsPanel        matlab.ui.container.Panel
        GCviewresultsButton             matlab.ui.control.Button
        ASDFTEviewresultsButton         matlab.ui.control.Button
        C2CloaddataLamp_GCsb            matlab.ui.control.Lamp
        C2CloaddataLamp_ASDFsb          matlab.ui.control.Lamp
        C2CmeanZdiffButton              matlab.ui.control.Button
        C2CloaddataLamp_Corrsb          matlab.ui.control.Lamp
        C2CloaddataLamp_schreiber_sb    matlab.ui.control.Lamp
        TEleeetalviewresultsButton      matlab.ui.control.Button
        C2CloaddataLamp_TE_trentool_results  matlab.ui.control.Lamp
        TETRENToolresultsButton         matlab.ui.control.Button
        settingsButtonGroup             matlab.ui.container.ButtonGroup
        sButton                         matlab.ui.control.ToggleButton
        pairedButton                    matlab.ui.control.ToggleButton
        pvalueEditFieldLabel            matlab.ui.control.Label
        pvalue_brain_conn_EditField     matlab.ui.control.NumericEditField
        nonparametricstatisticsCheckBoxsb  matlab.ui.control.CheckBox
        PermutationsLabel               matlab.ui.control.Label
        perm_brain_conn_EditField       matlab.ui.control.NumericEditField
        BehaviorvsBrainconnectivitywithEXELdataPanel  matlab.ui.container.Panel
        ExelFileEditFieldLabel          matlab.ui.control.Label
        ExelFileEditField               matlab.ui.control.EditField
        C2CchooseexelfileButton         matlab.ui.control.Button
        C2CexelreadmeButton             matlab.ui.control.Button
        loadexelfileButton              matlab.ui.control.Button
        ExelvariablesListBoxLabel       matlab.ui.control.Label
        ExelvariablesListBox            matlab.ui.control.ListBox
        C2CestimatecorrelationButton    matlab.ui.control.Button
        visualizecorrelationButton      matlab.ui.control.Button
        C2CestimateexelcorrelationLamp  matlab.ui.control.Lamp
        C2CvisualizenetworkcorrelationButton  matlab.ui.control.Button
        C2CestimatenetworkcorrelationButton  matlab.ui.control.Button
        C2CvisualizenetworkcorrelationButton_v3  matlab.ui.control.Button
        Cluster2Voxel                   matlab.ui.container.Tab
        Voxel2Voxel                     matlab.ui.container.Tab
        Cluster2Cluster_Granger         matlab.ui.container.Tab
        GlobalParameterPanel            matlab.ui.container.Panel
        resultsrootdirEditFieldLabel    matlab.ui.control.Label
        resultsdirEditField             matlab.ui.control.EditField
        Buttonchooseresultsdir          matlab.ui.control.Button
        Buttonchooseg1dir               matlab.ui.control.Button
        group1dirEditFieldLabel         matlab.ui.control.Label
        g1dirEditField                  matlab.ui.control.EditField
        chooseg2dir                     matlab.ui.control.Button
        group2dirEditFieldLabel         matlab.ui.control.Label
        g2dirEditField                  matlab.ui.control.EditField
        loadlastButton                  matlab.ui.control.Button
        loadButton                      matlab.ui.control.Button
        saveButton                      matlab.ui.control.Button
        MultiprocessoroptionsCheckBox   matlab.ui.control.CheckBox
        initnewButton                   matlab.ui.control.Button
        idleGaugeLabel                  matlab.ui.control.Label
        idleGauge                       matlab.ui.control.LinearGauge
        saveasButton                    matlab.ui.control.Button
        g1ListBox                       matlab.ui.control.ListBox
        g2ListBox                       matlab.ui.control.ListBox
        group1directoryLabel            matlab.ui.control.Label
        group2directoryLabel            matlab.ui.control.Label
        updateButton                    matlab.ui.control.Button
    end


    properties (Access = private)
        IDlist % Liste mit den IDs der Subjects
        exeltitel % Description
        exeldata
        C2C_stat_g1 % Description
        C2C_stat_g1_org
        C2C_stat_g2 % Description
        C2C_stat_g2_org
        C2C_stat_g1_des
        C2C_stat_g2_des
        Property8 % Description
        C2C_stat % Description
        namelist1
        namelist2% Description
        cfg % sb function handle
    end

    methods (Access = private)
        
        function stat = listboxupdate(app)
            stat = 1;
            fprintf('updating listbox\n');


            g1 = dir([app.g1dirEditField.Value filesep '*.mat']);
            g2 = dir([app.g2dirEditField.Value filesep '*.mat']);
            
            listg1{1}='empty';
            idxg1 = 1;
            listg2{1}='empty';
            idxg2 = 1;
            
            Valg1(1)=1;
            Valg2(1)=1;
            for i=1:size(g1,1)
                listg1{i} = g1(i,1).name;
            end
            for i=1:size(g2,1)
                listg2{i} = g2(i,1).name;
            end
            app.g1ListBox.Items=listg1;
            app.g2ListBox.Items=listg2;
            
            % if idxg1>1
            %     set(handles.listboxg1,'Value',Valg1);
            % else
            %     set(handles.listboxg1,'Value',1);
            % end
            % if idxg2>1
            %     set(handles.listboxg2,'Value',Valg2);
            % else
            %     set(handles.listboxg2,'Value',1);
            % end
            %
            %save(fullfile(fileparts(which('ck_rc2.m')),'lastprocessingdir.mat'),'lastprocessingdir');
            % speichere REchnerspezifisch unter Hilfe der MAC Adresse des REchners
            
            %[~,result] = dos('getmac')
            %mac = result(160:176);
            mac = '123456789101010';
            try
                load(fileparts(which(which('ck_rc3.mlapp')),['lastsession_' mac '.mat']),'lastsession');
                
                cd(app.resultsdirEditField.Value);
            catch
                lastsession.resultsdir = app.resultsdirEditField.Value;
                cd(app.resultsdirEditField.Value);
            end
            lastsession.resultsdir = app.resultsdirEditField.Value;
            lastsession.datadirg1 = app.g1dirEditField.Value;
            lastsession.datadirg2 = app.g2dirEditField.Value;
            %     lastsession.multifileg1 = handles.multifileg1;
            %     lastsession.multifileg2 = handles.multifileg2;
            savefile = fullfile(fileparts(which('ck_rc3.mlapp')),['lastsession_' mac '.mat']);
            save(savefile,'lastsession');
            parameterfile = fullfile(app.resultsdirEditField.Value,'parameter.mat');
            try
                load(parameterfile);
            catch
                parameter = struct;
            end
            parameter.outdir = app.resultsdirEditField.Value;
            parameter.datadirg1 = app.g1dirEditField.Value;
            parameter.datadirg2 = app.g2dirEditField.Value;
            try
                save(parameterfile,'parameter');
            catch
                fprintf('fehler beim Speichern des Parameterfiles\n');
            end
            
        end
        
        function C = del_network(app,C)
            for i=1:length(C)
                if isfield(C{i},'net')
                    C{i} = rmfield(C{i},'net');
                end
                if isfield(C{i},'group')
                    C{i} = rmfield(C{i},'group');
                end
                if isfield(C{i},'groupname')
                    C{i} = rmfield(C{i},'groupname');
                end
                if isfield(C{i},'groupm')
                    C{i} = rmfield(C{i},'groupm');
                end
                if isfield(C{i},'groupnamem')
                    C{i} = rmfield(C{i},'groupnamem');
                end
                if isfield(C{i},'num_global_networks')
                    C{i} = rmfield(C{i},'num_global_networks');
                end
                if isfield(C{i},'num_local_networks')
                    C{i} = rmfield(C{i},'num_local_networks');
                end
            end
            
        end
        
        function cell_list = get_updatedataimport_list(app,rootdir,filter)
            %rootdir = 'H:\data_ck\Vesti\data';
            %filter = 'filter_rs6';
            cell_list = {};
            dx = dir(rootdir);
            idx = 0;
            %dx2 = dx;
            for i=1:length(dx)
                if dx(i).isdir && ~strcmp(dx(i).name,'.') && ~strcmp(dx(i).name,'..')
                    idx = idx + 1;
                    dx2(idx)=dx(i);
                end
            end
            %assignin('base','dx2',dx2);
            
            idx = 0;
            if ~exist('dx2'), return, end;    %sb030518
            for i=1:length(dx2)
                dtmp = dir([rootdir, filesep ,dx2(i).name]);
                subdir_exist = 0;
                for j=1:length(dtmp)
                    
                    if dtmp(j).isdir && ~strcmp(dtmp(j).name,'.') && ~strcmp(dtmp(j).name,'..')
                        
                        if strcmp(dtmp(j).name,filter)
                            %fprintf('korrektes unterverzeichnis gefunden %.0d %.0d\n',i,j)
                            subdir_exist = 1;
                        end
                    end
                end
                if subdir_exist
                    idx = idx + 1;
                    rel_subdirs(idx)=dx2(i);
                    cell_list{idx} = dx2(i).name;
                end
                
            end
            
        end
        
        function file_list = get_full_subjects_filenames(app,path,Items,subdir)
            file_list = [];
            %subdir = app.g1dataimportsubdirnameEditField.Value;
            filefilter = app.dataimportfilefilterEditField.Value;
            for i=1:length(Items)
                p = [path filesep Items{i} filesep subdir filesep];
                tmp = [path filesep Items{i} filesep subdir filesep filefilter];
                files = dir(tmp);
                for j=1:length(files)
                    name = fullfile(files(j).folder,files(j).name);
                    file_list{i}(j,1:length(name))=name;
                end
                %file_list{i} = spm_vol(name);
%                path_list{i}=[path filesep Items{i} filesep subdir];
            end
            assignin('base','file_list',file_list);
        end


        function error = test_existence(app,rootdir,pathlist, subdirname)
            error = 0;
            
            for i=1:length(pathlist)
                %p = [rootdir filesep pathlist{i} filesep app.g1dataimportsubdirnameEditField.Value];
                p = [rootdir filesep pathlist{i} filesep subdirname];
                

%                pf = Vm{i}(1,1).fname; 
%                [p,f] = fileparts(pf);

                e1 = exist(fullfile(p,app.graymatterfilenameEditField.Value));
                e2 = exist(fullfile(p,app.whitematterfilenameEditField.Value));
                e3 = exist(fullfile(p,app.csffilenameEditField.Value));
               
                if (e1+e2+e3)~=6
                    fprintf('no c1-2-3 files were found in folder %s \n',p);
                    error = 1;
                end
            end
        end
        
        function [listg1,listg2] = get_all_selected_filenames(app,string)
            
            d1 = [app.g1dirEditField.Value,filesep'];
            d2 = [app.g2dirEditField.Value,filesep'];
            
            listg1 = app.g1ListBox.Value;
            listg2 = app.g2ListBox.Value;
            
            if strcmp(string,'full')
                listg1 = strcat(d1,listg1);
                listg2 = strcat(d2,listg2);
            end

        end
        
        function Cluster = reduce_Cluster(app,filelist,prefix)
            % entfernt die Y Daten und speichert die Cluster unter neuem Namen ab
            
            for i=1:length(filelist)
                fprintf('reduce_Cluster %.0d / %.0d subjects\n',i,length(filelist));
                file = filelist{i};
                [p,f,e] = fileparts(file);
                newfilename = fullfile(p,[prefix, f, e]);
                load(file); % load Cluster
                for j=1:length(Cluster)
                    
                    if isfield(Cluster{j},'Y') && app.YCheckBox.Value
                        Cluster{j} = rmfield(Cluster{j},'Y');
                    end
                    if isfield(Cluster{j},'Yc1') && app.Yc1CheckBox.Value
                        Cluster{j} = rmfield(Cluster{j},'Yc1');
                    end
                    if isfield(Cluster{j},'Yc2') && app.Yc2CheckBox.Value
                        Cluster{j} = rmfield(Cluster{j},'Yc2');
                    end
                end
                save(newfilename,'Cluster');
                
            end
            
        end
        
        function C2C_stat_g1 = deslect_subjects_ck(app,C2C_stat_g1,namelist,idx)
            %fprintf('deselect subjects\n');
            if length(idx)==0
                return;
            end
            %C2C_stat_g1.Z
            
            
            if isfield(C2C_stat_g1,'R'); C2C_stat_g1.R(:,:,idx)=[]; end
            if isfield(C2C_stat_g1,'P'); C2C_stat_g1.P(:,:,idx)=[];  end
            if isfield(C2C_stat_g1,'Z'); C2C_stat_g1.Z(:,:,idx)=[]; end
            if isfield(C2C_stat_g1,'Syn'); C2C_stat_g1.Syn(:,:,idx)=[]; end
            if isfield(C2C_stat_g1,'PLV'); C2C_stat_g1.PLV(:,:,idx)=[]; end
            if isfield(C2C_stat_g1,'T'); C2C_stat_g1.T(:,:,:,idx)=[]; end
            if isfield(C2C_stat_g1,'Coh'); C2C_stat_g1.Coh(:,:,:,idx)=[]; end
            if isfield(C2C_stat_g1,'CohF'); C2C_stat_g1.CohF(:,:,:,idx)=[]; end
            if isfield(C2C_stat_g1,'Power'); C2C_stat_g1.Power(:,idx)=[]; end
            if isfield(C2C_stat_g1,'Comp'); C2C_stat_g1.Comp(:,idx)=[]; end
            if isfield(C2C_stat_g1,'RiN'); C2C_stat_g1.RiN(:,:,idx)=[]; end
            if isfield(C2C_stat_g1,'RN'); C2C_stat_g1.RN(:,idx)=[]; end
            if isfield(C2C_stat_g1,'ZiN'); C2C_stat_g1.ZiN(:,:,idx)=[]; end
            if isfield(C2C_stat_g1,'ZN'); C2C_stat_g1.ZN(:,idx)=[]; end
            if isfield(C2C_stat_g1,'namelist'); C2C_stat_g1.namelist = namelist; end


        end
        
        function unsel = get_unselected_idx(app,listbox)
            tmpall = listbox.Items;
            tmpsel = listbox.Value;
            tmp = ismember(tmpall,tmpsel)==0;
            unsel = find(tmp);
        end
        
        function C2C = make_C2C_group_struct(app,dir,namelist,postfix)
            % postfix definiert die Daten die rausgeschrieben werden Y Yc1, Yc2
            h=waitbar(0,'Please wait');
            
            % gibt es eine Netzwerkdefinition ?
            load(fullfile(app.resultsdirEditField.Value,'Cluster.mat'));
            if isfield(Cluster,'net')
                net =1;
            end
            
            load(fullfile(dir,namelist{1,1}));
            C2C.R=zeros(size(Cluster,1),size(Cluster,1),length(namelist));
            C2C.P=zeros(size(Cluster,1),size(Cluster,1),length(namelist));
            C2C.Z=zeros(size(Cluster,1),size(Cluster,1),length(namelist));
            C2C.Syn=zeros(size(Cluster,1),size(Cluster,1),length(namelist));
            %C2C.T=zeros(size(Cluster,1),1,size(Cluster,1),length(namelist));
            C2C.PLV=zeros(size(Cluster,1),size(Cluster,1),length(namelist));
            Power=zeros(size(Cluster,1),length(namelist));
            clusterXYZ = cell(0);
            
            
            GC_sb = []; %added by sb
            TE_asdf = [];%added by sb
            TE_schreiber = [];TEprep = [];
            for i=1:length(namelist)
                waitbar(i / length(namelist));
                fprintf('load Cluster %s \n',fullfile(dir,namelist{i}));
                load(fullfile(dir,namelist{i}));
                % die Clusternamen und Koordinaten werden aus einem
                % Cluster extrahiert (alle Cluster müssen natürlich gleich sein)
                %fprintf('%d\n',size(Cluster,1));
                %fprintf('loading %s\n',namelist{i});
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%added by sb
                if isfield(Cluster{1}, 'GC');
                   if isempty(GC_sb)
                       GC_sb = Cluster{1}.GC;
                   else
                       [dim1, dim2, dim3] = size(GC_sb.GrangerC) ;
                       GC_sb.GrangerC(:,:,dim3+1) = Cluster{1}.GC.GrangerC;
                       GC_sb.sig(:,:,dim3+1) = Cluster{1}.GC.sig;
                   end
                   if i ==length(namelist)
                      C2C.GC_sb = GC_sb; 
                      app.C2CloaddataLamp_GCsb.Color = 'green';
                   end
                end
                
                if isfield(Cluster{1}, 'TE_asdf');
                    if isempty(TE_asdf)
                        TE_asdf = Cluster{1}.TE_asdf;
                    else
                        [dim1, dim2, dim3] = size(TE_asdf.peakTE) ;
                        TE_asdf.peakTE(:,:,dim3+1) = Cluster{1}.TE_asdf.peakTE;
                    end
                    if i ==length(namelist)
                        C2C.TE_asdf = TE_asdf;
                        app.C2CloaddataLamp_ASDFsb.Color = 'green';
                    end
                end
                
                 if isfield(Cluster{1}, 'TE_schreiber');
                     if isempty(TE_schreiber)
                         TE_schreiber = Cluster{1}.TE_schreiber;
                     else
                         [dim1, dim2, dim3] = size(TE_schreiber.TE) ;
                         TE_schreiber.TE(:,:,dim3+1) = Cluster{1}.TE_schreiber.TE;
                     end
                     if i ==length(namelist)
                         C2C.TE_schreiber = TE_schreiber;
                         app.C2CloaddataLamp_schreiber_sb.Color = 'green';
                     end
                 end
                  
                  if isfield(Cluster{1}, 'TEprep');
                      Ttmp = mean(Cluster{1}.TEprep.TEmat,2, 'omitnan');
                      if isempty(TEprep)
                          TEprep = Cluster{1}.TEprep;
                          TEprep.TEmat = Ttmp;
                      else
                          [dim1, dim2, dim3] = size(TEprep.TEmat) ;
                          TEprep.TEmat(:,:,dim3+1) = Ttmp;
                      end
                      if i ==length(namelist)
                          C2C.TEprep = TEprep;
                          app.C2CloaddataLamp_TE_trentool_results.Color = 'green';
                      end
                  end
                
                %%END added by sb
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                for j=1:size(Cluster,1)
                    clusternames{j,1}=Cluster{j,1}.name;
                    
                    if isfield(Cluster{j,1},'XYZ')
                        clusterXYZ{j,1}=Cluster{j,1}.XYZ;
                    end
                    %assignin('base','Cluster',Cluster);
                    if ~isfield(Cluster{j,1},'Cluster2Cluster')
                        error('please estimate C2C connectivity first (no Cluster2Cluster field in Cluster struct)');
                        
                    end
                   
                    if isfield(Cluster{j,1}.Cluster2Cluster,['R', postfix])
                        C2C.R(:,j,i)=Cluster{j,1}.Cluster2Cluster.(['R', postfix]);
                        C2C.P(:,j,i)=Cluster{j,1}.Cluster2Cluster.(['P', postfix]);
                        C2C.Z(:,j,i)=Cluster{j,1}.Cluster2Cluster.(['Z', postfix]);
                        app.C2CloaddataLamp_Corrsb.Color = 'green';
                    end
                    if isfield(Cluster{j,1}.Cluster2Cluster,(['T', postfix]))
                        Tx = Cluster{j,1}.Cluster2Cluster.(['T', postfix]);
                        C2C.T(:,1:size(Tx,1),j,i)=Tx';
                    end
                    % hier manchmal komplexe Zahlen ... evtl bei merkwuerdiegen Y_mean eingabe
                    try
                    if isfield(Cluster{j,1}.Cluster2Cluster,(['Coh', postfix]))
                        Coh = Cluster{j,1}.Cluster2Cluster.(['Coh', postfix]);
                        CohF = Cluster{j,1}.Cluster2Cluster.(['CohF', postfix]);
                        C2C.Coh(:,1:size(Coh,2),j,i)=Coh;
                        C2C.CohF(:,1:size(Coh,2),j,i)=CohF;
                    end
                    catch
                        fprintf('choerence konnte nicht berechnet werden warscheinlich Y_mean ungeeignet\n');
                    end
                    if isfield(Cluster{j,1}.Cluster2Cluster,(['Syn', postfix]))
                        C2C.Syn(:,j,i)=Cluster{j,1}.Cluster2Cluster.(['Syn', postfix]);
                    end
                    if isfield(Cluster{j,1}.Cluster2Cluster,(['PLV', postfix]))
                        C2C.PLV(:,j,i)=Cluster{j,1}.Cluster2Cluster.(['PLV', postfix]);
                    end
                    if isfield(Cluster{j,1}.Cluster2Cluster,(['Comp', postfix]))
                        C2C.Comp(j,i)=Cluster{j,1}.Cluster2Cluster.(['Comp', postfix]);
                    end
                    Y_mean = Cluster{j,1}.(['Y', postfix, '_mean']);
                    C2C.Power(j,i)=sum(Y_mean.^2)/(length(Y_mean)*2+1);
                    
                    
                    % Netzwerk einfuegen 
                    % C2C.RiN
                    % C2C.ZiN
                    
                end
                
            end
            close(h)
            C2C.clusternames=clusternames;
            C2C.clusterXYZ=clusterXYZ;
            C2C.namelist = namelist;
        end
        
        function idx = get_rows_of_subjects(app,IDlist,names)
            idx= [];
            k = 1;
            for i=1:length(names)
                
                assignin('base','names',names);
                id = str2num(names{i}(end-7:end-4));
                if isempty(id)
                    fprintf('\tno valid subject id in filename: %s\n', names{i});
                else
                    tmp = find(IDlist==id);
                    if isempty(tmp)
                        
                        sprintf(str,'Patient ID not found %s ',id);
                        msgdlg(str);
                    else
                    idx(k) = tmp;
                    k = k + 1;
                    end
                end
            end
        end
        
        
        % berechnet die Korrelation zwischen Konnektivitaet und behavioralen Daten
        %function [R_3d,R,P,clusternames] = get_Rg(d,group,B,handles)
        function [G] = get_Rg(app, d, group, B)
            % berechnet die Korrelation zwischen Konnektivitaet und behavioralen Daten
            %function [R_3d,R,P,clusternames] = get_Rg(d,group,B,handles)
            % gibt die Variable G zurueck mit den Eintraegen
            % G.R_3d
            % G.R
            % G.P
            % G.clusternames
            % falls vorhanden noch folgende weitere
            % G.Syn
            % G.PLV
            % G.
            assignin('base','B',B);
            file = fullfile(d,'C2C_stat.mat');
            try
                load(file)
            catch
                msgbox('please estimate Cluster statistic first for group 2\n');
            end
            
            if group==1
                C2C = app.C2C_stat_g1_des;
            elseif group==2
                C2C = app.C2C_stat_g2_des;
            end
            assignin('base','C2C_stat_g1_des',app.C2C_stat_g1_des);
            R_3d = C2C.R;
            clusternames = C2C.clusternames;
            G.R_3d = R_3d;
            % berechne Korrelation zweichen Person und behavioralen Daten
            [G.RR, G.RP] = get_conn_R_B(app,R_3d, B);
            
            if isfield(C2C,'Syn')
                G.Syn_3d = C2C.Syn;
                % berechne Korrelation zwischen Synchronization yund behavioralen Daten
                [G.SynR, G.SynP] = get_conn_R_B(app,G.Syn_3d, B);
            end
            if isfield(C2C,'PLV')
                G.PLV_3d = C2C.PLV;
                [G.PLVR, G.PLVP] = get_conn_R_B(app,G.PLV_3d, B);
            end
            if isfield(C2C,'T')
                G.T_4d = C2C.T;
                for i = 1:size(G.T_4d,2)
                    [G.TR(:,:,:,i), G.TP(:,:,:,i)] = get_conn_R_B(app,squeeze(G.T_4d(:,i,:,:)), B);
                end
            end
            
            try
            % Coherence
            if isfield(C2C,'Coh')
                G.Coh_4d = C2C.Coh;
                % frequenzen
                G.CohF = squeeze(C2C.CohF(1,:,1,1));
                for i = 1:size(G.Coh_4d,2)
                    [G.CohR(:,:,:,i), G.CohP(:,:,:,i)] = get_conn_R_B(app,squeeze(G.Coh_4d(:,i,:,:)), B);
                end
            end
            catch
                fprintf('problems with coherence estimation see workspace with input G_Coherr and B_err\n');
                G_Coherr = squeeze(G.Coh_4d(:,i,:,:));
                B_err = B;
                assignin('base','G_Coherr',G_Coherr);
                assignin('base','B_err',B_err);
                
            end
            
            % Power
            if isfield(C2C,'Power')
                G.Power = C2C.Power;
                [G.PowerR, G.PowerP] = get_conn_R2d_B(app,G.Power, B);
            end
            
            % Complexity
            if isfield(C2C,'Power')
                G.Power = C2C.Power;
                [G.PowerR, G.PowerP] = get_conn_R2d_B(app,G.Power, B);
            end
            % intranetwork
            if isfield(C2C,'RN')
                G.RN_2d = C2C.RN;
                % berechne Korrelation zwischen Synchronization yund behavioralen Daten
                [G.RNR, G.RNP] = get_conn_R2d_B(app,G.RN_2d, B);
                G.networknames = C2C.networknames;
            end
           % internetwork
            if isfield(C2C,'RiN')
                G.RiN_3d = C2C.RiN;
                % berechne Korrelation zwischen Synchronization yund behavioralen Daten
                [G.RiNR, G.RiNP] = get_conn_R_B(app,G.RiN_3d, B);
                % fuelle die diagonalelemente mit den RN netzwerken auf
                if isfield(G,'RNR')
                    for ii=1:size(G.RNR,1)
                        G.RiNR(ii,ii,:)=G.RNR(ii,:);
                        G.RiNP(ii,ii,:)=G.RNP(ii,:);
                        G.RiN_3d(ii,ii,:)=G.RN_2d(ii,:);
                        
                    end
                end
                G.networknames = C2C.networknames;
            end
 
            
            G.clusternames = clusternames;
            G.clusternames_org = clusternames;
            
        end
        
        function G = estimate_GroupDiff(app,G1,G2)
            
            
            % berechne eine gemeinsame Korrelationsmatrix zu den behavioralen Daten
            % erzeuge eine gemeinsames R R12 mit R1 links unten  und R2 rechts oben
            assignin('base','G1',G1);
            assignin('base','G2',G2);
            
            G.RR12 = G2.RR;
            for i=1:size(G2.RR,1)
                for j=1:i-1
                    G.RR12(i,j,:)=G1.RR(i,j,:);
                end
            end
            
            G.SynR12 = G2.SynR;
            for i=2:size(G1.SynR,1)
                G.SynR12(i,1:i-1,:)=G1.SynR(i,1:i-1,:);
            end
            G.PLVR12 = G2.PLVR;
            for i=2:size(G1.PLVR,1)
                G.PLVR12(i,1:i-1,:)=G1.PLVR(i,1:i-1,:);
            end
            %intranetwork
            if isfield(G2, 'RNR')
                G.RNR12 = zeros(size(G2.RNR,1),2);
                G.RNR12(:,2) =G2.RNR(:,1);
                G.RNR12(:,1) =G1.RNR(:,1);
            end
            
            %internetwork
            if isfield(G2, 'RiNR')
                G.RiNR12 = G2.RiNR;
                for i=1:size(G2.RiNR,1)
                    for j=1:i-1
                        G.RiNR12(i,j,:)=G1.RiNR(i,j,:);
                    end
                end
            end

            
        end
        
        function [R,P] = get_conn_R2d_B(app,R_2d, B)
            
            
            R = zeros(size(R_2d,1),size(B,2));
            P = ones(size(R_2d,1),size(B,2));
            
            for i=1:size(R_2d,1)
                for k=1:size(B,2)
                    [R(i,k),P(i,k)]=corr(squeeze(R_2d(i,:))',squeeze(B(:,k)));
                end
            end
        end
        
        function [R,P] = get_conn_R_B(app,R_3d, B)
            
            R = zeros(size(R_3d,1),size(R_3d,2),size(B,2));
            P = ones(size(R_3d,1),size(R_3d,2),size(B,2));
            
            for i=1:size(R_3d,1)
                %for j=1:size(R_3d,2)
                for j=1:i-1
                    for k=1:size(B,2)
                        %size(R_3d)
                        %size(B)
                    
                        [R(i,j,k),P(i,j,k)]=corr(squeeze(R_3d(i,j,:)),B(:,k));
                        R(j,i,k) = R(i,j,k);
                        P(j,i,k) = P(i,j,k);
                    end
                end
            end
            
            
        end

        function [titellist,BG1,BG2] = get_selected_exel_data(app)

            varnamelistall = app.ExelvariablesListBox.Items;
            varnamelistsel = app.ExelvariablesListBox.Value;
%            titellist = sort(varnamelistsel)
            
            tmp = ismember( varnamelistall,varnamelistsel)==1;
            varidxlist = find(tmp);

            [row_g1] = get_rows_of_subjects(app,app.IDlist,app.namelist1);
            [row_g2] = get_rows_of_subjects(app,app.IDlist,app.namelist2);
            titellist = (app.exeltitel(varidxlist));
            
            %create the regressors, changed by sb150518
            if ~isempty(row_g1)
                BG1 = cell2mat(app.exeldata(row_g1,varidxlist));
            else
                fprintf('\tusing 1 for namelist1\n');
                BG1 = ones(length(app.namelist1),1);
            end
            
            if ~isempty(row_g2)
                BG2 = cell2mat(app.exeldata(row_g2,varidxlist));
            else
                fprintf('\tusing 1 for namelist2\n');
                BG2 = ones(length(app.namelist2),1);
            end
            

        end
        
%         function test_for_g_1(app,R)
%             for i=1:size(R,1); for j=1:size(R,2); for k =1:size(R,3); if R(i,j,k)>1; fprintf(' %.0f  %.0f %.0f %.5f\n',i,j,k,R(i,j,k)); end; end; end; end;
%         end
        
        
        function STAT = make_C2C_group_struct2(app,dir,namelist,postfix)
            % postfix definiert die Daten die rausgeschrieben werden Y Yc1, Yc2
            h=waitbar(0,'Please wait');
            
            % gibt es eine Netzwerkdefinition ?
            load(fullfile(app.resultsdirEditField.Value,'Cluster.mat'));
            if isfield(Cluster,'net')
                net =1;
            end
            
            load(fullfile(dir,namelist{1,1}));
            STAT.C2C.PCC.R=zeros(size(Cluster,1),size(Cluster,1),length(namelist));
            STAT.C2C.PCC.P=zeros(size(Cluster,1),size(Cluster,1),length(namelist));
            STAT.C2C.PCC.Z=zeros(size(Cluster,1),size(Cluster,1),length(namelist));
            STAT.C2C.SYN.R=zeros(size(Cluster,1),size(Cluster,1),length(namelist));
            %C2C.T=zeros(size(Cluster,1),1,size(Cluster,1),length(namelist));
            STAT.C2C.PLV.R=zeros(size(Cluster,1),size(Cluster,1),length(namelist));
            STAT.C2C.POW.R2d=zeros(size(Cluster,1),length(namelist));
            clusterXYZ = cell(0);
            for i=1:length(namelist)
                waitbar(i / length(namelist));
                fprintf('load Cluster %s \n',fullfile(dir,namelist{i}));
                load(fullfile(dir,namelist{i}));
                % die Clusternamen und Koordinaten werden aus einem
                % Cluster extrahiert (alle Cluster müssen natürlich gleich sein)
                %fprintf('%d\n',size(Cluster,1));
                %fprintf('loading %s\n',namelist{i});
                for j=1:size(Cluster,1)
                    clusternames{j,1}=Cluster{j,1}.name;
                    if isfield(Cluster{j,1},'XYZ')
                        clusterXYZ{j,1}=Cluster{j,1}.XYZ;
                    end
                    %assignin('base','Cluster',Cluster);
                    if ~isfield(Cluster{j,1},'Cluster2Cluster')
                        error('please estimate C2C connectivity first (no Cluster2Cluster field in Cluster struct)');
                        
                    end
                   
                    if isfield(Cluster{j,1}.Cluster2Cluster,['R', postfix])
                        STAT.C2C.PCC.R(:,j,i)=Cluster{j,1}.Cluster2Cluster.(['R', postfix]);
                        STAT.C2C.PCC.P(:,j,i)=Cluster{j,1}.Cluster2Cluster.(['P', postfix]);
                        STAT.C2C.PCC.Z(:,j,i)=Cluster{j,1}.Cluster2Cluster.(['Z', postfix]);
                    end
                    if isfield(Cluster{j,1}.Cluster2Cluster,(['T', postfix]))
                        Tx = Cluster{j,1}.Cluster2Cluster.(['T', postfix]);
                        STAT.C2C.TRA.T(:,1:size(Tx,1),j,i)=Tx';
                    end
                    % hier manchmal komplexe Zahlen ... evtl bei merkwuerdiegen Y_mean eingabe
                    try
                    if isfield(Cluster{j,1}.Cluster2Cluster,(['Coh', postfix]))
                        Coh = Cluster{j,1}.Cluster2Cluster.(['Coh', postfix]);
                        CohF = Cluster{j,1}.Cluster2Cluster.(['CohF', postfix]);
                        STAT.C2C.COH.Coh(:,1:size(Coh,2),j,i)=Coh;
                        STAT.C2C.COH.Cohf(:,1:size(Coh,2),j,i)=CohF;
                    end
                    catch
                        fprintf('choerence konnte nicht berechnet werden warscheinlich Y_mean ungeeignet\n');
                    end
                    if isfield(Cluster{j,1}.Cluster2Cluster,(['Syn', postfix]))
                        STAT.C2C.SYN.R(:,j,i)=Cluster{j,1}.Cluster2Cluster.(['Syn', postfix]);
                    end
                    if isfield(Cluster{j,1}.Cluster2Cluster,(['PLV', postfix]))
                        STAT.C2C.PLV.R(:,j,i)=Cluster{j,1}.Cluster2Cluster.(['PLV', postfix]);
                    end
                    if isfield(Cluster{j,1}.Cluster2Cluster,(['Comp', postfix]))
                        STAT.C2C.COM.R(j,i)=Cluster{j,1}.Cluster2Cluster.(['Comp', postfix]);
                    end
                    Y_mean = Cluster{j,1}.(['Y', postfix, '_mean']);
                    STAT.C2C.POW.Power(j,i)=sum(Y_mean.^2)/(length(Y_mean)*2+1);
                    
                    % Netzwerk einfuegen 
                    % STAT.RiN
                    % STAT.ZiN
                    
                end
                
            end
            close(h)
            % die jeweilige Variable hat noch eine beschreibung mit dem Namen der Variablen + des
            STAT.C2C.PCC.des{1} = 'R ... Pearson correlation';
            STAT.C2C.COH.des{1} = 'Coh ... Coherence value';
            STAT.C2C.SYN.des{1} = 'Synchronicity value';
            STAT.C2C.PLV.des{1} = 'R ... PLV value';
            STAT.C2C.COM.des{1} = 'R ... Complexity value';
            STAT.C2C.POW.des{1} = 'Power ... Power value';
            STAT.C2C.TRA.des{1} = 'T ... Transinformation';
            STAT.C2C.clusternames=clusternames;
            STAT.C2C.clusterXYZ=clusterXYZ;
            STAT.namelist = namelist;
        end
        
        function C2C_stat_g1 = deslect_subjects_ck2(app,C2C_stat_g1,namelist,idx)
            %fprintf('deselect subjects\n');
            %assignin('base','C2C_stat_g1_deselect',C2C_stat_g1);
            %assignin('base','namelist_deselect',namelist);
            %assignin('base','idx_deselect',idx);
            num_all_subjects = length(C2C_stat_g1.namelist);
            if length(idx)==0
                return;
            end
            %C2C_stat_g1.Z
            f = fields(C2C_stat_g1);
            for i=1:length(f)
                %fprintf('go into field ... \n',f{i});
                if isstruct(C2C_stat_g1.(f{i}))
                    f2 = fields(C2C_stat_g1.(f{i}));
                    for j = 1:length(f2)
                    %fprintf('go into field ... %s\n',f2{i});
                        if isstruct(C2C_stat_g1.(f{i}).(f2{j}))
                            %assignin('base','C2C_stat_g1_part',C2C_stat_g1.(f{i}).(f2{j}));

                            %fprintf('call reduce with ... %s\n',['C2C_stat_g1..', f{i}, '..' , f2{j} ] );
                            C2C_stat_g1.(f{i}).(f2{j})=reduce(app,C2C_stat_g1.(f{i}).(f2{j}),idx,num_all_subjects);
                            %size( C2C_stat_g1.(f{i}).(f2{j}))
                        end
                    end
                end
            end
            
        end
        

        function C = reduce(app,C,idx,num_subjects)
           %assignin('base','C_initial',C);

            f = fields(C);
            for i=1:length(f)
                t = size(C.(f{i}));
                dims = length(t);
                %fprintf('reduce field ... %s   dim %d  t(end)= %d... num_subjects = %d\n',f{i},dims,t(end),num_subjects);
                if isnumeric(C.(f{i})) && t(end)==num_subjects
                  % fprintf('reducing with dim %d ... num_subjects = %d\n',dims,num_subjects);

                    if dims== 1
                        C.(f{i})(idx)=[];
                    end
                    if dims == 2
                        C.(f{i})(:,idx)=[];
                    end
                    if dims == 3
                        C.(f{i})(:,:,idx)=[];
                    end
                    if dims == 4
                        C.(f{i})(:,:,:,idx)=[];
                    end
                end
                
            end
         %assignin('base','C_last',C);
        
%             
%                 C2C_stat_g1.R(:,:,idx)=[]; end
%             if isfield(C2C_stat_g1,'P'); C2C_stat_g1.P(:,:,idx)=[];  end
%             if isfield(C2C_stat_g1,'Z'); C2C_stat_g1.Z(:,:,idx)=[]; end
%             if isfield(C2C_stat_g1,'Syn'); C2C_stat_g1.Syn(:,:,idx)=[]; end
%             if isfield(C2C_stat_g1,'PLV'); C2C_stat_g1.PLV(:,:,idx)=[]; end
%             if isfield(C2C_stat_g1,'T'); C2C_stat_g1.T(:,:,:,idx)=[]; end
%             if isfield(C2C_stat_g1,'Coh'); C2C_stat_g1.Coh(:,:,:,idx)=[]; end
%             if isfield(C2C_stat_g1,'CohF'); C2C_stat_g1.CohF(:,:,:,idx)=[]; end
%             if isfield(C2C_stat_g1,'Power'); C2C_stat_g1.Power(:,idx)=[]; end
%             if isfield(C2C_stat_g1,'Comp'); C2C_stat_g1.Comp(:,idx)=[]; end
%             if isfield(C2C_stat_g1,'RiN'); C2C_stat_g1.RiN(:,:,idx)=[]; end
%             if isfield(C2C_stat_g1,'RN'); C2C_stat_g1.RN(:,idx)=[]; end
%             if isfield(C2C_stat_g1,'ZiN'); C2C_stat_g1.ZiN(:,:,idx)=[]; end
%             if isfield(C2C_stat_g1,'ZN'); C2C_stat_g1.ZN(:,idx)=[]; end
%             if isfield(C2C_stat_g1,'namelist'); C2C_stat_g1.namelist = namelist; end
%             


        end


        function results = GC_calculation_sb(app, g_list)
            results = []; if isempty(g_list), return; end;
            %prepare data
            fieldstr = app.cfg.fieldstr ;
            block_length = app.cfg.block_length ;
            model_order= app.cfg.model_order;
           
            
             %load data
            for il = 1:length(g_list)
                
                GC = struct;
                fn = g_list{il};
                fprintf('|||LOADING: %s\n', fn);
                IN = load(fn);
                if ~isfield(IN, 'Cluster') 
                    disp(fn)
                    disp('input data not valid!');
                    
                else
                    
                    Cluster = IN.Cluster(app.cfg.cluster_id);%select selected clusters only
                    data = [];
                    for ic = 1:length(Cluster);
                        clustername{ic,1} = Cluster{ic}.name;
                        data(ic,:) = Cluster{ic}.(fieldstr);
                    end %ic
                    
                    %now bring to correct format dim1: cluster, dim2 datapoints, dim3 trials
                    [dim1 dim2] = size(data);
                    nt = floor(dim2/block_length);
                    tmp(:, 1:nt*block_length) = data(:,1:nt*block_length); data = tmp;
                        %NOW MAKE THIRD Dimension
                        data3d = reshape(data, dim1, block_length, nt);
                    
                    %setup data und perform GC
                    cfg.method = 'granger_mri';
                    cfg.momax = model_order;
                    out = sb_gen_connectivity(cfg, data3d);
                    
                    %preparing output
                    GC = out;
                    GC.clustername = clustername;
                    Cluster = IN.Cluster;
                    Cluster{1}.GC = GC; 
                    
                    fprintf('\tsave results to: %s\n', fn);
                    save(fn, 'Cluster');
                end%if
            end%il
        end %results = GC_calculation_sb(app)


        function results = return_FTfromcluster_sb(app, g_list)
            %return select data from Cluster in FT Format
            results = [];
            
            clusterlist = app.cfg.clusterlist;
            
            for il = 1:length(g_list)
                
                GC = struct;
                fn = g_list{il};
                fprintf('|||LOADING: %s\n', fn);
                IN = load(fn);
                if ~isfield(IN, 'Cluster') 
                    disp(fn)
                    disp('input data not valid!');
                    
                else
                    Cluster = IN.Cluster(clusterlist);
                    data = [];
                    
                    data.time = [];
                    data.fsample = 1/app.cfg.TR;
                    tmp = [];
                    %now loop through cluster
                    for ic = 1:length(Cluster)
                        label{ic,1} = Cluster{ic}.name;
                        tmp = [tmp;Cluster{ic}.(app.cfg.fieldstr)']; 
                    
                    end %ic
                    data.label = label;
                    len_tmp = size(tmp,2);
                    %splitY?
                    if app.cfg.splitY == 0
                        data.trial{1,1} = tmp;
                        data.time{1,1} = [0:app.cfg.TR:(app.cfg.TR*len_tmp)-app.cfg.TR];
                    else
                        steps = floor(size(tmp,2)/app.cfg.splitY);
                        trial = []; 
                        for i_s = 1:steps
                           trial{i_s} = tmp(:, 1:app.cfg.splitY);
                           data.time{i_s} = [0:app.cfg.TR:(app.cfg.TR*app.cfg.splitY)-app.cfg.TR];
                           tmp(:, 1:app.cfg.splitY) = [];
                        end
                        
                        data.trial = trial;
                    end
                    data.trialinfo = ones(length(data.trial),1);
                    results = [results;{data}];
                end %if
                
            end%il
%                  assignin('base', 'results', results);
        end%results = return_cluster_sb(app)
        
          function results = return_datafromcluster_sb(app, g_list)
            %return select data from Cluster in FT Format
            results = [];
            
            clusterlist = app.cfg.clusterlist;
            
            for il = 1:length(g_list)
                
                GC = struct;
                fn = g_list{il};
                fprintf('|||LOADING: %s\n', fn);
                IN = load(fn);
                if ~isfield(IN, 'Cluster') 
                    disp(fn)
                    disp('input data not valid!');
                    
                else
                    Cluster = IN.Cluster(clusterlist);
                    data = [];
                    
                    for ic = 1:length(Cluster)
                        label{ic,1} = Cluster{ic}.name;
                        data = [data;Cluster{ic}.(app.cfg.fieldstr)']; 
                    
                    end %ic
                   
                    results{il}.data = data;
                    results{il}.label = label;
                end %if
                
            end%il
%                  assignin('base', 'results', results);
          end%results = return_datafromcluster_sb(app)

        function results = calc_ASDF_TE(app)
            
            %Idee: um die SPIKE-TE zu verwenden, bestimme ich aus der Zeitreihe PEAKs, indem ich zunaechst einen Detrend andwende und 
            %dann die Ableitung bilde, anschlie-end bestimme ich automatisch einen cutoffwert fuer alle Ableitungen (positive)
            %ab dem X% als PEAK gezaehlt werden (cutoff PEAK)
            %mit diesem binearen PEAK data kann ich dann ASDFTE verwenden
            %literatur: https://www.ncbi.nlm.nih.gov/pmc/articles/PMC3216957/pdf/pone.0027431.pdf
            
% %              cutoff = 0.4; %percent of all peakes in dataset
% %             TR = 2.54;
% %             u = [1 5];%in sec
% %             do_smooth = 1;
            cfg = app.cfg;
            TR = cfg.TR;
            u = cfg.u;
            do_smooth = 0;
            cutoff = cfg.cutoff;
            data = cfg.data;
            y = data.data;
            
              u_min = ceil(min(u)/TR);
                u_max = ceil(max(u)/TR);
                if u_min < 1, u_min = 1;end
                if u_max > size(y,2)-1, u_max = size(y,2)-1;end
            
                y_detrend = detrend(y')';
            
                for iy = 1:size(y_detrend,1)
                   y_diff(iy,:) = diff(y_detrend(iy,:));
                   
                   if do_smooth
                        y_diff(iy,:) = smooth(y_diff(iy,:),3);
                   end
                   
                   y_diff(iy,:) =  y_diff(iy,:) / max(y_diff(iy,:));
                end
            
                y_bin = ones(size(y_diff));
               
            
                start = 1; steps = 0.01;
                
                for ic = start:-steps:0
                    y_bin = ones(size(y_diff));
                    y_bin(y_diff<ic) =0;
                    
                    if sum(sum(y_bin))/numel(y_bin) >= cutoff
                        fprintf('\tcutoff of %.2f (%.2f) achieved at %.2f\n', cutoff,sum(sum(y_bin))/numel(y_bin), ic);
                        break
                    end
                end
              
                    
                % crate ASDF format
                asdf = SparseToASDF(y_bin, 1);
                
                [peakTE, CI, TEdelays] = ASDFTE(asdf, u_min:u_max);
                
                if cfg.view_plot
                    % output
                    figure
                    subplot(1,2,1)
                    sb_gen_plot_pw(peakTE); colorbar
                    ylabel('from');xlabel('to');
                    xticklabels(data.label);
                    yticklabels(data.label);
                    title(sprintf('cutoff = %.2f%%', cutoff));
                    
                    subplot(1,2,2)
                    plot(y_diff');xlim([0 size(y_diff,2)]);ylim([-1 1]);
                    hline = refline([0 ic]);hline.Color = 'r';
                    xlabel('scans');ylabel('normalized diff(y)');
                    
                    suptitle(sprintf('subject: %s', cfg.label));
                end
                
                results.peakTE = peakTE;
                results.label = data.label;
        end
        
        %%%%%%%%%%%% calc_schreiber_TE %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
         function results = calc_schreiber_TE(app)
                %%      calculation TE nach Schreiber et al 2000
                % For details, please see T Schreiber, "Measuring information transfer", Physical Review Letters, 85(2):461-464, 2000.
               
                cfg = [];
                switch app.ProbabilitydensityestimationDropDown.Value
                    case 'Guassian kernel density estimation'
                        cfg.method = 'TE_schreiber_KDE';
                    case 'Darbellay-Vajda partitioning algorithm'
                         cfg.method = 'TE_schreiber_partition';
                    case 'bin counting with fixed and equally-spaced bins'
                         cfg.method = 'TE_schreiber_rank';
                    otherwise 
                        disp(app.ProbabilitydensityestimationDropDown.Value);
                end
                
               cfg.param = app.parameteru_minu_maxlwEditField.Value;
               TE = sb_gen_transferentropy(cfg, app.cfg.data.data);
                
                if app.viewindidvidualresultsCheckBoxsb_2.Value;
                    % output
                    figure
                    sb_gen_plot_pw(TE); colorbar
                    ylabel('from');xlabel('to');
                    xticklabels(app.cfg.data.label);
                    yticklabels(app.cfg.data.label);
                    title(sprintf('%s: %s ', cfg.method, app.cfg.label));
    %               suptitle(sprintf('subject: %s', app.data.label));
                end
                
                results.TE = TE;
                results.label = app.cfg.data.label;

         end%calc_schreiber_TE
         
        %%%%%%%%%%%% view_sb_conn_results %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function results = view_sb_conn_results(app)
            
            if check_sb_preprocesser(app) == 0, disp('Please add /sb_preprocessor'), return;end
            
            main_dir = app.resultsdirEditField.Value;
            fn1 = fullfile(main_dir, 'C2C_stat_g1.mat'); if ~exist(fn1), disp('load g1 data first!');return;end
            fn2 = fullfile(main_dir, 'C2C_stat_g2.mat'); if ~exist(fn2), disp('load g2 data first!');return;end
           
            %load data
            fprintf('trying to load: %s, %s\n', fn1, fn2);
            load(fn1);
            load(fn2);
            
            switch app.cfg.mode
                case 'GC'
                    GC1 = C2C_stat_g1.GC_sb.GrangerC;
                    GC2 = C2C_stat_g2.GC_sb.GrangerC;
                    label = C2C_stat_g1.GC_sb.clustername;
                    out_str = 'GC_stat';
                    xstr = 'from';
                    ystr = 'to';
                case 'TE_asdf'
                    GC1 = C2C_stat_g1.TE_asdf.peakTE;
                    GC2 = C2C_stat_g2.TE_asdf.peakTE;
                    label = C2C_stat_g2.TE_asdf.label;
                    out_str = 'TEasdf_stat';
                    xstr = 'to';
                    ystr = 'from';
                case 'TE_schreiber'
                    GC1 = C2C_stat_g1.TE_schreiber.TE;
                    GC2 = C2C_stat_g2.TE_schreiber.TE;
                    label = C2C_stat_g2.TE_schreiber.label;
                    out_str = 'TE_lee_et_al';
                    xstr = 'to';
                    ystr = 'from';    
                case 'Correlation'
                    GC1 = C2C_stat_g1.Z;
                    GC2 = C2C_stat_g2.Z;
                    label = C2C_stat_g2.clusternames;
                    out_str = 'Corr_stat';
                    xstr = '';
                    ystr = '';   
                case 'TE_prep'
                    %assuming labelcmb to be correcT!!!
                    label = unique(C2C_stat_g2.TEprep.sgncmb(:,1));
                    
                    GC1tmp  = C2C_stat_g1.TEprep.TEmat;
                    GC2tmp  = C2C_stat_g2.TEprep.TEmat;
                    
                    %need to bring results in square matrix form
                    GC1 = zeros(length(label), length(label), size(GC1tmp,3));
                    GC2 = zeros(length(label), length(label), size(GC2tmp,3));
                    cc = 0;
                    for i1=1:length(label)
                        for i2 = 1:length(label)
                           
                           if i1 ~= i2
                               cc= cc+1;
                               GC1(i1, i2,:) = GC1tmp(cc,1,:);
                               GC2(i1, i2,:) = GC2tmp(cc,1,:);
                           end
                        end
                    end
                    
                    assignin('base', 'TE1', GC1);
                    out_str = 'TE_TRENTOOL';
                    xstr = 'to';
                    ystr = 'from';      
                otherwise
                    disp('mode not supported yet:');disp(app.cfg.mode);
            end
            
            %bring data to 2d: rows -> obersvations AND cols -> variables (clusterpaires)
            [dim11, dim12, dim13] = size(GC1);
            GC1_2d = reshape(GC1, dim11*dim12, dim13)';
            
             [dim21, dim22, dim23] = size(GC2);
            GC2_2d = reshape(GC2, dim21*dim22, dim23)';
            
            %%perform tests
            pstr = 'p = ';
            switch app.settingsButtonGroup.SelectedObject.Text
                case 'paired'
                    %check data
                    if max(abs(size( GC1)-size(GC2))) >0, disp('Dimensions mismatch found ..');return;end
                    if ~app.nonparametricstatisticsCheckBoxsb.Value
                        [h p] = ttest(GC1_2d, GC2_2d);
                        [h p_2k1] = ttest(GC1_2d, GC2_2d, 'Tail', 'right');
                        [h p_1k2] = ttest(GC1_2d, GC2_2d, 'Tail', 'left');
                    else
                        [p p_2k1 p_1k2] = sb_gen_nonparametric_ttest_1s(GC1_2d-GC2_2d, app.perm_brain_conn_EditField.Value);
                        pstr = 'p (nonparam) = ';
                    end
                case '2s'
                    if ~app.nonparametricstatisticsCheckBoxsb.Value
                        [h p] = ttest2(GC1_2d, GC2_2d);
                        [h p_2k1] = ttest2(GC1_2d, GC2_2d, 'Tail', 'right');
                        [h p_1k2] = ttest2(GC1_2d, GC2_2d, 'Tail', 'left');
                    else
                        [p p_2k1 p_1k2] = sb_gen_nonparametric_ttest_2s(GC1_2d,GC2_2d, app.perm_brain_conn_EditField.Value);
                        pstr = 'p (nonparam) = ';
                    end
                otherwise
                    disp('not supported, should not happen!');
                    
            end
            
            %reshape results
            p = reshape(p, dim11, dim12);
            p_2k1 = reshape(p_2k1, dim11, dim12);
            p_1k2 = reshape(p_1k2, dim11, dim12);    

            %display results
            figure
            sb_gen_plot_pw(p);colorbar; caxis([0 app.pvalue_brain_conn_EditField.Value]);
            xticks([1:dim11]);yticks([1:dim12]);
            xticklabels(label);xlabel(xstr); ylabel(ystr);
            yticklabels(label);
            title(sprintf('%s %s %s%.3f',app.cfg.mode, app.settingsButtonGroup.SelectedObject.Text,pstr, ...
                app.pvalue_brain_conn_EditField.Value));
            set(gca, 'XTickLabelRotation', 60);
            
            [tt1, gr1, ext] = fileparts(app.g1dirEditField.Value);
            [tt1, gr2, ext] = fileparts(app.g2dirEditField.Value);
            
            figure
            sb_gen_plot_pw(p_2k1);colorbar; caxis([0 app.pvalue_brain_conn_EditField.Value]);
            xticks([1:dim11]);yticks([1:dim12]);
            xticklabels(label);xlabel(xstr); ylabel(ystr);
            yticklabels(label);
            title(sprintf('%s %s %s%.3f %s > %s', app.cfg.mode, app.settingsButtonGroup.SelectedObject.Text,pstr, ...
            app.pvalue_brain_conn_EditField.Value, gr1, gr2));
            set(gca, 'XTickLabelRotation', 60);
            
            figure
            sb_gen_plot_pw(p_1k2);colorbar; caxis([0 app.pvalue_brain_conn_EditField.Value]);
            xticks([1:dim11]);yticks([1:dim12]);
            xticklabels(label);xlabel(xstr); ylabel(ystr);
            yticklabels(label);
            title(sprintf('%s %s %s%.3f %s < %s', app.cfg.mode, app.settingsButtonGroup.SelectedObject.Text,pstr, ...
            app.pvalue_brain_conn_EditField.Value, gr1, gr2));
            set(gca, 'XTickLabelRotation', 60);
                            
            %workspace output
            GC_stat.p = p;
            GC_stat.p_1k2 = p_1k2;
            GC_stat.p_2k1 = p_2k1;
            GC_stat.GC1 = GC1;
            GC_stat.GC2 = GC2;
            GC_stat.clusternames = label;
                assignin('base', out_str, GC_stat);
                fprintf('Results written to workspace: %s\n', out_str);
                disp(GC_stat);
        end
        
        function results = calc_TE_sb(app, g_list)
            data = return_FTfromcluster_sb(app, g_list);
            if isempty(data), disp('no data found');return;end
            %assignin('base','data_ft', data);
            
            %TEPrepare
            cfg.method = 'TEPrepare';
            u_vec = str2num(app.uvecscansEditField.Value);
            TR = app.EditField_TR_TEsb.Value;
            u_step = app.EditField_ustep_TEsb.Value;
            cfg.predicttimemin_u = min(u_vec)*TR *1000;
            cfg.predicttimemax_u = max(u_vec)*TR *1000;
            cfg.predicttimestepsize = u_step*TR *1000;
            
            cfg.actthrvalue = 20;
            cfg.minnrtrials = 2;
            cfg.repPred        = 20;
            cfg.embedding_delay_unit = 'Volumes';
            
            %TEprepare
            data_TE_prep = sb_gen_transferentropy(cfg, data);
            assignin('base', 'TE_prepare', data_TE_prep);
            
            %TEfinddelay
            cfg.method = 'tefinddelay';
            data_TE_prep = sb_gen_transferentropy(cfg, data_TE_prep);
            assignin('base', 'TE_prepare', data_TE_prep);
            
            %TEsurrog
            cfg.method = 'tesurrogatestats';
            data_TE_prep = sb_gen_transferentropy(cfg, data_TE_prep);
            assignin('base', 'TE_prepare', data_TE_prep);
            
            for id = 1:length(g_list)
                
                fprintf('saving to: %s\n', g_list{id});
                load(g_list{id});
                Cluster{1}.TEprep = data_TE_prep{id};
                save(g_list{id}, 'Cluster');
            end
              
        end
        
        function g_list = return_group_cluster_fn_SB(app)
            g1_list = app.g1ListBox.Value;
            g2_list = app.g2ListBox.Value;
            
            g1_dir = app.g1dirEditField.Value;
            g2_dir = app.g2dirEditField.Value;
            
            g_list = [];
            
            for il = 1:length(g1_list)
                g_list = [g_list; {fullfile(g1_dir, g1_list{il})}];
                
            end
            
            if ~isempty(g2_list)
                for il = 1:length(g2_list);
                    g_list = [g_list; {fullfile(g2_dir, g2_list{il})}];
                end
            end
        end %g_list = return_group_cluster_fn_SB(app)
        
        function cfg = cfg_cfg_sb_con(app, method_str)
            cfg = [];
            
            %cfg fieldstr
            if isempty(app.dataListBox.Value)
                cfg.fieldstr = 'Y_mean';
            else
                cfg.fieldstr = app.dataListBox.Value;
                cfg.fieldstr = cfg.fieldstr(1:strfind(cfg.fieldstr, ':')-1);
            end
            fprintf('\tUsing: %s\n', cfg.fieldstr);
            
            %clusterlist
            cfg.clusterlist = find(ismember(app.clusternamessbListBox.Items, app.clusternamessbListBox.Value));
            if isempty(cfg.clusterlist), disp('please select a cluster!');cfg = []; return;   end
            
            %specifig settings
            switch lower(method_str)
                case 'estimategc'
                    cfg.block_length = app.splitYeveryscanEditField.Value;
                    cfg.model_order = app.GCmodelorderEditField.Value;
                    cfg.cluster_id = find(ismember(app.clusternamessbListBox.Items, app.clusternamessbListBox.Value));
                    
                case lower('estimateTETRENTOOL')
                    cfg.splitY = app.splitYeveryscanEditField_TE.Value;
                    cfg.TR = app.EditField_TR_TEsb.Value;
                    
                case lower('ASDFTE')
                    cfg.TR = app.EditField_TR_TEsb_2.Value;
                    cfg.u = str2num(app.uvecscansEditField_2.Value);
                    cfg.do_smooth = 0;
                    cfg.cutoff = app.EditField_peakcutoff_TEsb_3.Value;;
                    cfg.view_plot = app.viewindidvidualresultsCheckBoxsb.Value;
                case lower('TEschreiber')
                    app.cfg.view_plot = app.viewindidvidualresultsCheckBoxsb_2.Value;
                otherwise
                    disp('no special cfg method found ..');
                    
            end %method_str
            
            
        end %cfg_cfg_sb_con(app, method_str)
        
        function results = data_import_nifti(app, method_str)
            options = struct;
            options.extract_all = app.extractallvoxelinClusterCheckBox.Value;
            options.extract_con1 = app.extractconditionalvoxelCheckBox.Value;
            options.extract_con1_g1_val = app.graymatterprobgreaterasEditField.Value;
            options.extract_con_g1_name = app.graymatterfilenameEditField.Value;
            options.extract_con1_g2_val = app.whitematterproblessthenEditField.Value;
            options.extract_con_g2_name = app.whitematterfilenameEditField.Value;
            options.extract_con1_g3_val = app.CSFproblessthenEditField.Value;
            options.extract_con_g3_name = app.csffilenameEditField.Value;
            options.extract_con2 = app.extractconditionalvoxelCheckBox.Value;
            options.extract_con2_g1_val = app.graymatterprobgreateras2EditField.Value;
            options.extract_con2_g2_val = app.whitematterproblessthen2EditField.Value;
            options.extract_con2_g3_val = app.CSFproblessthen2EditField.Value;
            
            %added by sb280518: add append cluster method
            options.method_str = lower(method_str);
            switch method_str
                case 'new'
                    %do nothing
                case 'append'
                    if app.usedifferentClustermatCheckBox.Value
                        options.cluster_fn = app.diffClustermatEditField.Value;
                        if isempty(options.cluster_fn), disp('select Cluster.mat!');return;end
                    else
                        options.cluster_fn = fullfile(fullfile(app.resultsdirEditField.Value, 'Cluster.mat'));
                    end
            end
            %end SB EDIT
 
            parameterfile = fullfile(app.resultsdirEditField.Value,'parameter.mat');
            try 
                load(parameterfile)
            catch
                parameter = struct;
            end
                
            subjectfilelist_g1 = get_full_subjects_filenames(app,app.g1dirdataimportEditField.Value,app.g1dirdataimportListBox.Value, app.g1dataimportsubdirnameEditField.Value);
            subjectfilelist_g2 = get_full_subjects_filenames(app,app.g2dirdataimportEditField.Value,app.g2dirdataimportListBox.Value, app.g2dataimportsubdirnameEditField.Value);
            resultsdir = app.resultsdirEditField.Value;
            outputdirg1 = app.g1dirEditField.Value;
            outputdirg2 = app.g2dirEditField.Value;
            namelistg1 = app.g1dirdataimportListBox.Value;
            namelistg2 = app.g2dirdataimportListBox.Value;
            assignin('base','outputdirg1',outputdirg1);
            assignin('base','subjectfilelist_g1',subjectfilelist_g1);
            assignin('base','namelistg1',namelistg1);
            assignin('base','outputdirg2',outputdirg2);
            assignin('base','subjectfilelist_g2',subjectfilelist_g2);
            assignin('base','namelistg2',namelistg2);
            
            ck_rc3_fill_all_clusters_from_niftifiles(resultsdir,outputdirg1,subjectfilelist_g1,namelistg1,options);
            ck_rc3_fill_all_clusters_from_niftifiles(resultsdir,outputdirg2,subjectfilelist_g2,namelistg2,options);
        end
        
        function lastsession = return_app_lastsession(app) %sb290518
            lastsession = struct;
            lastsession.resultsdir =  app.resultsdirEditField.Value ;
            lastsession.g1dir = app.g1dirEditField.Value;
            lastsession.g2dir = app.g2dirEditField.Value;
            lastsession.exemplarydatafile = app.exemplarydatafileEditField.Value;
            lastsession.g1dirdataimport = app.g1dirdataimportEditField.Value;
            lastsession.g2dirdataimport = app.g2dirdataimportEditField.Value;
            lastsession.dataimportsubdirname = app.g1dataimportsubdirnameEditField.Value;
            lastsession.extractallvoxelinCluster =  app.extractallvoxelinClusterCheckBox.Value;
            lastsession.extractconditionalvoxel = app.extractconditionalvoxelCheckBox.Value;
            lastsession.extract2conditionalvoxel = app.extract2conditionalvoxelCheckBox.Value;
            lastsession.graymatterprobgreateras = app.graymatterprobgreaterasEditField.Value;
            lastsession.whitematterproblessthen = app.whitematterproblessthenEditField.Value ;
            lastsession.CSFproblessthen = app.CSFproblessthenEditField.Value ;
            lastsession.graymatterprobgreateras2 = app.graymatterprobgreateras2EditField.Value;
            lastsession.whitematterproblessthen2 = app.whitematterproblessthen2EditField.Value ;
            lastsession.CSFproblessthen2 = app.CSFproblessthen2EditField.Value ;
            lastsession.graymatterfilename = app.graymatterfilenameEditField.Value;
            lastsession.whitematterfilename = app.whitematterfilenameEditField.Value ;
            lastsession.csffilename = app.csffilenameEditField.Value ;
            lastsession.dataimportfilefilter =app.dataimportfilefilterEditField.Value  ;
            lastsession.exelfileeditfield = app.ExelFileEditField.Value;
            
            
            %sb_settings
            lastsession.sb_add_rc1fn = app.graymatterfilenameEditField.Value;
            lastsession.sb_add_rc2fn = app.whitematterfilenameEditField.Value;
            lastsession.sb_add_rc3fn = app.csffilenameEditField.Value;
            lastsession.sb_add_cluster_fn = app.diffClustermatEditField.Value;
            lastsession.sb_add_surf_cluster_ffound = app.datafoundLamp.Color;
            lastsession.sb_add_surf_cluster_subfolder = app.subfolder4surfEditField.Value;
            lastsession.sb_add_surf_cluster_ffilter = app.ffilter4surfEditField.Value;
            
            lastsession.sb_conn_gc_modelorder = app.GCmodelorderEditField.Value;
            lastsession.sb_conn_gc_splitY = app.splitYeveryscanEditField.Value;
            
            lastsession.sb_conn_te_splitY = app.splitYeveryscanEditField_TE.Value;;
            lastsession.sb_conn_te_TR = app.EditField_TR_TEsb.Value;
            lastsession.sb_conn_te_u_vec = app.uvecscansEditField.Value;
            lastsession.sb_conn_te_u_step = app.EditField_ustep_TEsb.Value;
            
            lastsession.sb_conn_te2_u = app.parameteru_minu_maxlwEditField.Value;
            
            lastsession.sb_conn_teasdf_tr = app.EditField_TR_TEsb_2.Value;
            lastsession.sb_conn_teasdf_u = app.uvecscansEditField_2.Value;
            lastsession.sb_conn_teasdf_peakcutoff = app.EditField_peakcutoff_TEsb_3.Value;

        end
        
        function results = load_gui_lastsession_sb(app, lastsession)
            app.resultsdirEditField.Value = lastsession.resultsdir;
            app.g1dirEditField.Value = lastsession.g1dir;
            app.g2dirEditField.Value = lastsession.g2dir;
            app.exemplarydatafileEditField.Value = lastsession.exemplarydatafile;
            app.g1dirdataimportEditField.Value = lastsession.g1dirdataimport;
            app.g2dirdataimportEditField.Value = lastsession.g2dirdataimport;
            app.g1dataimportsubdirnameEditField.Value = lastsession.dataimportsubdirname;
            app.extractallvoxelinClusterCheckBox.Value = lastsession.extractallvoxelinCluster;
            app.extractconditionalvoxelCheckBox.Value = lastsession.extractconditionalvoxel;
            app.extract2conditionalvoxelCheckBox.Value = lastsession.extract2conditionalvoxel;
            app.graymatterprobgreaterasEditField.Value = lastsession.graymatterprobgreateras;
            app.whitematterproblessthenEditField.Value = lastsession.whitematterproblessthen;
            app.CSFproblessthenEditField.Value = lastsession.CSFproblessthen;
            app.graymatterprobgreateras2EditField.Value = lastsession.graymatterprobgreateras2;
            app.whitematterproblessthen2EditField.Value = lastsession.whitematterproblessthen2;
            app.CSFproblessthen2EditField.Value = lastsession.CSFproblessthen2;
            app.graymatterfilenameEditField.Value = lastsession.graymatterfilename;
            app.whitematterfilenameEditField.Value = lastsession.whitematterfilename;
            app.csffilenameEditField.Value = lastsession.csffilename;
            app.dataimportfilefilterEditField.Value = lastsession.dataimportfilefilter;
            app.ExelFileEditField.Value = lastsession.exelfileeditfield;
            
            %sb_settings
            if isfield(lastsession, 'sb_add_cluster_fn')
                app.diffClustermatEditField.Value = lastsession.sb_add_cluster_fn ;
                app.datafoundLamp.Color = lastsession.sb_add_surf_cluster_ffound;
                app.subfolder4surfEditField.Value= lastsession.sb_add_surf_cluster_subfolder;
                app.ffilter4surfEditField.Value = lastsession.sb_add_surf_cluster_ffilter;
                app.GCmodelorderEditField.Value= lastsession.sb_conn_gc_modelorder;
                app.splitYeveryscanEditField.Value= lastsession.sb_conn_gc_splitY;
                
                app.splitYeveryscanEditField_TE.Value= lastsession.sb_conn_te_splitY ;
                app.EditField_TR_TEsb.Value= lastsession.sb_conn_te_TR  ;
                app.uvecscansEditField.Value= lastsession.sb_conn_te_u_vec ;
                app.EditField_ustep_TEsb.Value= lastsession.sb_conn_te_u_step ;
                
                app.parameteru_minu_maxlwEditField.Value= lastsession.sb_conn_te2_u  ;
                
                app.EditField_TR_TEsb_2.Value= lastsession.sb_conn_teasdf_tr ;
                app.uvecscansEditField_2.Value= lastsession.sb_conn_teasdf_u ;
                app.EditField_peakcutoff_TEsb_3.Value = lastsession.sb_conn_teasdf_peakcutoff;
            end
        end%load_gui_lastsession_sb
        
        function error_found = check_add_surf_cluster(app, g1_list, g1_dir, g1_group_dir, method_str)
            error_found  = 0;
            switch lower(method_str)
                case 'check'
                    check_only = 1;
                case 'add'
                    check_only = 0;
                otherwise
                    disp(method_str); disp('method not understood: check_add_surf_cluster');
                    return
            end
            
            if ~isempty(g1_list)
                for i_subj = 1:length(g1_list)
                    IN = []; Cluster = [];
                    %check for Cluster in subdir
                    cluster_fn = fullfile(g1_dir, g1_list{i_subj}, app.subfolder4surfEditField.Value);
                    if exist(cluster_fn)
                        flist = sb_gen_dir_list(fullfile(cluster_fn, app.ffilter4surfEditField.Value));
                        if isempty(flist), error_found = 0;end;
                    else
                        fprintf('|%s not found!\n', cluster_fn);
                        error_found = 1;
                    end
                    
                    %now check, if a valid Clusterfile is found in the group1 dir
                    cluster_fn = fullfile(g1_group_dir, ['Cluster_' g1_list{i_subj} '.mat']);
                    if ~exist(cluster_fn)
                        fprintf('|%s not found!\n', cluster_fn);
                        error_found = 1;
                    end
                    
                    if error_found == 0 & check_only == 0
                        IN = load(flist{1});
                        load(cluster_fn);
                        if ~isempty(IN)
                            if check_is_cluster_in_cluster(app, Cluster, IN.Cluster) == 0;
                                Cluster = [Cluster; IN.Cluster];
                                save(cluster_fn, 'Cluster');
                                fprintf('|%i clusters added to: %s\n', length(IN.Cluster), cluster_fn);
                            else
                                fprintf('Error: could not merge clusters, some doubled cluster_names ddetected!\n');
                            end
                            
                            
                        else
                            fprintf('Error occured loading: %s\n', flist{1});
                        end
                    end
                    
                    
                end %i_subj
            end
        end %check_add_surf_cluster
        
        function error_found = check_is_cluster_in_cluster(app, Cluster1, Cluster2)
            error_found = 0;
            if isempty(Cluster1) | isempty(Cluster2) %trivialster fall, dann sind beide cluster leer
                return
            end
            
            for i1 = 1:length(Cluster1)
                c1_list{i1} = Cluster1{i1}.name;
            end
            
             for i1 = 1:length(Cluster2)
                c2_list{i1} = Cluster2{i1}.name;
            end
            
            error_found = max(ismember(c1_list, c2_list));
%             if error_found
%                 disp('doubled clusters found:');
%                 disp(c1_list(find(ismember(c1_list, c2_list))));
%             end
        end
        
        function results = check_sb_preprocesser(app)
            
            if isempty(which('sb_gen_plot_pw'))
                results =0;
            else
                results =1;
            end
        end
        
        function results = merge_Subject(app,listg1,listg2,prefix)
            fprintf('merging Subjects\n');
            assignin('base','listg1',listg1);
            assignin('base','listg2',listg2);
            %speichere die Ergebnisse im Verzeichnis 1 ab
            if size(listg1)~=size(listg2) 
                fprintf('please select only one entry in each column\n');
                return
            end
            
            for j = 1:size(listg1,2)
                [save_dir1,file1,ext1] = fileparts(listg1{1,j});
                [save_dir2,file2,ext2] = fileparts(listg2{1,j});
                
                f1 = listg1{1,j};
                f2 = listg2{1,j};
                
                load(f1);
                C1 = Cluster;
                load(f2);
                C2 = Cluster;
                Cluster = C1;
                
                for i=1:size(C1,1)
                    % veraendert werden muss nur die Cluster2Cluster Struktur
                    % hier kann man die P WErte nicht verwenden ... diese werden geloescht
                    % die R-werte und Z-Werte werden subtrahiert Cluster1 - Cluster2
                    Cluster{i,1}.Cluster2Cluster.R =C1{i,1}.Cluster2Cluster.R - C2{i,1}.Cluster2Cluster.R;
                    Cluster{i,1}.Cluster2Cluster.Rc1 =C1{i,1}.Cluster2Cluster.Rc1 - C2{i,1}.Cluster2Cluster.Rc1;
                    Cluster{i,1}.Cluster2Cluster.Rc2 =C1{i,1}.Cluster2Cluster.Rc2 - C2{i,1}.Cluster2Cluster.Rc2;
                    Cluster{i,1}.Cluster2Cluster.Z =C1{i,1}.Cluster2Cluster.Z - C2{i,1}.Cluster2Cluster.Z;
                    Cluster{i,1}.Cluster2Cluster.Zc1 =C1{i,1}.Cluster2Cluster.Zc1 - C2{i,1}.Cluster2Cluster.Zc1;
                    Cluster{i,1}.Cluster2Cluster.Zc2 =C1{i,1}.Cluster2Cluster.Zc2 - C2{i,1}.Cluster2Cluster.Zc2;
                    Cluster{i,1}.Cluster2Cluster.P(:,1) = 1;
                    Cluster{i,1}.Cluster2Cluster.Pc1(:,1) = 1;
                    Cluster{i,1}.Cluster2Cluster.Pc2(:,1) = 1;
                    
                end
                save(fullfile(save_dir1,[prefix,file1,ext1]),'Cluster');
            end
        end
        
    end
    

    methods (Access = private)

        % Button pushed function: Buttonchooseg1dir
        function Buttonchooseg1dirPushed(app, event)
            p = uigetdir(app.resultsdirEditField.Value, 'Select  dir for Clusterdata of group 1');
            if p == 0, return, end;
            app.g1dirEditField.Value = p;
        end

        % Button pushed function: Buttonchooseresultsdir
        function ButtonchooseresultsdirPushed(app, event)
            p = uigetdir(app.resultsdirEditField.Value, 'Select root dir for results and Cluster struct');
            if p == 0, return, end;
            app.resultsdirEditField.Value = p;
        end

        % Button pushed function: C2CchooseexelfileButton
        function C2CchooseexelfileButtonPushed(app, event)
            [f,p]=uigetfile('*.mat');
            app.ExelFileEditField.Value = fullfile(p,f);
        end

        % Button pushed function: C2CestimateClusterstatisticButton
        function C2CestimateClusterstatisticButtonPushed(app, event)
            resultsdir = app.resultsdirEditField.Value;
                app.C2CestimatedataLamp.Color = 'red';
                
            dir1=app.g1dirEditField.Value;
            dir2=app.g1dirEditField.Value;
            
            %erstelle die zu übergebende Datenstruktur
            if app.C2CtwosampleButton.Value
                method = 2;
            elseif app.C2CpairedButton.Value
                method = 3;
            end
            
            unsel_g1 = get_unselected_idx(app,app.C2Cgroup1ListBox);
            unsel_g2 = get_unselected_idx(app, app.C2Cgroup2ListBox);
            
            
            app.namelist1 = app.C2Cgroup1ListBox.Value;
            app.namelist2 = app.C2Cgroup2ListBox.Value;
            
            [app.C2C_stat_g1_des] = deslect_subjects_ck(app, app.C2C_stat_g1_org, app.namelist1, unsel_g1);
            [app.C2C_stat_g2_des] = deslect_subjects_ck(app, app.C2C_stat_g2_org, app.namelist2, unsel_g2);
            

            data=struct;
            if isempty(app.namelist1)
                msgbox('you have to selct at least one subject of group1');
                return
            else
                numgroup=1;
                assignin('base','C2C_stat_g1_des',app.C2C_stat_g1_des);
                % Uebertragung aller Korrelationsmasse von Gruppe 1
                if isfield(app.C2C_stat_g1_des,'R'); data.R1=app.C2C_stat_g1_des.R;  end
                if isfield(app.C2C_stat_g1_des,'P'); data.P1=app.C2C_stat_g1_des.P;  end
                if isfield(app.C2C_stat_g1_des,'Z'); data.Z1=app.C2C_stat_g1_des.Z;  end
                if isfield(app.C2C_stat_g1_des,'Syn'); data.Syn1=app.C2C_stat_g1_des.Syn;  end
                if isfield(app.C2C_stat_g1_des,'PLV'); data.PLV1=app.C2C_stat_g1_des.PLV;  end
                if isfield(app.C2C_stat_g1_des,'T'); data.T1=app.C2C_stat_g1_des.T; end
                if isfield(app.C2C_stat_g1_des,'Coh'); data.Coh1=app.C2C_stat_g1_des.Coh;  end
                if isfield(app.C2C_stat_g1_des,'CohF'); data.CohF1=app.C2C_stat_g1_des.CohF;  end
                if isfield(app.C2C_stat_g1_des,'Comp'); data.Comp1=app.C2C_stat_g1_des.Comp; end
                if isfield(app.C2C_stat_g1_des,'Power'); data.Power1=app.C2C_stat_g1_des.Power;  end
                if isfield(app.C2C_stat_g1_des,'RN'); data.RN1=app.C2C_stat_g1_des.RN;  end
                if isfield(app.C2C_stat_g1_des,'RiN'); data.RiN1=app.C2C_stat_g1_des.RiN;  end
                if isfield(app.C2C_stat_g1_des,'ZN'); data.ZN1=app.C2C_stat_g1_des.ZN;  end
                if isfield(app.C2C_stat_g1_des,'ZiN'); data.ZiN1=app.C2C_stat_g1_des.ZiN;  end
                if isfield(app.C2C_stat_g1_des,'GNM'); data.GNM1=app.C2C_stat_g1_des.GNM;  end
                if isfield(app.C2C_stat_g1_des,'GiNM'); data.GiNM1=app.C2C_stat_g1_des.GiNM;  end
            end
            if isempty(app.namelist2)
                msgbox('you have to select at least one dataset of group 2');
                return
            else
                numgroup=2;
                if isfield(app.C2C_stat_g2_des,'R'); data.R2=app.C2C_stat_g2_des.R;  end
                if isfield(app.C2C_stat_g2_des,'P'); data.P2=app.C2C_stat_g2_des.P;  end
                if isfield(app.C2C_stat_g2_des,'Z'); data.Z2=app.C2C_stat_g2_des.Z;  end
                if isfield(app.C2C_stat_g2_des,'Syn'); data.Syn2=app.C2C_stat_g2_des.Syn;  end
                if isfield(app.C2C_stat_g2_des,'PLV'); data.PLV2=app.C2C_stat_g2_des.PLV;  end
                if isfield(app.C2C_stat_g2_des,'T'); data.T2=app.C2C_stat_g2_des.T;  end
                if isfield(app.C2C_stat_g2_des,'Coh'); data.Coh2=app.C2C_stat_g2_des.Coh;  end
                if isfield(app.C2C_stat_g2_des,'CohF'); data.CohF2=app.C2C_stat_g2_des.CohF;  end
                if isfield(app.C2C_stat_g2_des,'Comp'); data.Comp2=app.C2C_stat_g2_des.Comp; end
                if isfield(app.C2C_stat_g2_des,'Power'); data.Power2=app.C2C_stat_g2_des.Power;  end
                if isfield(app.C2C_stat_g2_des,'RN'); data.RN2=app.C2C_stat_g2_des.RN;  end
                if isfield(app.C2C_stat_g2_des,'RiN'); data.RiN2=app.C2C_stat_g2_des.RiN;  end
                if isfield(app.C2C_stat_g2_des,'ZN'); data.ZN2=app.C2C_stat_g2_des.ZN;  end
                if isfield(app.C2C_stat_g2_des,'ZiN'); data.ZiN2=app.C2C_stat_g2_des.ZiN;  end
                if isfield(app.C2C_stat_g1_des,'GNM'); data.GNM2=app.C2C_stat_g1_des.GNM;  end
                if isfield(app.C2C_stat_g1_des,'GiNM'); data.GiNM2=app.C2C_stat_g1_des.GiNM;  end           
            end            
            
            unc = 0.05;
            fdr = 0.05;
            fwe = 0.05;
            % Festlegung welche Daten ... Y oder Yc1 oder Yc2
            
            normalize = app.C2CnormalizeCheckBox.Value;
            C2C_stat=ck_rc3_estimate_C2C_statistic(resultsdir,unc,fdr,fwe,data,numgroup,method,normalize);
            
            
            % Berechnung der Netzwerkkonnektivitaet falls diese zuvor spezifiziert
            % wurde (Cluster{i}.group)
            load(fullfile(resultsdir,'Cluster.mat'));
            %if isfield(Cluster{1},'group')
            if isfield(Cluster{1},'net')
                Network=ck_rc3_estimate_C2C_network_groups_statistic(resultsdir,unc,fdr,fwe,...
                    data,numgroup,method,normalize,app.C2C_stat,Cluster);
               Network2=ck_rc3_estimate_C2C_network_groups_statistic2(resultsdir,unc,fdr,fwe,...
                    data,numgroup,method,normalize,app.C2C_stat,Cluster);
                            C2C_stat.Network=Network;
            C2C_stat.Network2=Network2;
            end
            %C2C_stat = C2C_stat;        

            C2C_stat.clusternames=app.C2C_stat_g1.clusternames;
            app.C2C_stat=C2C_stat;
            %app.C2C_stat = app.C2C_stat;
            
            fprintf('results written to workspace: \n\tC2C_stat\n');
            assignin('base','C2C_stat',C2C_stat);
            assignin('base','datax',data);
            save(fullfile(resultsdir,'C2C_stat.mat'),'C2C_stat');
            
            app.C2CestimatedataLamp.Color = 'green';
        end

        % Button pushed function: C2CestimateClusterstatisticButton_2
        function C2CestimateClusterstatisticButton_2Pushed(app, event)
            resultsdir = app.resultsdirEditField.Value;
            %dir1=app.g1dirEditField.Value;
            %dir2=app.g2dirEditField.Value;
            
            %erstelle die zu übergebende Datenstruktur
            if app.C2CtwosampleButton.Value
                method = 2;
            elseif app.C2CpairedButton.Value
                method = 3;
            end
            
            unsel_g1 = get_unselected_idx(app,app.C2Cgroup1ListBox);
            unsel_g2 = get_unselected_idx(app, app.C2Cgroup2ListBox);
            
            
            app.namelist1 = app.C2Cgroup1ListBox.Value;
            app.namelist2 = app.C2Cgroup2ListBox.Value;
            
            [app.C2C_stat_g1_des] = deslect_subjects_ck2(app, app.C2C_stat_g1_org, app.namelist1, unsel_g1);
            [app.C2C_stat_g2_des] = deslect_subjects_ck2(app, app.C2C_stat_g2_org, app.namelist2, unsel_g2);


            data=struct;
            if isempty(app.namelist1) || isempty(app.namelist2)
                msgbox('you have to selct at least one subject of each group');
                return
            else
                numgroup=2;
            end

            normalize = app.C2CnormalizeCheckBox.Value;
            C2C_stat=ck_rc3_estimate_C2C_statistic2(resultsdir,app.C2C_stat_g1_des,app.C2C_stat_g2_des,numgroup,method,normalize);
            %               unc = 0.05;
            %             fdr = 0.05;
            %             fwe = 0.05;
            %             assignin('base','C2C_stat',C2C_stat);
            %             % Oberflaeche
            %             %C2C_stat.Oberflaeche = .... % noch einzufuegen
            %             % Berechnung der Netzwerkkonnektivitaet falls diese zuvor spezifiziert
            %             % wurde (Cluster{i}.group)
            %             load(fullfile(resultsdir,'Cluster.mat'));
            %             %if isfield(Cluster{1},'group')
            %             if isfield(Cluster{1},'net')
            %                 IntraNetwork=ck_rc3_estimate_C2C_network_groups_statistic(resultsdir,unc,fdr,fwe,...
            %                     data,numgroup,method,normalize,app.C2C_stat,Cluster);
            %                Network2=ck_rc3_estimate_C2C_network_groups_statistic2(resultsdir,unc,fdr,fwe,...
            %                     data,numgroup,method,normalize,app.C2C_stat,Cluster);
            %                Network3=ck_rc3_estimate_C2C_network_groups_statistic3(resultsdir,unc,fdr,fwe,...
            %                     data,numgroup,method,normalize,app.C2C_stat,Cluster);
            %             end
            %             %C2C_stat = C2C_stat;        
            %             C2C_stat.Network=Network3;
            %             %C2C_stat.Network2=Network2;
             %            C2C_stat.C2C.clusternames=app.C2C_stat_g1.clusternames;
            %             %app.C2C_stat=C2C_stat;
             app.C2C_stat = C2C_stat;
            %             assignin('base','C2C_stat',C2C_stat);
             save(fullfile(resultsdir,'C2C_stat.mat'),'C2C_stat');
            %             save(fullfile(resultsdir,'IntraNetwork.mat'),'IntraNetwork');
            %               
            assignin('base','C2C_stat',C2C_stat);
            app.C2CestimatedataLamp.Color = 'green';
        end

        % Button pushed function: C2CestimatecorrelationButton
        function C2CestimatecorrelationButtonPushed(app, event)
            fprintf('estimate exel correlation');
            [varnamelistsel,BG1,BG2] = get_selected_exel_data(app);
            load(fullfile(app.resultsdirEditField.Value,'parameter.mat'));
            if ~exist(fullfile(app.resultsdirEditField.Value,'C2C_stat.mat'))
                fprintf('%s not found, perform ESTIMATE CLUSTER STAISTICS first\n', fullfile(app.resultsdirEditField.Value,'C2C_stat.mat'));
                return;
            end
            load(fullfile(app.resultsdirEditField.Value,'C2C_stat.mat'));
            parameter.behavioraldata = [BG1 ;BG2];
            parameter.behavioraltitle = varnamelistsel;
            save(fullfile(app.resultsdirEditField.Value,'parameter.mat'),'parameter');
            
            h=waitbar(0,'Please wait estimating');
            waitbar(1 / 4);
            [G1] = get_Rg(app,app.resultsdirEditField.Value,1,BG1);
            G1.BG = BG1;
            G1.TG = varnamelistsel;
            waitbar(2/4);
            [G2] = get_Rg(app,app.resultsdirEditField.Value,2,BG2);
            G2.BG = BG2;
            G2.TG = varnamelistsel;
            waitbar(3/4);
            G = estimate_GroupDiff(app,G1,G2);
            G.G1 = G1;
            G.G2 = G2;
            G.clusternames = C2C_stat.clusternames;
            G.clusternames_org = G.clusternames;
            if isfield(C2C_stat, 'Network2')
                G.networknames = C2C_stat.Network2.networknames';
            end
            % waitbar(1);
            waitbar(4/4);
            
            C2C_stat.G = G;
            save(fullfile(app.resultsdirEditField.Value,'C2C_stat.mat'),'C2C_stat');
            app.C2C_stat = C2C_stat;
            close(h);
            app.C2CestimateexelcorrelationLamp.Color = 'green';
            
        end

        % Button pushed function: C2CestimatenetworkcorrelationButton
        function C2CestimatenetworkcorrelationButtonPushed(app, event)
            app.C2CestimateexelcorrelationLamp.Color = 'red';
            fprintf('estimate exel correlation');
            [varnamelistsel,BG1,BG2] = get_selected_exel_data(app);
            load(fullfile(app.resultsdirEditField.Value,'parameter.mat'));
            load(fullfile(app.resultsdirEditField.Value,'C2C_stat.mat'));
            parameter.behavioraldata = [BG1 ;BG2];
            parameter.behavioraltitle = varnamelistsel;
            save(fullfile(app.resultsdirEditField.Value,'parameter.mat'),'parameter');
            
            h=waitbar(0,'Please wait estimating');
            waitbar(1 / 4);
            [G1] = get_Rg(app,app.resultsdirEditField.Value,1,BG1);
            G1.BG = BG1;
            G1.TG = varnamelistsel;
            waitbar(2/4);
            [G2] = get_Rg(app,app.resultsdirEditField.Value,2,BG2);
            G2.BG = BG2;
            G2.TG = varnamelistsel;
            waitbar(3/4);
            G = estimate_GroupDiff(app,G1,G2);
            G.G1 = G1;
            G.G2 = G2;
            G.clusternames = C2C_stat.clusternames;
            G.clusternames_org = G.clusternames;
            G.networknames = C2C_stat.Network2.networknames';
            % waitbar(1);
            waitbar(4/4);
            
            C2C_stat.G = G;
            save(fullfile(app.resultsdirEditField.Value,'C2C_stat.mat'),'C2C_stat');
            app.C2C_stat = C2C_stat;
            close(h);
            app.C2CestimateexelcorrelationLamp.Color = 'green';
            
        end

        % Button pushed function: C2CexelreadmeButton
        function C2CexelreadmeButtonPushed(app, event)
            readme{1,1} = 'Behavioral data\n';
            readme{2,1} = '-----------------------------------------------------------\n';
            readme{3,1} = 'Datenformat\n';
            readme{4,1} = 'Daten werden als Exel file uebergeben\n';
            readme{5,1} = 'die erste Zeile Kennzeichnet die Varibalennamen \n';
            readme{6,1} = 'Eine Spalte muss den Titel "ID" tragen\n';
            readme{7,1} = 'Diese ID wird mit den letzten 4 Ziffern der Datensatznamen\n';
            readme{8,1} = 'verglichen und die entsprechenden Werte den Probanden/Patienten\n';
            readme{9,1} = 'Zugeordnet\n';
            
            txt = '';
            for i=1:size(readme,1)
                txt = [txt readme{i,1}]; %#ok<AGROW>
            end
            helpdlg(sprintf(txt));
        end

        % Button pushed function: C2CloaddataButton
        function C2CloaddataButtonPushed(app, event)
         app.C2CloaddataLamp.Color = 'red';  
         app.C2CloaddataLamp_GCsb.Color = 'red';
         app.C2CloaddataLamp_ASDFsb.Color = 'red';
         app.C2CloaddataLamp_Corrsb.Color = 'red';
         app.C2CloaddataLamp_schreiber_sb.Color = 'red';app.C2CloaddataLamp_TE_trentool_results.Color = 'red';
         
            load(fullfile(app.resultsdirEditField.Value,'parameter.mat'));
            load(fullfile(app.resultsdirEditField.Value,'Cluster.mat'));
            
            [app.namelist1,app.namelist2] = get_all_selected_filenames(app,'part');
            resultsdir = app.resultsdirEditField.Value;
            dir1=app.g1dirEditField.Value;
            dir2=app.g2dirEditField.Value;
            namelistpre1=app.namelist1;
            namelistpre2=app.namelist2;
            if app.C2CYButton.Value; choosedata = 'Y';  postfix = ''; end
            if app.C2CYc1Button.Value; choosedata = 'Yc1';  postfix = 'c1'; end
            if app.C2CYc2Button.Value; choosedata = 'Yc2';  postfix = 'c2'; end
            
            if app.C2CtwosampleButton.Value
                if ~isempty(namelistpre1)
                    assignin('base','dir1',dir1)
                    app.C2C_stat_g1=make_C2C_group_struct(app,dir1,app.namelist1,postfix);
                    app.C2C_stat_g1.method = 2;
                    if isfield(Cluster,'net')
                        
                        app.C2C_stat_g1 = ck_rc3_get_C2C_network_values(resultsdir,app.C2C_stat_g1,Cluster);
                    end
                    app.C2C_stat_g1_org = app.C2C_stat_g1;
                    C2C_stat_g1 = app.C2C_stat_g1;
                    save(fullfile(app.resultsdirEditField.Value,'C2C_stat_g1.mat'),'C2C_stat_g1');
                end
                % nochmal für die zweite Gruppe falls gewählt
                if ~isempty(namelistpre2)
                    app.C2C_stat_g2=make_C2C_group_struct(app,dir2,app.namelist2,postfix);
                    app.C2C_stat_g2.method = 2;
                    if isfield(Cluster,'net')
                        app.C2C_stat_g2 = ck_rc3_get_C2C_network_values(resultsdir,app.C2C_stat_g2,Cluster);
                    end
                    app.C2C_stat_g2_org = app.C2C_stat_g2;
                    C2C_stat_g2 = app.C2C_stat_g2;
                    save(fullfile(app.resultsdirEditField.Value,'C2C_stat_g2.mat'),'C2C_stat_g2');
                    
                end
            end
       
            %%%%%%%%%%%%%%%%%
            %%% paired t-test
            if app.C2CpairedButton.Value
                fprintf('paired t-test not adpated .. a little work...\n');
            %                 
            %                 %%%%%%modified sb 28.07.2012
            %                 if get(handles.radiobuttonpairedgroups,'Value')
            %                     handles.method=3;
            %                     dir1=get(handles.editdatadirg1,'String');
            %                     dir2=get(handles.editdatadirg2,'String');
            %                     gesetzt=0;
            %                     if isfield(parameter,'C2Ccontrolparameter')
            %                         
            %                         if isfield(parameter.C2Ccontrolparameter,'pairedgroup1')
            %                             choice = questdlg('soll die alte paired liste verwendet werden:','load previous', 'Yes', 'No','No');
            %                             % Handle response
            %                             switch choice
            %                                 case 'Yes'
            %                                     app.namelist1=parameter.C2Ccontrolparameter.pairedgroup1;
            %                                     app.namelist2=parameter.C2Ccontrolparameter.pairedgroup2;
            %                                     gesetzt=1;
            %                                     
            %                                 case 'No'
            %                                     
            %                             end
            %                         end
            %                     end
            %                     % wenn noch nicht eingetragen dann frage ab
            %                     if ~gesetzt
            %                         [t1,sts1]=spm_select([1 Inf],'mat',...
            %                             'Please select C2C_* Files in the correct (paired) oder ',[],dir1,'Cluster_');
            %                         [t2,sts2]=spm_select(size(t1,1),'mat',...
            %                             'Please select C2C_* Files in the correct (paired) oder ',[],dir2,'Cluster_');
            %                         for i=1:size(t1,1)
            %                             [p1 f1 e1 ] = fileparts(t1(i,:));
            %                             [p2 f2 e2 ] = fileparts(t2(i,:));
            %                             app.namelist1{i,1}=[f1 '.mat'];
            %                             app.namelist2{i,1}=[f2 '.mat'];
            %                         end
            %                     end
            %                     %     tmp2name=dir(fullfile(dir2,'AAL_*.mat'));
            %                     %     for i=1:size(tmp2name,1)
            %                     %         app.namelist2{i,1}=tmp2name(i,1).name;
            %                     %     end
            %                     assignin('base','app.namelist1',app.namelist1);
            %                     assignin('base','app.namelist2',app.namelist2);
            %                     set(handles.textg1subjects,'String',num2str(size(app.namelist1,1)));
            %                     set(handles.textg2subjects,'String',num2str(size(app.namelist2,1)));
            %                     namelistpre1=app.namelist1;
            %                     namelistpre2=app.namelist2;
            %                     handles.C2C_stat_g1=make_C2C_group_struct(dir1,app.namelist1);
            %                     handles.C2C_stat_g2=make_C2C_group_struct(dir2,app.namelist2);
            %                     % erstelle stuct nur zum Abspeichern
            %                     C2C_stat_g1=struct;
            %                     C2C_stat_g2=struct;
            %                     C2C_stat_g1=handles.C2C_stat_g1;C2C_stat_g1.method = handles.method;
            %                     C2C_stat_g2=handles.C2C_stat_g2;C2C_stat_g2.method = handles.method;
            %                     handles.C2C_stat_g1_org = C2C_stat_g1;
            %                     handles.C2C_stat_g2_org = C2C_stat_g2;
            %                     save(fullfile(app.resultsdirEditField.Value,'C2C_stat_g1.mat'),'C2C_stat_g1');
            %                     save(fullfile(app.resultsdirEditField.Value,'C2C_stat_g2.mat'),'C2C_stat_g2');
            %                 end
            end
            %%% end modified sb 28.07.2012
            
            app.C2Cgroup1ListBox.Items = app.namelist1;
            app.C2Cgroup2ListBox.Items = app.namelist2;
            
            app.C2CloaddataLamp.Color = 'green';
            
            
            % speichere die Eintragungen zur späteren Verwendung
            % bei erneuten Aufrufen der Gui damit diese dann sofort automatisch
            % eingetragen werden können
            %             load(fullfile(app.resultsdirEditField.Value,'parameter.mat'));
            %             parameter.C2Ccontrolparameter.dir1=get(app.g1dirEditField.Value);
            %             parameter.C2Ccontrolparameter.dir2=get(handles.editdatadirg2,'String');
            %             parameter.C2Ccontrolparameter.radiobuttononegroup=get(handles.radiobuttononegroup,'Value');
            %             parameter.C2Ccontrolparameter.radiobuttontwogroups=get(handles.radiobuttontwogroups,'Value');
            %             parameter.C2Ccontrolparameter.radiobuttonpairedgroups=get(handles.radiobuttonpairedgroups,'Value');
            %             parameter.C2Ccontrolparameter.editg1datafile=handles.editg1datafile.String;
            %             parameter.C2Ccontrolparameter.editg1titlefile=handles.editg1titlefile.String;
            %             parameter.C2Ccontrolparameter.editg2datafile=handles.editg2datafile.String;
            %             parameter.C2Ccontrolparameter.editg2titlefile=handles.editg2titlefile.String;
            %             save(fullfile(app.resultsdirEditField.Value,'parameter.mat'),'parameter');
            %            guidata(hObject,handles);
            assignin('base', 'dd', app);
        end

        % Button pushed function: C2CloaddataButton_2
        function C2CloaddataButton_2Pushed(app, event)
            load(fullfile(app.resultsdirEditField.Value,'parameter.mat'));
            load(fullfile(app.resultsdirEditField.Value,'Cluster.mat'));
            
            [app.namelist1,app.namelist2] = get_all_selected_filenames(app,'part');
            resultsdir = app.resultsdirEditField.Value;
            dir1=app.g1dirEditField.Value;
            dir2=app.g2dirEditField.Value;
            namelistpre1=app.namelist1;
            namelistpre2=app.namelist2;
            if app.C2CYButton.Value; choosedata = 'Y';  postfix = ''; end
            if app.C2CYc1Button.Value; choosedata = 'Yc1';  postfix = 'c1'; end
            if app.C2CYc2Button.Value; choosedata = 'Yc2';  postfix = 'c2'; end
            
            if app.C2CtwosampleButton.Value
                if ~isempty(namelistpre1)
                    assignin('base','dir1',dir1)
                    app.C2C_stat_g1=make_C2C_group_struct2(app,dir1,app.namelist1,postfix);
                    app.C2C_stat_g1.method = 2;
                    app.C2C_stat_g1.NET = ck_rc3_get_C2C_network_values3(resultsdir,app.C2C_stat_g1,Cluster);
                    app.C2C_stat_g1_org = app.C2C_stat_g1;
                    C2C_stat_g1 = app.C2C_stat_g1;
                    save(fullfile(app.resultsdirEditField.Value,'C2C_stat_g1v2.mat'),'C2C_stat_g1');
                end
                % nochmal für die zweite Gruppe falls gewählt
                if ~isempty(namelistpre2)
                    app.C2C_stat_g2=make_C2C_group_struct2(app,dir2,app.namelist2,postfix);
                    app.C2C_stat_g2.method = 2;
                    app.C2C_stat_g2.NET = ck_rc3_get_C2C_network_values3(resultsdir,app.C2C_stat_g2,Cluster);
                    app.C2C_stat_g2_org = app.C2C_stat_g2;
                    C2C_stat_g2 = app.C2C_stat_g2;
                    save(fullfile(app.resultsdirEditField.Value,'C2C_stat_g2v2.mat'),'C2C_stat_g2');
                    
                end
            end
       
            %%%%%%%%%%%%%%%%%
            %%% paired t-test
            if app.C2CpairedButton.Value
                fprintf('paired t-test not adpated .. a little work...\n');
            %                 
            %                 %%%%%%modified sb 28.07.2012
            %                 if get(handles.radiobuttonpairedgroups,'Value')
            %                     handles.method=3;
            %                     dir1=get(handles.editdatadirg1,'String');
            %                     dir2=get(handles.editdatadirg2,'String');
            %                     gesetzt=0;
            %                     if isfield(parameter,'C2Ccontrolparameter')
            %                         
            %                         if isfield(parameter.C2Ccontrolparameter,'pairedgroup1')
            %                             choice = questdlg('soll die alte paired liste verwendet werden:','load previous', 'Yes', 'No','No');
            %                             % Handle response
            %                             switch choice
            %                                 case 'Yes'
            %                                     app.namelist1=parameter.C2Ccontrolparameter.pairedgroup1;
            %                                     app.namelist2=parameter.C2Ccontrolparameter.pairedgroup2;
            %                                     gesetzt=1;
            %                                     
            %                                 case 'No'
            %                                     
            %                             end
            %                         end
            %                     end
            %                     % wenn noch nicht eingetragen dann frage ab
            %                     if ~gesetzt
            %                         [t1,sts1]=spm_select([1 Inf],'mat',...
            %                             'Please select C2C_* Files in the correct (paired) oder ',[],dir1,'Cluster_');
            %                         [t2,sts2]=spm_select(size(t1,1),'mat',...
            %                             'Please select C2C_* Files in the correct (paired) oder ',[],dir2,'Cluster_');
            %                         for i=1:size(t1,1)
            %                             [p1 f1 e1 ] = fileparts(t1(i,:));
            %                             [p2 f2 e2 ] = fileparts(t2(i,:));
            %                             app.namelist1{i,1}=[f1 '.mat'];
            %                             app.namelist2{i,1}=[f2 '.mat'];
            %                         end
            %                     end
            %                     %     tmp2name=dir(fullfile(dir2,'AAL_*.mat'));
            %                     %     for i=1:size(tmp2name,1)
            %                     %         app.namelist2{i,1}=tmp2name(i,1).name;
            %                     %     end
            %                     assignin('base','app.namelist1',app.namelist1);
            %                     assignin('base','app.namelist2',app.namelist2);
            %                     set(handles.textg1subjects,'String',num2str(size(app.namelist1,1)));
            %                     set(handles.textg2subjects,'String',num2str(size(app.namelist2,1)));
            %                     namelistpre1=app.namelist1;
            %                     namelistpre2=app.namelist2;
            %                     handles.C2C_stat_g1=make_C2C_group_struct(dir1,app.namelist1);
            %                     handles.C2C_stat_g2=make_C2C_group_struct(dir2,app.namelist2);
            %                     % erstelle stuct nur zum Abspeichern
            %                     C2C_stat_g1=struct;
            %                     C2C_stat_g2=struct;
            %                     C2C_stat_g1=handles.C2C_stat_g1;C2C_stat_g1.method = handles.method;
            %                     C2C_stat_g2=handles.C2C_stat_g2;C2C_stat_g2.method = handles.method;
            %                     handles.C2C_stat_g1_org = C2C_stat_g1;
            %                     handles.C2C_stat_g2_org = C2C_stat_g2;
            %                     save(fullfile(app.resultsdirEditField.Value,'C2C_stat_g1.mat'),'C2C_stat_g1');
            %                     save(fullfile(app.resultsdirEditField.Value,'C2C_stat_g2.mat'),'C2C_stat_g2');
            %                 end
            end
            %%% end modified sb 28.07.2012
            
            app.C2Cgroup1ListBox.Items = app.namelist1;
            app.C2Cgroup2ListBox.Items = app.namelist2;
            
            app.C2CloaddataLamp.Color = 'green';


        end

        % Button pushed function: C2CmeanZdiffButton
        function C2CmeanZdiffButtonPushed(app, event)
            app.cfg.mode = 'Correlation';
            view_sb_conn_results(app)
        end

        % Button pushed function: C2CshownetworksButton
        function C2CshownetworksButtonPushed(app, event)
            fprintf('show networks\n');
            ck_rc3_show_networkgroup_results(app.resultsdirEditField.Value)
        end

        % Button pushed function: C2CvisualizenetworkcorrelationButton
        function C2CvisualizenetworkcorrelationButtonPushed(app, event)
            fprintf('C2CvisualizenetworkcorrelationButtonPushed');
            [B.TG,B.BG1,B.BG2] = get_selected_exel_data(app);
            %             try
            %                 load(fullfile(app.resultsdirEditField.Value,'C2C_stat.mat'));
            %                 load(fullfile(app.resultsdirEditField.Value,'parameter.mat'));
            %             catch
            %                 msgbox('no C2C_stat2.mat file');
            %             end
            %             
            %             parameter.behavioraldata = [BG1 ;BG2];
            %             parameter.behavioraltitle = varnamelistsel;
            %             save(fullfile(app.resultsdirEditField.Value,'parameter.mat'),'parameter');
            %             B.BG1 = BG1;
            %             B.BG2 = BG2;
            %             B.TG = varnamelistsel;
            
            ck_rc3_visualize_network_clusterbehavior2(app.resultsdirEditField.Value,app.C2C_stat,B);
            
        end

        % Button pushed function: DefineClusterButton
        function DefineClusterButtonPushed(app, event)
            d = pwd;
            % speichere den ausgewaehlten Namen des Beispieldatensatzes im parameterfile ab
            f = fullfile(app.resultsdirEditField.Value,'parameter.mat');
            load(f);
            parameter.define_cluster_input_file = app.exemplarydatafileEditField.Value;
            save(f,'parameter');
            
            cd(app.resultsdirEditField.Value);
            
            %checkpath
            if isempty(which('sb_roi_cluster_manager'))
             [pn, fn, ext] = fileparts(which('ck_rc3.mlapp'));
             [pn, fn, ext] = fileparts(pn);
             pn = fullfile(pn, 'sb_sonstiges', 'sb_roi_cluster_manager');
             addpath(pn);
            end
            
            %load clustermanager and try to load the cluster.mat
            temp_app = sb_roi_cluster_manager;
            temp_app.workingdirectoryDropDown.Items = {app.resultsdirEditField.Value};
            temp_app.load_cluster_at_start()
            
            %try to load resolution
            if ~isempty(app.exemplarydatafileEditField.Value)
                fprintf('\tloading NIFTI file: %\t', app.exemplarydatafileEditField.Value);
                if exist(app.exemplarydatafileEditField.Value)
                    V = spm_vol(app.exemplarydatafileEditField.Value);disp('done');
                     if ~isempty(V)
                        
                        temp_app.resolution.V = V;
                        temp_app.update_resolution;
                        disp('updating resolution settings');
                     end
                else
                   fprintf('|| File not found: %s\n',  app.exemplarydatafileEditField.Value);
                end
            end
            
            stat = listboxupdate(app);
            cd(d);
        end

        % Button pushed function: DefineNetworkButton
        function DefineNetworkButtonPushed(app, event)
            ck_rc3_define_network(app.resultsdirEditField.Value);
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            
            if app.CheckBox_append_cluster.Value
                disp('Starting to append Clusters');
                data_import_nifti(app, 'append');
            else
                fprintf('go for data import\n');
                data_import_nifti(app, 'new'); %changed by sb 28.05.18
            end
            
            parameterfile = fullfile(app.resultsdirEditField.Value, 'parameter.mat');
            
            parameter.TR = app.TREditField.Value;
            parameter.lpf = app.lpfEditField.Value;
            parameter.hpf = app.hpfEditField.Value;
            save(parameterfile,'parameter');
            
            disp('finished'); %sb030518
            
        end

        % Button pushed function: chooseg2dir
        function chooseg2dirButtonPushed(app, event)
            p = uigetdir(app.resultsdirEditField.Value, 'Select  dir for Clusterdata of group 2');
            if p == 0, return, end;
            app.g2dirEditField.Value = p; 
        end

        % Value changed function: coherenceSwitch
        function coherenceSwitchValueChanged(app, event)
            value = str2num(app.coherenceSwitch.Value);
            if value
                app.coherencePanel.BackgroundColor = 'green';
            else
                app.coherencePanel.BackgroundColor = [0.94 0.94 0.94];
            end
        end

        % Value changed function: complexitySwitch
        function complexitySwitchValueChanged(app, event)
            value = str2num(app.complexitySwitch.Value);
            if value
                app.complexityPanel.BackgroundColor = 'green';
            else
                app.complexityPanel.BackgroundColor = [0.94 0.94 0.94];
            end
        end

        % Value changed function: correlationSwitch
        function correlationSwitchValueChanged(app, event)
            value = str2num(app.correlationSwitch.Value);
            if value
                app.correlationsPanel.BackgroundColor = 'green';
            else
                app.correlationsPanel.BackgroundColor = [0.94 0.94 0.94];
            end            
            
        end

        % Button pushed function: 
        % deletenetworkdefinitionsfromClusterstructButton
        function deletenetworkdefinitionsfromClusterstructButtonPushed(app, event)
            h=waitbar(0,'Please wait ...');
            f = fullfile(app.resultsdirEditField.Value,'Cluster.mat');
            if exist(f)
                
                load(f);
                Cluster = del_network(app,Cluster);
                waitbar(2/3);
                save(f,'Cluster','-v7.3');
            else
                msgbox('no Cluster.mat file found in results dir','error');
            end
            waitbar(3/3)
            close(h);
        end

        % Button pushed function: estimateconnectivityButton
        function estimateconnectivityButtonPushed(app, event)
            [filenamesg1] = app.g1ListBox.Value;
            [filenamesg2] = app.g2ListBox.Value;
            resultsdir = app.resultsdirEditField.Value;
            %get_all_selected_filenames(handles,'part');
            %assignin('base','file',filenamesg1);
            %[filenamesg1, filenamesg2] = get_filenames(handles,'Cluster_');
            %outdir = get(handles.editoutdir,'String');
           
            load(fullfile(resultsdir,'parameter.mat'));
            %options = ck_rc2_estimate_connectivity_options();
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %  erstelle die zu uebergebende options struct
            options.parameter = parameter;
            options.cancel = 0;
            options.EstimateCorrelations = str2num(app.correlationSwitch.Value);
            options.EstimateCoherence = str2num(app.coherenceSwitch.Value);
            options.EstimatePartialCoherence = str2num(app.partialcoherenceSwitch.Value);
            options.EstimateTransinformation = str2num(app.transinformationSwitch.Value);
            options.EstimateSynchronization = str2num(app.synchronizationSwitch.Value);
            options.EstimateComplexity = str2num(app.complexitySwitch.Value);
            options.Transinformation_bins = app.histogramEditField.Value;
            options.Transinformation_lag = app.timelagEditField.Value;
            options.Synchronization_bins = app.histogramsynchronizationEditField.Value;
            options.Complexity_bins = app.windowlengthcomplexityEditField.Value;
            options.Complexity_r = app.similaritymeasurecomplexityEditField.Value;
            options.Cluster2Voxel = app.Cluster2VoxelCheckBox.Value;
            options.C2V_c1mask = app.C1maskthresholdCheckBox.Value;
            options.C2V_threshold= app.thresholdEditField.Value;
            options.C2V_smoothing= app.smoothingCheckBox.Value;
            options.C2V_kernel= eval(app.kernelEditField.Value);
            options.Cluster2Cluster= app.Cluster2ClusterCheckBox.Value;
            options.Voxel2Voxel= app.Voxel2VoxelCheckBox.Value;
            options.useY = app.conuseYCheckBox.Value;
            options.useYc1 = app.conuseYc1CheckBox.Value;
            options.useYc2 = app.conuseYc2CheckBox.Value;
            
            
            % options struct fertig erstellt
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Uebergabe der Paramter an die Funktion zur Berechnung der Connectivitaet
            
            if  ~isempty(filenamesg1)& ~strcmp(filenamesg1{1}, 'empty')  %changed sb0518
                ck_rc3_estimate_connectivity(resultsdir,app.g1dirEditField.Value,filenamesg1,options);
            end
            if  ~isempty(filenamesg2) & ~strcmp(filenamesg2{1}, 'empty') %changed sb0518 %changed sb0518
                ck_rc3_estimate_connectivity(resultsdir,app.g2dirEditField.Value,filenamesg2,options);
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % granger causality als extra aufruf
            
            if str2num(app.grangerSwitch.Value)
                
                GC_options = struct;
                GC_options.MultiSubjectAnalysis = 1;
                GC_options.EstimateCGCt = app.conditionalGCinthetimedomainCheckBox.Value;
                
                GC_options.nlagst = app.grangernumberoflagsEditField.Value;
                
                GC_options.pt = app.grangerpEditField.Value;
                GC_options.cort = 1;
                GC_options.EstimateCGCf = app.conditionalGCinfrequencydomainCheckBox.Value;
                GC_options.prep.detrend = app.grangerdetrenddataCheckBox.Value;
                GC_options.prep.removemean = app.grangerremovetemporalmeanCheckBox.Value;
                GC_options.prep.removemeanstd = app.grangerremovetemporalmeananddividebystdCheckBox.Value;
                GC_options.prep.differencing = app.grangerdifferencingtimeseriesCheckBox.Value;
                GC_options.prep.differencingit = app.grangeriterationEditField.Value;
                GC_options.EstimateGCautonomy = app.estimategrangerautonomyCheckBox.Value;
                GC_options.cluster2cluster = app.Cluster2ClusterCheckBox.Value;
                GC_options.cluster2voxel = app.Cluster2VoxelCheckBox.Value;
                GC_options.onegroup = 0;
                GC_options.twogroups = 1;
                GC_options.pairedgroups = 0;
                GC_options.freq = eval(app.grangerfrequencyEditField.Value);
                GC_options.prep.filter = 0;
                GC_options.prep.iffreqiteration = 0.01;
                GC_options.prep.freqiteration = app.grangerfrequencyiterationstepEditField.Value;
                
                GC_options.Cluster_sel_name = app.grangerselectedClusterListBox.Value;
                
                GC_options.Cluster_sel_num = length(options.Cluster_sel_name);
                CG_options.TR = parameter.TR;
                CG_options.parameter = parameter;
                % nun der Funktionsaufruf
                resultsdir = app.resultsdirEditField.Value;
                
                if  ~isempty(filenamesg1)
                    datadir = app.g1dirEditField.Value;
                    cGCt_groupA = ck_rc3_estimate_granger_causality(resultsdir,datadir,filenamesg1,GC_options);
                end
                if  ~isempty(filenamesg2)
                    datadir = app.g2dirEditField.Value;
                    cGCt_groupB = ck_rc3_estimate_granger_causality(resultsdir,datadir,filenamesg2,GC_options);
                end
                
                
            end
            % 
            % 
            % handles.output.MultiSubjectAnalysis = get(handles.radiobuttonmultisubjectanalysis,'Value');
            % handles.output.EstimateCGCt = get(handles.radiobuttonCGCtime,'Value');
            % handles.output.nlagst = str2double(get(handles.editnlagst,'String'));
            % handles.output.pt = str2double(get(handles.editpt,'String'));
            % handles.output.cort = (get(handles.popupmenucor,'Value'))-1;
            % handles.output.EstimateCGCf = get(handles.radiobuttonCGCfrequency,'Value');
            % handles.output.prep.detrend = get(handles.checkboxdetrend,'Value');
            % handles.output.prep.removemean = get(handles.checkboxremovetemporalmean,'Value');
            % handles.output.prep.removemeanstd = get(handles.checkboxremovetemporalmeanstdd,'Value');
            % handles.output.prep.differencing = get(handles.checkboxdifferencing,'Value');
            % handles.output.prep.differencingit = str2double(get(handles.editit,'String'));
            % handles.output.EstimateGCautonomy = get(handles.radiobuttongrangerautonomy,'Value');
            % handles.output.cluster2cluster = get(handles.radiobuttoncluster2cluster,'Value');
            % handles.output.cluster2voxel = get(handles.radiobuttoncluster2voxel,'Value');
            % handles.output.onegroup = get(handles.radiobuttononegroup,'Value');
            % handles.output.twogroups = get(handles.radiobuttontwogroups,'Value');
            % handles.output.pairedgroups = get(handles.radiobuttonpairedgroups,'Value');
            % handles.output.onegroupsingle = get(handles.radiobuttononegroupsingle,'Value');
            % handles.output.freq = eval((get(handles.editfrequency,'String')));
            % handles.output.prep.filter = get(handles.checkboxfreq,'Value');
            % handles.output.prep.iffreqiteration = get(handles.checkboxfreqiteration,'Value');
            % handles.output.prep.freqiteration = str2num(get(handles.editfreqiteration,'String'));
            % 
            % idx = get(handles.listbox1,'Value');
            % list = get(handles.listbox1,'String');
            % for i=1:size(idx,2);
            % handles.output.Cluster_sel_name{i} = list{idx(i)};
            % end
            % handles.output.Cluster_sel_num=idx;
            %             
            disp('finished'); %sb030518
        end

        % Button pushed function: exemplarydatadirButton
        function exemplarydatadirButtonPushed(app, event)
            [f,p] = uigetfile(fullfile(app.resultsdirEditField.Value, '*.nii'),'Select nifti image file');
            app.exemplarydatafileEditField.Value = fullfile(p,f);
        end

        % Button pushed function: g1dirdataimportButton
        function g1dirdataimportButtonPushed(app, event)
            p = uigetdir(app.resultsdirEditField.Value, 'Select root dir for data of group 1');
            if p==0, return, end;
            app.g1dirdataimportEditField.Value = p; 
        end

        % Button pushed function: g2dirdataimportButton
        function g2dirdataimportButtonPushed(app, event)
            p = uigetdir(app.resultsdirEditField.Value, 'Select root dir for data of group 2');
            
            if p==0, return, end;
            app.g2dirdataimportEditField.Value = p; 
        end

        % Value changed function: grangerSwitch
        function grangerSwitchValueChanged(app, event)
            value = str2num(app.grangerSwitch.Value);
            if value
                app.GrangercausalitybigPanel.BackgroundColor = 'green';
                app.GrangercausalityPanel.BackgroundColor = 'green';
                try
                    load(fullfile(app.resultsdirEditField.Value,'Cluster.mat'));
                    for i=1:length(Cluster)
                        list{i} = Cluster{i}.name;
                    end
                    app.grangerselectedClusterListBox.Items = list;
                catch
                    msgbox('Cluster struct is needed');
                end
            else
                app.GrangercausalityPanel.BackgroundColor = [0.94 0.94 0.94];
                app.GrangercausalitybigPanel.BackgroundColor = [0.94 0.94 0.94];
            end            
        end

        % Button pushed function: initnewButton
        function initnewButtonPushed(app, event)
            d = app.resultsdirEditField.Value;
            if isempty(d) | ~exist(d), disp('select a valid ouput folder first!'),return, end;
            
            
            f=fullfile(d,'parameter.mat');
            parameter = struct;
            if exist(f)
                answer = questdlg('parameter.mat exists ... overwrite?', 'Overwrite?', 'Yes','No','No');
                switch answer
                    case 'No'
                        return;
                end
            end
                save(f,'parameter');
                
            
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            cd(fileparts(which('ck_rc3.mlapp')));
            [f,p]=uigetfile('select lastsessionfile');
            
            load(fullfile(p,f),'lastsession');
            load_gui_lastsession_sb(app, lastsession);
           
            cd(app.resultsdirEditField.Value);
            
            stat = listboxupdate(app);
        end

        % Button pushed function: loadexelfileButton
        function loadexelfileButtonPushed(app, event)
            
            [Lnum,Ltxt,L] = xlsread(app.ExelFileEditField.Value);
            
            titel = L(1,:);
            
            %skala = L(2,:);
            exeldata = L(2:end,:);
            % extrahiere die IDs
            id_col=find(strcmp(titel,'ID'));
            app.IDlist = cell2mat(exeldata(:,id_col));
            assignin('base','titel',titel);
            app.ExelvariablesListBox.Items = titel;
            %app.ExelvariablesListBox.
            
            %set(handles.listboxexelvariables,'Value',1);
            app.exeltitel = titel;
            app.exeldata = exeldata;
            
            
        end

        % Button pushed function: loadlastButton
        function loadlastButtonPushed(app, event)
            try
                [~,result] = dos('getmac');
                mac = result(160:176);
            catch 
                mac = 'no_mac';
            end
            
            try
                local_file = fullfile(fileparts(which('ck_rc3.mlapp')),['lastsession_' mac '.mat']);
                load(local_file,'lastsession');
                load_gui_lastsession_sb(app, lastsession);%sb290518
                
                cd(app.resultsdirEditField.Value);
            catch
                fprintf('kein altes verzeichnis abgespeichert\n');
            end
            
            stat = listboxupdate(app);
        end

        % Value changed function: partialcoherenceSwitch
        function partialcoherenceSwitchValueChanged(app, event)
            value = str2num(app.partialcoherenceSwitch.Value);
            if value
                app.partialcoherencePanel.BackgroundColor = 'green';
            else
                app.partialcoherencePanel.BackgroundColor = [0.94 0.94 0.94];
            end
            
        end

        % Button pushed function: reduceClusterdataButton
        function reduceClusterdataButtonPushed(app, event)
            app.idleGauge.Value = 0;
            app.idleGaugeLabel.Text = 'working on cluster reduction';
            prefix = app.newprefixforClustermatfilesEditField.Value;
            
            [listg1,listg2] = get_all_selected_filenames(app,'full');
            reduce_Cluster(app,listg1,prefix);
            app.idleGauge.Value = 50;
            reduce_Cluster(app,listg2,prefix);
            app.idleGauge.Value = 100;
            app.idleGaugeLabel.Text = 'idle';
            
            
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            try
                [~,result] = dos('getmac');
                mac = result(160:176);
            catch
                mac = 'no_mac';
                fprintf('keine Mac adresse eruierbar\n');
            end
            
            lastsession = return_app_lastsession(app);
            local_file = fullfile(fileparts(which('ck_rc3.mlapp')),['lastsession_' mac '.mat']);

            save(local_file,'lastsession');
            fprintf('|Session saved to: %s\n', local_file);
            
            
        end

        % Value changed function: synchronizationSwitch
        function synchronizationSwitchValueChanged(app, event)
            value = str2num(app.synchronizationSwitch.Value);
            if value
                app.synchronizationPanel.BackgroundColor = 'green';
            else
                app.synchronizationPanel.BackgroundColor = [0.94 0.94 0.94];
            end
        end

        % Button pushed function: testforfilepresenceButton
        function testforfilepresenceButtonPushed(app, event)
            
             %           g1pathlist
            %fprintf('testforfilepresencebutton\n');
            %Items = app.g1dirdataimportListBox.Items
            %            Val = app.g1dirdataimportListBox.Value
            r1 = test_existence(app,app.g1dirdataimportEditField.Value, app.g1dirdataimportListBox.Value, app.g1dataimportsubdirnameEditField.Value);
            r2 = test_existence(app,app.g2dirdataimportEditField.Value, app.g2dirdataimportListBox.Value, app.g2dataimportsubdirnameEditField.Value);
            
            if (r1+r2)==0
                app.Lamp.Color='green';
                
            else
                app.Lamp.Color='red';
            end
            
        end

        % Value changed function: transinformationSwitch
        function transinformationSwitchValueChanged(app, event)
            value = str2num(app.transinformationSwitch.Value);
            
            if value
                app.transinformationPanel.BackgroundColor = 'green';
            else
                app.transinformationPanel.BackgroundColor = [0.94 0.94 0.94];
            end
        end

        % Button pushed function: updateButton
        function updateButtonPushed(app, event)
            stat = listboxupdate(app);
        end

        % Button pushed function: updatedataimportButton
        function updatedataimportButtonPushed(app, event)
            p1 = app.g1dirdataimportEditField.Value;
            p2 = app.g2dirdataimportEditField.Value;
            filter1 = app.g1dataimportsubdirnameEditField.Value;
            filter2 = app.g2dataimportsubdirnameEditField.Value;
            listg1 = get_updatedataimport_list(app,p1,filter1);
            listg2 = get_updatedataimport_list(app,p2,filter2);
            app.g1dirdataimportListBox.Items = listg1;
            app.g2dirdataimportListBox.Items = listg2;
            
            
        end

        % Button pushed function: visualizecorrelationButton
        function visualizecorrelationButtonPushed(app, event)
            % lade C2C_stat datei
            try
            load(fullfile(app.resultsdirEditField.Value,'C2C_stat.mat'));
            catch
                msgdlg('no C2C_stat.mat file');
            end
            ck_rc3_visualize_network_clusterbehavior(app.resultsdirEditField.Value,C2C_stat.G);
            
        end

        % Button pushed function: saveasButton
        function saveasButtonPushed(app, event)
            local_file = fullfile(app.resultsdirEditField.Value, 'lastsession.mat');
            [fn, pn] = uiputfile(local_file, 'save ck_rc3 session');
            if fn == 0, return, end
                        
            local_file = fullfile(pn, fn);
           
            lastsession = return_app_lastsession(app);
            
            
            save(local_file,'lastsession');
            fprintf('||settings saved to: %s\n', local_file);
        end

        % Button pushed function: addsuffixtofoldernameButton
        function addsuffixtofoldernameButtonPushed(app, event)
            disp('Rename Data folders and add a prefix...');
            %get folder/list
            g1_dir = app.g1dirdataimportEditField.Value;
            g2_dir = app.g2dirdataimportEditField.Value;
            g1_list = app.g1dirdataimportListBox.Value;
            g2_list = app.g2dirdataimportListBox.Value;
            
            if isempty(g1_list)&isempty(g2_list)
                disp('no subject folder selected!');
                return
            end
            %get PREFIX in dialog
            answ = inputdlg({'Enter prefix', 'remove CHAR at end:'}, 'ADD prefix to selected subject', 1, {'_ID999', '0'});
            if isempty(answ), return;end;
            rm_char = abs(str2num(answ{2}));
            %now change the filenames
                %G1List
                for il = 1:length(g1_list)
                    oldname = fullfile(g1_dir, g1_list{il});
                    newname = [oldname(1:end-rm_char) answ{1}];
                    movefile(oldname, newname);
                    fprintf('\trenaming: %s -> %s\n', oldname, newname);
                end
               
                %G2List
                for il = 1:length(g2_list)
                    oldname = fullfile(g2_dir, g2_list{il});
                    newname = [oldname(1:end-rm_char) answ{1}];
                    movefile(oldname, newname);
                    fprintf('\trenaming: %s -> %s\n', oldname, newname);
                end
            
            disp('finished, press UPDATE to reload lists');
        end

        % Button pushed function: estimateGCButton
        function estimateGCButtonPushed(app, event)
            if isempty(which('sb_gen_connectivity.m')), disp('add /sb_preprocessor first!');return;end
            
            g_list =  return_group_cluster_fn_SB(app);
            if isempty(g_list), disp('Nothing selected');return;end
            
            app.cfg = cfg_cfg_sb_con(app, 'estimateGC');
            
            GC_calculation_sb(app, g_list);
       
            disp('FINISHED');
            
        end

        % Button pushed function: estimateTEButton
        function estimateTEButtonPushed(app, event)
            %% FIRST LOAD DATA
            %Then CONVERT to FieldTrip Format
            %then Estimate TE
            
            %disp('NOT implemented yet!');return;
            
            if isempty(app.clusternamessbListBox.Value), disp('Select clusters / inspect clusters!');return;end
            
            %INPUT GUI
            if isempty(which('sb_gen_transferentropy.m')), disp('add /meg_gem first!');return;end
            g_list =  return_group_cluster_fn_SB(app);
            if isempty(g_list), disp('Nothing selected');return;end
            
            app.cfg = cfg_cfg_sb_con(app, 'estimateTETRENTOOL');
            
            calc_TE_sb(app, g_list);
            
            disp('FINISHED');
                
             
        end

        % Button pushed function: inspectclusterButton
        function inspectclusterButtonPushed(app, event)
            g_list =  return_group_cluster_fn_SB(app);
            if isempty(g_list), disp('Nothing selected');return;end
            
            load(g_list{1});
            
            for ic = 1:length(Cluster);
                    clustername{ic,1} = Cluster{ic}.name;
            end %ic
            app.clusternamessbListBox.Items = clustername;
            
            data = Cluster{1};
            
            app.dataListBox.Items ={};
            if isfield(data, 'Y_mean')
                app.dataListBox.Items = [app.dataListBox.Items sprintf('Y_mean: %i', length(data.Y_mean))];
            end
            
            if isfield(data, 'Yc1_mean')
                app.dataListBox.Items = [app.dataListBox.Items sprintf('Yc1_mean: %i', length(data.Y_mean))];
            end
            
            if isfield(data, 'Yc2_mean')
                app.dataListBox.Items = [app.dataListBox.Items sprintf('Yc2_mean: %i', length(data.Y_mean))];
            end
                
            disp(Cluster{1});
            
        end

        % Button pushed function: GCviewresultsButton
        function GCviewresultsButtonPushed(app, event)
            app.cfg.mode = 'GC';
            view_sb_conn_results(app)
        end

        % Button pushed function: ASDFTEButton
        function ASDFTEButtonPushed(app, event)
            
            if isempty(which('ASDFTE.m')), disp('add ASDF toolbox in /sb_preprocessor');return;end;
            
            g_list =  return_group_cluster_fn_SB(app);
            if isempty(g_list), disp('Nothing selected');return;end

           %INPUT GUI
            if isempty(which('sb_gen_transferentropy.m')), disp('add /meg_gem first!');return;end
            
            data = return_datafromcluster_sb(app, g_list);
            app.cfg = cfg_cfg_sb_con(app, 'ASDFTE');
            
            %CALC DATA1
            for id = 1:length(data)
                app.cfg.data = data{id};
                app.cfg.label = g_list{id};
                out = calc_ASDF_TE(app);
                fprintf('saving to: %s\n', g_list{id});
                load(g_list{id});
                Cluster{1}.TE_asdf = out;
                save(g_list{id}, 'Cluster');
            end
  
           
            disp('FINISHED');
        end

        % Button pushed function: ASDFTEviewresultsButton
        function ASDFTEviewresultsButtonPushed(app, event)
            app.cfg.mode = 'TE_asdf';
            view_sb_conn_results(app)
        end

        % Button pushed function: Button_select_subdir
        function Button_select_subdirPushed(app, event)
             p = uigetdir(app.g1dirdataimportEditField.Value, 'Select folder containing functional data');
            if isempty(p), return, end;
            
            [a p c] = fileparts(p);
            app.g1dataimportsubdirnameEditField.Value = p; 
        end

        % Button pushed function: TEButton_schreiber
        function TEButton_schreiberPushed(app, event)
            
           if isempty(app.clusternamessbListBox.Value), disp('Select clusters / inspect clusters!');return;end
           
           g_list =  return_group_cluster_fn_SB(app);
            if isempty(g_list), disp('Nothing selected');return;end
            
            app.cfg = cfg_cfg_sb_con(app, 'TEschreiber'); if isempty(app.cfg), return, end;

            data = return_datafromcluster_sb(app, g_list);

            %CALC DATA
            for id = 1:length(data)
                app.cfg.data = data{id};
                app.cfg.label = g_list{id};
                out = calc_schreiber_TE(app);
                fprintf('saving to: %s\n', g_list{id});
                load(g_list{id});
                Cluster{1}.TE_schreiber = out;
                save(g_list{id}, 'Cluster');
            end
           
            disp('FINISHED');
        end

        % Button pushed function: iButton
        function iButtonPushed(app, event)
            web('http://journals.plos.org/plosone/article?id=10.1371/journal.pone.0027431');
           
        end

        % Button pushed function: iButton_2
        function iButton_2Pushed(app, event)
            web('http://trentool.github.io/TRENTOOL3/');
        end

        % Button pushed function: iButton_3
        function iButton_3Pushed(app, event)
                web('https://physionet.org/physiotools/tewp/');            
        end

        % Button pushed function: iButton_4
        function iButton_4Pushed(app, event)
            web('https://users.sussex.ac.uk/~lionelb/MVGC/html/mvgchelp.html');
        end

        % Button pushed function: TEleeetalviewresultsButton
        function TEleeetalviewresultsButtonPushed(app, event)
            app.cfg.mode = 'TE_schreiber';
            view_sb_conn_results(app)
        end

        % Button pushed function: TETRENToolresultsButton
        function TETRENToolresultsButtonPushed(app, event)
            app.cfg.mode = 'TE_prep';
            view_sb_conn_results(app)
        end

        % Button pushed function: 
        % removedeselectclustersfromallsubjectsButton
        function removedeselectclustersfromallsubjectsButtonPushed(app, event)

            %read lists
            g_list = return_group_cluster_fn_SB(app);
            
            if isempty(g_list);disp('nothing selected'); return; end
            
            %view clusters
            load(g_list{1});fprintf('\t|loading: %s\n', g_list{1});
            llist = [];
            for ic = 1:length(Cluster)
                llist = [llist;{Cluster{ic}.name}];
            end
            
            %now deselect cluster
            fprintf('Select Clusters to deselect\n');
            [s, v] = listdlg('PromptString', 'Select clusters to remove from Cluster*.mat', 'SelectionMode', 'multiple', ...
                'ListString', llist, 'InitialValue', []);
            if isempty(s), return; end
            
            %now loop through Clusters and remove selected indices
            fprintf('Starting to remove selected Clusters\n');
            for ig = 1:length(g_list)
                load(g_list{ig});fprintf('\t|loading: %s, ', g_list{ig});
                Cluster(s) = [];
                save(g_list{ig}, 'Cluster');disp('saved back');
            end
            
            disp('finished');
            
        end

        % Button pushed function: sel_diff_clustermatimportButton
        function sel_diff_clustermatimportButtonPushed(app, event)
            [fn pn ] = uigetfile(fullfile(app.resultsdirEditField.Value, 'Cluster*.mat'), 'Select Cluster.mat');
            if pn == 0, return, end
            app.diffClustermatEditField.Value = fullfile(pn, fn);
        end

        % Button pushed function: Button_check_cluster_subfolder
        function Button_check_cluster_subfolderPushed(app, event)
            disp('Checking for data consistency');
            app.datafoundLamp.Color = 'red';
            error_found = 0;
           
            if isempty(app.g1dirdataimportListBox.Value) &isempty(app.g2dirdataimportListBox.Value) , disp('nothing selected'), return ,end;
               
             %first check group1
            g1_list = app.g1dirdataimportListBox.Value;
            g1_dir = app.g1dirdataimportEditField.Value;
            g1_group_dir = app.g1dirEditField.Value;
            error_found = error_found + check_add_surf_cluster(app, g1_list, g1_dir, g1_group_dir, 'check');
            
             %first check group2
            g1_list = app.g2dirdataimportListBox.Value;
            g1_dir = app.g2dirdataimportEditField.Value;
            g1_group_dir = app.g2dirEditField.Value;
            error_found = error_found + check_add_surf_cluster(app, g1_list, g1_dir, g1_group_dir, 'check');
           
            %everythins is ok, if we get until here
            if error_found == 0
                app.datafoundLamp.Color = 'green';
                disp('every thing is perfect ');
            end
            
        end

        % Button pushed function: Button_add_cluster_subfolder
        function Button_add_cluster_subfolderPushed(app, event)
            if min(app.datafoundLamp.Color == [0 1 0]) == 0, disp('Perform a successful check first!'); return;end
            
            if isempty(app.g1dirdataimportListBox.Value) &isempty(app.g2dirdataimportListBox.Value) , disp('nothing selected'), return ,end;
               
             %first check group1
            g1_list = app.g1dirdataimportListBox.Value;
            g1_dir = app.g1dirdataimportEditField.Value;
            g1_group_dir = app.g1dirEditField.Value;
            check_add_surf_cluster(app, g1_list, g1_dir, g1_group_dir, 'add');
            
             %first check group2
            g1_list = app.g2dirdataimportListBox.Value;
            g1_dir = app.g2dirdataimportEditField.Value;
            g1_group_dir = app.g2dirEditField.Value;
            check_add_surf_cluster(app, g1_list, g1_dir, g1_group_dir, 'add');
        end

        % Button pushed function: 
        % C2CvisualizenetworkcorrelationButton_v3
        function C2CvisualizenetworkcorrelationButton_v3Pushed(app, event)
            % new experimental verstion
            fprintf('C2CvisualizenetworkcorrelationButtonPushed v3');
            [B.TG,B.BG1,B.BG2] = get_selected_exel_data(app);
            
            ck_rc3_visualize_network_clusterbehavior3(app.resultsdirEditField.Value,app.C2C_stat,B);
        end

        % Button pushed function: 
        % mergeselected2SubjecttooneSubjectButton
        function mergeselected2SubjecttooneSubjectButtonPushed(app, event)
            % current
            fprintf('working on Subject merging');
            prefix = app.prefixmergeEditField.Value;
            
            [listg1,listg2] = get_all_selected_filenames(app,'full');
            merge_Subject(app,listg1,listg2,prefix);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ClusterDefinitionTab = uitab(app.TabGroup);
            app.ClusterDefinitionTab.Title = 'Cluster Definition';
            app.exemplarydatafileLabel = uilabel(app.ClusterDefinitionTab);
            app.exemplarydatafileLabel.Text = 'exemplary data file';
            app.exemplarydatafileEditField = uieditfield(app.ClusterDefinitionTab, 'text');
            app.exemplarydatafileEditField.Value = 'H:\data_ck\Vesti\data\H_41W_HD_ID0072\filter_rs6\ffswuaf0010318407-0007-00250-000250-01.nii';
            app.exemplarydatadirButton = uibutton(app.ClusterDefinitionTab, 'push');
            app.exemplarydatadirButton.ButtonPushedFcn = createCallbackFcn(app, @exemplarydatadirButtonPushed, true);
            app.exemplarydatadirButton.Text = '...';
            app.DefineClusterButton = uibutton(app.ClusterDefinitionTab, 'push');
            app.DefineClusterButton.ButtonPushedFcn = createCallbackFcn(app, @DefineClusterButtonPushed, true);
            app.DefineClusterButton.Text = 'Define Cluster';
            app.DefineNetworkButton = uibutton(app.ClusterDefinitionTab, 'push');
            app.DefineNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @DefineNetworkButtonPushed, true);
            app.DefineNetworkButton.Text = 'Define Network';
            app.deletenetworkdefinitionsfromClusterstructButton = uibutton(app.ClusterDefinitionTab, 'push');
            app.deletenetworkdefinitionsfromClusterstructButton.ButtonPushedFcn = createCallbackFcn(app, @deletenetworkdefinitionsfromClusterstructButtonPushed, true);
            app.deletenetworkdefinitionsfromClusterstructButton.Text = 'delete network definitions from Cluster struct';
            app.removedeselectclustersfromallsubjectsButton = uibutton(app.ClusterDefinitionTab, 'push');
            app.removedeselectclustersfromallsubjectsButton.ButtonPushedFcn = createCallbackFcn(app, @removedeselectclustersfromallsubjectsButtonPushed, true);
            app.removedeselectclustersfromallsubjectsButton.Text = 'remove / deselect clusters from all subjects';
            app.selectClustermatingroupdirectoryLabel = uilabel(app.ClusterDefinitionTab);
            app.selectClustermatingroupdirectoryLabel.Text = '(select Cluster*.mat in group-directory)';
            app.DataimportTab = uitab(app.TabGroup);
            app.DataimportTab.Title = 'Data import';
            app.g1dirdataimportButton = uibutton(app.DataimportTab, 'push');
            app.g1dirdataimportButton.ButtonPushedFcn = createCallbackFcn(app, @g1dirdataimportButtonPushed, true);
            app.g1dirdataimportButton.Text = '...';
            app.g2dirdataimportButton = uibutton(app.DataimportTab, 'push');
            app.g2dirdataimportButton.ButtonPushedFcn = createCallbackFcn(app, @g2dirdataimportButtonPushed, true);
            app.g2dirdataimportButton.Text = '...';
            app.g1dirdataimportListBox = uilistbox(app.DataimportTab);
            app.g1dirdataimportListBox.Items = {};
            app.g1dirdataimportListBox.Multiselect = 'on';
            app.g1dirdataimportListBox.Value = {};
            app.g2dirdataimportListBox = uilistbox(app.DataimportTab);
            app.g2dirdataimportListBox.Items = {};
            app.g2dirdataimportListBox.Multiselect = 'on';
            app.g2dirdataimportListBox.Value = {};
            app.rootdirgroup1EditField_2Label = uilabel(app.DataimportTab);
            app.rootdirgroup1EditField_2Label.Text = 'root dir group 1';
            app.g1dirdataimportEditField = uieditfield(app.DataimportTab, 'text');
            app.g1dirdataimportEditField.Value = 'H:\data_ck\Vesti\data';
            app.rootdirgroup2EditField_2Label = uilabel(app.DataimportTab);
            app.rootdirgroup2EditField_2Label.Text = 'root dir group 2';
            app.g2dirdataimportEditField = uieditfield(app.DataimportTab, 'text');
            app.g2dirdataimportEditField.Value = 'H:\data_ck\Vesti\data';
            app.subdirnameEditField_2Label = uilabel(app.DataimportTab);
            app.subdirnameEditField_2Label.Text = 'subdir name';
            app.g1dataimportsubdirnameEditField = uieditfield(app.DataimportTab, 'text');
            app.g1dataimportsubdirnameEditField.Value = 'filter_rs6';
            app.OptionsforvoxelextractionLabel = uilabel(app.DataimportTab);
            app.OptionsforvoxelextractionLabel.Text = 'Options for voxel extraction';
            app.Panel = uipanel(app.DataimportTab);
            app.Panel.Title = 'unconditional cluster extraction (all voxel given by the Clusterdefinition)';
            app.extractallvoxelinClusterCheckBox = uicheckbox(app.Panel);
            app.extractallvoxelinClusterCheckBox.Text = 'extract all voxel in Cluster';
            app.extractallvoxelinClusterCheckBox.Value = true;
            app.savedasClusteriYLabel = uilabel(app.Panel);
            app.savedasClusteriYLabel.Text = 'saved as Cluster{i}.Y';
            app.Panel_2 = uipanel(app.DataimportTab);
            app.Panel_2.Title = 'conditional cluster extraction (only voxel were extractat which meet the following individ. criteria)';
            app.extractconditionalvoxelCheckBox = uicheckbox(app.Panel_2);
            app.extractconditionalvoxelCheckBox.Text = 'extract conditional voxel';
            app.extractconditionalvoxelCheckBox.Value = true;
            app.savedasClussteriYc1Label = uilabel(app.Panel_2);
            app.savedasClussteriYc1Label.Text = 'saved as Clusster{i}.Yc1';
            app.graymatterprobgreaterasEditFieldLabel = uilabel(app.Panel_2);
            app.graymatterprobgreaterasEditFieldLabel.Text = 'gray matter prob. greater as';
            app.graymatterprobgreaterasEditField = uieditfield(app.Panel_2, 'numeric');
            app.graymatterprobgreaterasEditField.Value = 0.1;
            app.whitematterproblessthenEditFieldLabel = uilabel(app.Panel_2);
            app.whitematterproblessthenEditFieldLabel.Text = 'white matter prob. less then';
            app.whitematterproblessthenEditField = uieditfield(app.Panel_2, 'numeric');
            app.whitematterproblessthenEditField.Value = 0.9;
            app.CSFproblessthenEditFieldLabel = uilabel(app.Panel_2);
            app.CSFproblessthenEditFieldLabel.Text = 'CSF prob. less then';
            app.CSFproblessthenEditField = uieditfield(app.Panel_2, 'numeric');
            app.CSFproblessthenEditField.Value = 0.9;
            app.graymatterfilenameEditFieldLabel = uilabel(app.Panel_2);
            app.graymatterfilenameEditFieldLabel.Text = 'gray matter file name';
            app.graymatterfilenameEditField = uieditfield(app.Panel_2, 'text');
            app.graymatterfilenameEditField.Value = 'rc1_file.nii';
            app.whitematterfilenameEditFieldLabel = uilabel(app.Panel_2);
            app.whitematterfilenameEditFieldLabel.Text = 'white matter file name';
            app.whitematterfilenameEditField = uieditfield(app.Panel_2, 'text');
            app.whitematterfilenameEditField.Value = 'rc2_file.nii';
            app.csffilenameEditFieldLabel = uilabel(app.Panel_2);
            app.csffilenameEditFieldLabel.Text = 'csf file name';
            app.csffilenameEditField = uieditfield(app.Panel_2, 'text');
            app.csffilenameEditField.Value = 'rc3_file.nii';
            app.Lamp = uilamp(app.Panel_2);
            app.testforfilepresenceButton = uibutton(app.Panel_2, 'push');
            app.testforfilepresenceButton.ButtonPushedFcn = createCallbackFcn(app, @testforfilepresenceButtonPushed, true);
            app.testforfilepresenceButton.Text = 'test for file presence';
            app.extract2conditionalvoxelCheckBox = uicheckbox(app.Panel_2);
            app.extract2conditionalvoxelCheckBox.Text = 'extract 2. conditional voxel';
            app.extract2conditionalvoxelCheckBox.Value = true;
            app.graymatterprobgreaterasEditField_2Label = uilabel(app.Panel_2);
            app.graymatterprobgreaterasEditField_2Label.Text = 'gray matter prob. greater as';
            app.graymatterprobgreateras2EditField = uieditfield(app.Panel_2, 'numeric');
            app.graymatterprobgreateras2EditField.Value = 0.4;
            app.whitematterproblessthenEditField_2Label = uilabel(app.Panel_2);
            app.whitematterproblessthenEditField_2Label.Text = 'white matter prob. less then';
            app.whitematterproblessthen2EditField = uieditfield(app.Panel_2, 'numeric');
            app.whitematterproblessthen2EditField.Value = 0.2;
            app.CSFproblessthenEditField_2Label = uilabel(app.Panel_2);
            app.CSFproblessthenEditField_2Label.Text = 'CSF prob. less then';
            app.CSFproblessthen2EditField = uieditfield(app.Panel_2, 'numeric');
            app.CSFproblessthen2EditField.Value = 0.2;
            app.savedasClussteriYc2Label = uilabel(app.Panel_2);
            app.savedasClussteriYc2Label.Text = 'saved as Clusster{i}.Yc2';
            app.updatedataimportButton = uibutton(app.DataimportTab, 'push');
            app.updatedataimportButton.ButtonPushedFcn = createCallbackFcn(app, @updatedataimportButtonPushed, true);
            app.updatedataimportButton.Text = 'update';
            app.filefilterLabel = uilabel(app.DataimportTab);
            app.filefilterLabel.Text = 'filefilter';
            app.dataimportfilefilterEditField = uieditfield(app.DataimportTab, 'text');
            app.dataimportfilefilterEditField.Value = 'ff*.nii';
            app.DatapropertiesPanel = uipanel(app.DataimportTab);
            app.DatapropertiesPanel.Title = 'Data properties';
            app.TRinsecEditFieldLabel = uilabel(app.DatapropertiesPanel);
            app.TRinsecEditFieldLabel.Text = 'TR in sec';
            app.TREditField = uieditfield(app.DatapropertiesPanel, 'numeric');
            app.TREditField.Value = 1.9;
            app.lpfEditFieldLabel = uilabel(app.DatapropertiesPanel);
            app.lpfEditFieldLabel.Text = 'lpf';
            app.lpfEditField = uieditfield(app.DatapropertiesPanel, 'numeric');
            app.lpfEditField.Value = 0.1;
            app.hpfEditFieldLabel = uilabel(app.DatapropertiesPanel);
            app.hpfEditFieldLabel.Text = 'hpf';
            app.hpfEditField = uieditfield(app.DatapropertiesPanel, 'numeric');
            app.hpfEditField.Value = 0.01;
            app.addsuffixtofoldernameButton = uibutton(app.DataimportTab, 'push');
            app.addsuffixtofoldernameButton.ButtonPushedFcn = createCallbackFcn(app, @addsuffixtofoldernameButtonPushed, true);
            app.addsuffixtofoldernameButton.Text = 'add suffix to foldername';
            app.Button_select_subdir = uibutton(app.DataimportTab, 'push');
            app.Button_select_subdir.ButtonPushedFcn = createCallbackFcn(app, @Button_select_subdirPushed, true);
            app.Button_select_subdir.Text = '...';
            app.extractclustersPanel = uipanel(app.DataimportTab);
            app.extractclustersPanel.Title = 'extract clusters';
            app.GOButton = uibutton(app.extractclustersPanel, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO!';
            app.appendextractedclusterstoexistingClustermatPanel = uipanel(app.extractclustersPanel);
            app.appendextractedclusterstoexistingClustermatPanel.Title = 'append extracted clusters to existing Cluster*.mat';
            app.usedifferentClustermatCheckBox = uicheckbox(app.appendextractedclusterstoexistingClustermatPanel);
            app.usedifferentClustermatCheckBox.Text = 'use different Cluster.mat ';
            app.sel_diff_clustermatimportButton = uibutton(app.appendextractedclusterstoexistingClustermatPanel, 'push');
            app.sel_diff_clustermatimportButton.ButtonPushedFcn = createCallbackFcn(app, @sel_diff_clustermatimportButtonPushed, true);
            app.sel_diff_clustermatimportButton.Text = '...';
            app.ClustermatEditFieldLabel = uilabel(app.appendextractedclusterstoexistingClustermatPanel);
            app.ClustermatEditFieldLabel.Text = 'Cluster*.mat:';
            app.diffClustermatEditField = uieditfield(app.appendextractedclusterstoexistingClustermatPanel, 'text');
            app.CheckBox_append_cluster = uicheckbox(app.extractclustersPanel);
            app.CheckBox_append_cluster.Text = '';
            app.Panel_3 = uipanel(app.DataimportTab);
            app.Panel_3.Title = 'add clusters from subjects'' subfolder, i.e. for adding surface based clusters';
            app.Button_check_cluster_subfolder = uibutton(app.Panel_3, 'push');
            app.Button_check_cluster_subfolder.ButtonPushedFcn = createCallbackFcn(app, @Button_check_cluster_subfolderPushed, true);
            app.Button_check_cluster_subfolder.Text = 'check';
            app.datafoundLampLabel = uilabel(app.Panel_3);
            app.datafoundLampLabel.Text = 'data found:';
            app.datafoundLamp = uilamp(app.Panel_3);
            app.Button_add_cluster_subfolder = uibutton(app.Panel_3, 'push');
            app.Button_add_cluster_subfolder.ButtonPushedFcn = createCallbackFcn(app, @Button_add_cluster_subfolderPushed, true);
            app.Button_add_cluster_subfolder.Text = 'add clusters found in subfolder';
            app.subfolderEditFieldLabel = uilabel(app.Panel_3);
            app.subfolderEditFieldLabel.Text = 'subfolder:';
            app.subfolder4surfEditField = uieditfield(app.Panel_3, 'text');
            app.subfolder4surfEditField.Value = 'clusters_surf';
            app.ClustermatfilefilterLabel = uilabel(app.Panel_3);
            app.ClustermatfilefilterLabel.Text = {'Cluster*.mat'; 'file filter:'};
            app.ffilter4surfEditField = uieditfield(app.Panel_3, 'text');
            app.ffilter4surfEditField.Value = 'Cluster*.mat';
            app.subdirnameEditField_2Label_2 = uilabel(app.DataimportTab);
            app.subdirnameEditField_2Label_2.Text = 'subdir name';
            app.g2dataimportsubdirnameEditField = uieditfield(app.DataimportTab, 'text');
            app.g2dataimportsubdirnameEditField.Value = 'filter_rs6';
            app.connectivityestimationTab = uitab(app.TabGroup);
            app.connectivityestimationTab.Title = 'connectivity estimation';
            app.estimateconnectivityButton = uibutton(app.connectivityestimationTab, 'push');
            app.estimateconnectivityButton.ButtonPushedFcn = createCallbackFcn(app, @estimateconnectivityButtonPushed, true);
            app.estimateconnectivityButton.Text = 'estimate connectivity';
            app.estimationoptionsPanel = uipanel(app.connectivityestimationTab);
            app.estimationoptionsPanel.Title = 'estimation options';
            app.Cluster2VoxelCheckBox = uicheckbox(app.estimationoptionsPanel);
            app.Cluster2VoxelCheckBox.Text = 'Cluster2Voxel';
            app.Cluster2ClusterCheckBox = uicheckbox(app.estimationoptionsPanel);
            app.Cluster2ClusterCheckBox.Text = 'Cluster2Cluster';
            app.Cluster2ClusterCheckBox.Value = true;
            app.Voxel2VoxelCheckBox = uicheckbox(app.estimationoptionsPanel);
            app.Voxel2VoxelCheckBox.Text = 'Voxel2Voxel';
            app.C1maskthresholdCheckBox = uicheckbox(app.estimationoptionsPanel);
            app.C1maskthresholdCheckBox.Text = 'C1 mask threshold';
            app.thresholdEditFieldLabel = uilabel(app.estimationoptionsPanel);
            app.thresholdEditFieldLabel.Text = 'threshold';
            app.thresholdEditField = uieditfield(app.estimationoptionsPanel, 'numeric');
            app.thresholdEditField.Value = 0.1;
            app.smoothingCheckBox = uicheckbox(app.estimationoptionsPanel);
            app.smoothingCheckBox.Text = 'smoothing';
            app.kernelEditFieldLabel = uilabel(app.estimationoptionsPanel);
            app.kernelEditFieldLabel.Text = 'kernel';
            app.kernelEditField = uieditfield(app.estimationoptionsPanel, 'text');
            app.kernelEditField.Value = '[6 6 6]';
            app.MethodsPanel = uipanel(app.connectivityestimationTab);
            app.MethodsPanel.Title = 'Methods';
            app.synchronizationPanel = uipanel(app.MethodsPanel);
            app.synchronizationPanel.Title = 'synchronization';
            app.readmesynchronizationButton = uibutton(app.synchronizationPanel, 'push');
            app.readmesynchronizationButton.Text = 'readme';
            app.histogramEditField_2Label = uilabel(app.synchronizationPanel);
            app.histogramEditField_2Label.Text = 'histogram';
            app.histogramsynchronizationEditField = uieditfield(app.synchronizationPanel, 'numeric');
            app.histogramsynchronizationEditField.Value = 4;
            app.complexityPanel = uipanel(app.MethodsPanel);
            app.complexityPanel.Title = 'complexity';
            app.windowlengthEditFieldLabel = uilabel(app.complexityPanel);
            app.windowlengthEditFieldLabel.Text = 'window length';
            app.windowlengthcomplexityEditField = uieditfield(app.complexityPanel, 'numeric');
            app.windowlengthcomplexityEditField.Value = 5;
            app.similaritymeasureEditFieldLabel = uilabel(app.complexityPanel);
            app.similaritymeasureEditFieldLabel.Text = 'similarity measure';
            app.similaritymeasurecomplexityEditField = uieditfield(app.complexityPanel, 'numeric');
            app.similaritymeasurecomplexityEditField.Value = 0.15;
            app.readmecomplexityButton = uibutton(app.complexityPanel, 'push');
            app.readmecomplexityButton.Text = 'readme';
            app.transinformationPanel = uipanel(app.MethodsPanel);
            app.transinformationPanel.Title = 'transinformation';
            app.readmetransinformationButton = uibutton(app.transinformationPanel, 'push');
            app.readmetransinformationButton.Text = 'readme';
            app.histogramEditFieldLabel = uilabel(app.transinformationPanel);
            app.histogramEditFieldLabel.Text = 'histogram';
            app.histogramEditField = uieditfield(app.transinformationPanel, 'numeric');
            app.histogramEditField.Value = 4;
            app.timelagEditFieldLabel = uilabel(app.transinformationPanel);
            app.timelagEditFieldLabel.Text = 'time lag';
            app.timelagEditField = uieditfield(app.transinformationPanel, 'numeric');
            app.timelagEditField.Value = 5;
            app.complexitySwitch = uiswitch(app.MethodsPanel, 'toggle');
            app.complexitySwitch.Items = {'0', '1'};
            app.complexitySwitch.ValueChangedFcn = createCallbackFcn(app, @complexitySwitchValueChanged, true);
            app.complexitySwitch.Value = '0';
            app.correlationsPanel = uipanel(app.MethodsPanel);
            app.correlationsPanel.Title = 'correlations';
            app.coherencePanel = uipanel(app.MethodsPanel);
            app.coherencePanel.Title = 'coherence';
            app.readmecoherenceButton = uibutton(app.coherencePanel, 'push');
            app.readmecoherenceButton.Text = 'readme';
            app.partialcoherencePanel = uipanel(app.MethodsPanel);
            app.partialcoherencePanel.Title = 'partial coherence';
            app.readmepartialcoherenceButton = uibutton(app.partialcoherencePanel, 'push');
            app.readmepartialcoherenceButton.Text = 'readme';
            app.synchronizationSwitch = uiswitch(app.MethodsPanel, 'toggle');
            app.synchronizationSwitch.Items = {'0', '1'};
            app.synchronizationSwitch.ValueChangedFcn = createCallbackFcn(app, @synchronizationSwitchValueChanged, true);
            app.synchronizationSwitch.Value = '0';
            app.transinformationSwitch = uiswitch(app.MethodsPanel, 'toggle');
            app.transinformationSwitch.Items = {'0', '1'};
            app.transinformationSwitch.ValueChangedFcn = createCallbackFcn(app, @transinformationSwitchValueChanged, true);
            app.transinformationSwitch.Value = '0';
            app.partialcoherenceSwitch = uiswitch(app.MethodsPanel, 'toggle');
            app.partialcoherenceSwitch.Items = {'0', '1'};
            app.partialcoherenceSwitch.ValueChangedFcn = createCallbackFcn(app, @partialcoherenceSwitchValueChanged, true);
            app.partialcoherenceSwitch.Enable = 'off';
            app.partialcoherenceSwitch.Value = '0';
            app.coherenceSwitch = uiswitch(app.MethodsPanel, 'toggle');
            app.coherenceSwitch.Items = {'0', '1'};
            app.coherenceSwitch.ValueChangedFcn = createCallbackFcn(app, @coherenceSwitchValueChanged, true);
            app.coherenceSwitch.Value = '0';
            app.correlationSwitch = uiswitch(app.MethodsPanel, 'toggle');
            app.correlationSwitch.Items = {'0', '1'};
            app.correlationSwitch.ValueChangedFcn = createCallbackFcn(app, @correlationSwitchValueChanged, true);
            app.correlationSwitch.Value = '0';
            app.grangerSwitch = uiswitch(app.connectivityestimationTab, 'toggle');
            app.grangerSwitch.Items = {'0', '1'};
            app.grangerSwitch.ValueChangedFcn = createCallbackFcn(app, @grangerSwitchValueChanged, true);
            app.grangerSwitch.Enable = 'off';
            app.grangerSwitch.Value = '0';
            app.GrangercausalitybigPanel = uipanel(app.connectivityestimationTab);
            app.GrangercausalitybigPanel.Title = 'Granger causality';
            app.GrangercausalityPanel = uipanel(app.GrangercausalitybigPanel);
            app.GrangercausalityPanel.Title = 'Granger causality';
            app.conditionalGCinthetimedomainCheckBox = uicheckbox(app.GrangercausalityPanel);
            app.conditionalGCinthetimedomainCheckBox.Text = 'conditional GC in the time domain';
            app.additionalpreprocessingPanel = uipanel(app.GrangercausalityPanel);
            app.additionalpreprocessingPanel.Title = 'additional preprocessing';
            app.grangerdetrenddataCheckBox = uicheckbox(app.additionalpreprocessingPanel);
            app.grangerdetrenddataCheckBox.Text = 'detrend data';
            app.grangerremovetemporalmeanCheckBox = uicheckbox(app.additionalpreprocessingPanel);
            app.grangerremovetemporalmeanCheckBox.Text = 'remove temporal mean';
            app.grangerremovetemporalmeananddividebystdCheckBox = uicheckbox(app.additionalpreprocessingPanel);
            app.grangerremovetemporalmeananddividebystdCheckBox.Text = 'remove temporal mean and divide by std';
            app.grangerdifferencingtimeseriesCheckBox = uicheckbox(app.additionalpreprocessingPanel);
            app.grangerdifferencingtimeseriesCheckBox.Text = 'differencing time series';
            app.iterationEditFieldLabel = uilabel(app.additionalpreprocessingPanel);
            app.iterationEditFieldLabel.Text = 'iteration';
            app.grangeriterationEditField = uieditfield(app.additionalpreprocessingPanel, 'numeric');
            app.grangeriterationEditField.Value = 1;
            app.readmegrangerpreprocessingButton = uibutton(app.additionalpreprocessingPanel, 'push');
            app.readmegrangerpreprocessingButton.Text = 'readme';
            app.estimategrangerautonomyCheckBox = uicheckbox(app.GrangercausalityPanel);
            app.estimategrangerautonomyCheckBox.Text = 'estimate granger autonomy';
            app.conditionalGCinfrequencydomainCheckBox = uicheckbox(app.GrangercausalityPanel);
            app.conditionalGCinfrequencydomainCheckBox.Text = 'conditional GC in frequency domain';
            app.readmegrangerpreprocessingButton_2 = uibutton(app.GrangercausalityPanel, 'push');
            app.readmegrangerpreprocessingButton_2.Text = 'readme';
            app.CGCtimeparameterPanel = uipanel(app.GrangercausalityPanel);
            app.CGCtimeparameterPanel.Title = 'CGC time parameter';
            app.numberoflagsEditFieldLabel = uilabel(app.CGCtimeparameterPanel);
            app.numberoflagsEditFieldLabel.Text = 'number of lags';
            app.grangernumberoflagsEditField = uieditfield(app.CGCtimeparameterPanel, 'numeric');
            app.grangernumberoflagsEditField.Value = 1;
            app.pEditFieldLabel = uilabel(app.CGCtimeparameterPanel);
            app.pEditFieldLabel.Text = 'p =';
            app.grangerpEditField = uieditfield(app.CGCtimeparameterPanel, 'numeric');
            app.grangerpEditField.Value = 0.05;
            app.frequencyparameterPanel = uipanel(app.GrangercausalityPanel);
            app.frequencyparameterPanel.Title = 'frequency parameter';
            app.frequencyiterationstepEditFieldLabel = uilabel(app.frequencyparameterPanel);
            app.frequencyiterationstepEditFieldLabel.Text = 'frequency iteration step';
            app.grangerfrequencyiterationstepEditField = uieditfield(app.frequencyparameterPanel, 'numeric');
            app.grangerfrequencyiterationstepEditField.Value = 0.01;
            app.frequencyEditFieldLabel = uilabel(app.frequencyparameterPanel);
            app.frequencyEditFieldLabel.Text = 'frequency';
            app.grangerfrequencyEditField = uieditfield(app.frequencyparameterPanel, 'text');
            app.grangerfrequencyEditField.Value = '[0.01 0.1]';
            app.selectedClusterListBoxLabel = uilabel(app.GrangercausalitybigPanel);
            app.selectedClusterListBoxLabel.Text = 'selected Cluster';
            app.grangerselectedClusterListBox = uilistbox(app.GrangercausalitybigPanel);
            app.grangerselectedClusterListBox.Items = {};
            app.grangerselectedClusterListBox.Value = {};
            app.usedataPanel = uipanel(app.connectivityestimationTab);
            app.usedataPanel.Title = 'use data';
            app.conuseYCheckBox = uicheckbox(app.usedataPanel);
            app.conuseYCheckBox.Text = 'Y';
            app.conuseYCheckBox.Value = true;
            app.conuseYc1CheckBox = uicheckbox(app.usedataPanel);
            app.conuseYc1CheckBox.Text = 'Yc1';
            app.conuseYc1CheckBox.Value = true;
            app.conuseYc2CheckBox = uicheckbox(app.usedataPanel);
            app.conuseYc2CheckBox.Text = 'Yc2';
            app.conuseYc2CheckBox.Value = true;
            app.sb_connTab = uitab(app.TabGroup);
            app.sb_connTab.Title = 'sb_conn';
            app.GrangerPanel = uipanel(app.sb_connTab);
            app.GrangerPanel.Title = 'Granger';
            app.estimateGCButton = uibutton(app.GrangerPanel, 'push');
            app.estimateGCButton.ButtonPushedFcn = createCallbackFcn(app, @estimateGCButtonPushed, true);
            app.estimateGCButton.Text = 'estimate GC';
            app.performGCanalysisusingsb_gen_connectivityfunctionLabel = uilabel(app.GrangerPanel);
            app.performGCanalysisusingsb_gen_connectivityfunctionLabel.Enable = 'off';
            app.performGCanalysisusingsb_gen_connectivityfunctionLabel.Text = {'perform GC analysis using sb_gen_connectivity function.'; 'Results stored in: Cluster.GC'};
            app.GCmodelorderEditFieldLabel = uilabel(app.GrangerPanel);
            app.GCmodelorderEditFieldLabel.Text = 'GC model order';
            app.GCmodelorderEditField = uieditfield(app.GrangerPanel, 'numeric');
            app.GCmodelorderEditField.Limits = [1 20];
            app.GCmodelorderEditField.Value = 5;
            app.splitYeveryscanEditFieldLabel = uilabel(app.GrangerPanel);
            app.splitYeveryscanEditFieldLabel.Text = 'split Y every ... scan';
            app.splitYeveryscanEditField = uieditfield(app.GrangerPanel, 'numeric');
            app.splitYeveryscanEditField.Limits = [0 Inf];
            app.splitYeveryscanEditField.Value = 30;
            app.CheckBox = uicheckbox(app.GrangerPanel);
            app.CheckBox.Enable = 'off';
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.iButton_4 = uibutton(app.GrangerPanel, 'push');
            app.iButton_4.ButtonPushedFcn = createCallbackFcn(app, @iButton_4Pushed, true);
            app.iButton_4.Text = 'i';
            app.TRENTOOLTransferEntropyPanel = uipanel(app.sb_connTab);
            app.TRENTOOLTransferEntropyPanel.Title = 'TRENTOOL: Transfer Entropy';
            app.estimateTEButton = uibutton(app.TRENTOOLTransferEntropyPanel, 'push');
            app.estimateTEButton.ButtonPushedFcn = createCallbackFcn(app, @estimateTEButtonPushed, true);
            app.estimateTEButton.Text = 'estimate TE';
            app.performGCanalysisusingsb_gen_connectivityfunctionLabel_2 = uilabel(app.TRENTOOLTransferEntropyPanel);
            app.performGCanalysisusingsb_gen_connectivityfunctionLabel_2.Enable = 'off';
            app.performGCanalysisusingsb_gen_connectivityfunctionLabel_2.Text = {'perform TE analysis using sb_gen_transferentropy function'; 'and TRENToolbox (Wibal et al)'; 'Results stored in: Cluster.TE'};
            app.uvecscansEditFieldLabel = uilabel(app.TRENTOOLTransferEntropyPanel);
            app.uvecscansEditFieldLabel.Text = {'u-vec:'; '(scans)'};
            app.uvecscansEditField = uieditfield(app.TRENTOOLTransferEntropyPanel, 'text');
            app.uvecscansEditField.Value = '1 3';
            app.TRLabel = uilabel(app.TRENTOOLTransferEntropyPanel);
            app.TRLabel.Text = 'TR:';
            app.EditField_TR_TEsb = uieditfield(app.TRENTOOLTransferEntropyPanel, 'numeric');
            app.EditField_TR_TEsb.Limits = [0 5];
            app.EditField_TR_TEsb.Value = 2.54;
            app.ustepsLabel = uilabel(app.TRENTOOLTransferEntropyPanel);
            app.ustepsLabel.Text = 'u-steps:';
            app.EditField_ustep_TEsb = uieditfield(app.TRENTOOLTransferEntropyPanel, 'numeric');
            app.EditField_ustep_TEsb.Limits = [1 20];
            app.EditField_ustep_TEsb.Value = 2;
            app.CheckBox_2 = uicheckbox(app.TRENTOOLTransferEntropyPanel);
            app.CheckBox_2.Enable = 'off';
            app.CheckBox_2.Text = '';
            app.CheckBox_2.Value = true;
            app.splitYeveryscanEditField_2Label = uilabel(app.TRENTOOLTransferEntropyPanel);
            app.splitYeveryscanEditField_2Label.Text = 'split Y every ... scan';
            app.splitYeveryscanEditField_TE = uieditfield(app.TRENTOOLTransferEntropyPanel, 'numeric');
            app.splitYeveryscanEditField_TE.Limits = [0 Inf];
            app.splitYeveryscanEditField_TE.Value = 180;
            app.iButton_2 = uibutton(app.TRENTOOLTransferEntropyPanel, 'push');
            app.iButton_2.ButtonPushedFcn = createCallbackFcn(app, @iButton_2Pushed, true);
            app.iButton_2.Text = 'i';
            app.inspectclusterPanel = uipanel(app.sb_connTab);
            app.inspectclusterPanel.Title = 'inspect cluster';
            app.inspectclusterButton = uibutton(app.inspectclusterPanel, 'push');
            app.inspectclusterButton.ButtonPushedFcn = createCallbackFcn(app, @inspectclusterButtonPushed, true);
            app.inspectclusterButton.Text = 'inspect cluster';
            app.clusternamessbListBoxLabel = uilabel(app.inspectclusterPanel);
            app.clusternamessbListBoxLabel.Text = 'clusternamessb';
            app.clusternamessbListBox = uilistbox(app.inspectclusterPanel);
            app.clusternamessbListBox.Items = {};
            app.clusternamessbListBox.Multiselect = 'on';
            app.clusternamessbListBox.Value = {};
            app.dataListBoxLabel = uilabel(app.inspectclusterPanel);
            app.dataListBoxLabel.Text = 'data';
            app.dataListBox = uilistbox(app.inspectclusterPanel);
            app.dataListBox.Items = {};
            app.dataListBox.Value = {};
            app.ASDFTEPanel = uipanel(app.sb_connTab);
            app.ASDFTEPanel.Title = 'ASDF TE';
            app.ASDFTEButton = uibutton(app.ASDFTEPanel, 'push');
            app.ASDFTEButton.ButtonPushedFcn = createCallbackFcn(app, @ASDFTEButtonPushed, true);
            app.ASDFTEButton.Text = 'ASDF TE';
            app.TRLabel_2 = uilabel(app.ASDFTEPanel);
            app.TRLabel_2.Text = 'TR:';
            app.EditField_TR_TEsb_2 = uieditfield(app.ASDFTEPanel, 'numeric');
            app.EditField_TR_TEsb_2.Limits = [0 5];
            app.EditField_TR_TEsb_2.Value = 2.54;
            app.uvecscansEditField_2Label = uilabel(app.ASDFTEPanel);
            app.uvecscansEditField_2Label.Text = 'u-vec(scans):';
            app.uvecscansEditField_2 = uieditfield(app.ASDFTEPanel, 'text');
            app.uvecscansEditField_2.Value = '1 10';
            app.peakcutoffLabel = uilabel(app.ASDFTEPanel);
            app.peakcutoffLabel.Text = 'peak cut off';
            app.EditField_peakcutoff_TEsb_3 = uieditfield(app.ASDFTEPanel, 'numeric');
            app.EditField_peakcutoff_TEsb_3.Limits = [0.01 0.5];
            app.EditField_peakcutoff_TEsb_3.Value = 0.4;
            app.viewindidvidualresultsCheckBoxsb = uicheckbox(app.ASDFTEPanel);
            app.viewindidvidualresultsCheckBoxsb.Text = 'view indidvidual results';
            app.iButton = uibutton(app.ASDFTEPanel, 'push');
            app.iButton.ButtonPushedFcn = createCallbackFcn(app, @iButtonPushed, true);
            app.iButton.Text = 'i';
            app.TransferentropyLeeetal2011Panel = uipanel(app.sb_connTab);
            app.TransferentropyLeeetal2011Panel.Title = 'Transfer entropy (Lee et al 2011)';
            app.TEButton_schreiber = uibutton(app.TransferentropyLeeetal2011Panel, 'push');
            app.TEButton_schreiber.ButtonPushedFcn = createCallbackFcn(app, @TEButton_schreiberPushed, true);
            app.TEButton_schreiber.Text = 'calc TE';
            app.parameteru_minu_maxlwEditFieldLabel = uilabel(app.TransferentropyLeeetal2011Panel);
            app.parameteru_minu_maxlwEditFieldLabel.Text = {'parameter:'; '(u_min, u_max, '; 'l, w) '};
            app.parameteru_minu_maxlwEditField = uieditfield(app.TransferentropyLeeetal2011Panel, 'text');
            app.parameteru_minu_maxlwEditField.Value = '3';
            app.viewindidvidualresultsCheckBoxsb_2 = uicheckbox(app.TransferentropyLeeetal2011Panel);
            app.viewindidvidualresultsCheckBoxsb_2.Text = 'view indidvidual results';
            app.ProbabilitydensityestimationDropDownLabel = uilabel(app.TransferentropyLeeetal2011Panel);
            app.ProbabilitydensityestimationDropDownLabel.Text = 'Probability density estimation';
            app.ProbabilitydensityestimationDropDown = uidropdown(app.TransferentropyLeeetal2011Panel);
            app.ProbabilitydensityestimationDropDown.Items = {'Darbellay-Vajda partitioning algorithm', 'Guassian kernel density estimation', 'bin counting with fixed and equally-spaced bins'};
            app.ProbabilitydensityestimationDropDown.Value = 'Darbellay-Vajda partitioning algorithm';
            app.iButton_3 = uibutton(app.TransferentropyLeeetal2011Panel, 'push');
            app.iButton_3.ButtonPushedFcn = createCallbackFcn(app, @iButton_3Pushed, true);
            app.iButton_3.Text = 'i';
            app.Label_3 = uilabel(app.sb_connTab);
            app.Label_3.Text = {'http://journals.plos.org/plosone/'; 'article?id=10.1371/journal.pone.'; '0027431'};
            app.datareductionTab = uitab(app.TabGroup);
            app.datareductionTab.Title = 'data reduction';
            app.deleteifpossiblePanel = uipanel(app.datareductionTab);
            app.deleteifpossiblePanel.Title = 'delete if possible';
            app.YCheckBox = uicheckbox(app.deleteifpossiblePanel);
            app.YCheckBox.Text = 'Y';
            app.Yc1CheckBox = uicheckbox(app.deleteifpossiblePanel);
            app.Yc1CheckBox.Text = 'Yc1';
            app.Yc2CheckBox = uicheckbox(app.deleteifpossiblePanel);
            app.Yc2CheckBox.Text = 'Yc2';
            app.newprefixforClustermatfilesEditFieldLabel = uilabel(app.deleteifpossiblePanel);
            app.newprefixforClustermatfilesEditFieldLabel.Text = 'new prefix for Cluster.mat files';
            app.newprefixforClustermatfilesEditField = uieditfield(app.deleteifpossiblePanel, 'text');
            app.newprefixforClustermatfilesEditField.Value = 'red_';
            app.reduceClusterdataButton = uibutton(app.datareductionTab, 'push');
            app.reduceClusterdataButton.ButtonPushedFcn = createCallbackFcn(app, @reduceClusterdataButtonPushed, true);
            app.reduceClusterdataButton.Text = 'reduce Cluster data';
            app.ExplanationTextAreaLabel = uilabel(app.datareductionTab);
            app.ExplanationTextAreaLabel.Text = 'Explanation';
            app.ExplanationTextArea = uitextarea(app.datareductionTab);
            app.ExplanationTextArea.Value = {'Much of the estimation time is consumed by loading the Cluster.mat files. The major amount of memory size is consumed by the original data from the included voxel. However, only the mean values for every Cluster will be used further. Therefore, if Clustersize is large it might be reasonable to delete the original data and save only the Y_mean values. This is performed by the current function - reduce Cluster structure.'; ''};
            app.mergeselected2SubjecttooneSubjectButton = uibutton(app.datareductionTab, 'push');
            app.mergeselected2SubjecttooneSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @mergeselected2SubjecttooneSubjectButtonPushed, true);
            app.mergeselected2SubjecttooneSubjectButton.Text = 'merge selected 2 Subject to one Subject';
            app.prefixEditFieldLabel = uilabel(app.datareductionTab);
            app.prefixEditFieldLabel.Text = 'prefix';
            app.prefixmergeEditField = uieditfield(app.datareductionTab, 'text');
            app.prefixmergeEditField.Value = 'FmS';
            app.visualizationTab = uitab(app.TabGroup);
            app.visualizationTab.Title = 'visualization';
            app.TabGroup2 = uitabgroup(app.visualizationTab);
            app.Cluster2Cluster = uitab(app.TabGroup2);
            app.Cluster2Cluster.Title = 'Cluster2Cluster';
            app.loadeddataPanel = uipanel(app.Cluster2Cluster);
            app.loadeddataPanel.Title = 'loaded data';
            app.C2CloaddataButton = uibutton(app.loadeddataPanel, 'push');
            app.C2CloaddataButton.ButtonPushedFcn = createCallbackFcn(app, @C2CloaddataButtonPushed, true);
            app.C2CloaddataButton.Text = 'load data';
            app.C2CloaddataLamp = uilamp(app.loadeddataPanel);
            app.group1ListBoxLabel = uilabel(app.loadeddataPanel);
            app.group1ListBoxLabel.Text = 'group 1';
            app.C2Cgroup1ListBox = uilistbox(app.loadeddataPanel);
            app.C2Cgroup1ListBox.Items = {};
            app.C2Cgroup1ListBox.Multiselect = 'on';
            app.C2Cgroup1ListBox.Value = {};
            app.group2ListBoxLabel = uilabel(app.loadeddataPanel);
            app.group2ListBoxLabel.Text = 'group 2';
            app.C2Cgroup2ListBox = uilistbox(app.loadeddataPanel);
            app.C2Cgroup2ListBox.Items = {};
            app.C2Cgroup2ListBox.Multiselect = 'on';
            app.C2Cgroup2ListBox.Value = {};
            app.C2CnormalizeCheckBox = uicheckbox(app.loadeddataPanel);
            app.C2CnormalizeCheckBox.Text = 'normalize';
            app.C2CestimateClusterstatisticButton = uibutton(app.loadeddataPanel, 'push');
            app.C2CestimateClusterstatisticButton.ButtonPushedFcn = createCallbackFcn(app, @C2CestimateClusterstatisticButtonPushed, true);
            app.C2CestimateClusterstatisticButton.Text = 'estimate Cluster statistic';
            app.C2CstatisticButtonGroup = uibuttongroup(app.loadeddataPanel);
            app.C2CstatisticButtonGroup.Title = 'statistic';
            app.C2CtwosampleButton = uitogglebutton(app.C2CstatisticButtonGroup);
            app.C2CtwosampleButton.Text = 'two-sample';
            app.C2CtwosampleButton.Value = true;
            app.C2CpairedButton = uitogglebutton(app.C2CstatisticButtonGroup);
            app.C2CpairedButton.Text = 'paired';
            app.C2CestimatedataLamp = uilamp(app.loadeddataPanel);
            app.C2CDatatouseButtonGroup = uibuttongroup(app.loadeddataPanel);
            app.C2CDatatouseButtonGroup.Title = 'Data to use';
            app.C2CYButton = uiradiobutton(app.C2CDatatouseButtonGroup);
            app.C2CYButton.Text = 'Y';
            app.C2CYButton.Value = true;
            app.C2CYc1Button = uiradiobutton(app.C2CDatatouseButtonGroup);
            app.C2CYc1Button.Text = 'Yc1';
            app.C2CYc2Button = uiradiobutton(app.C2CDatatouseButtonGroup);
            app.C2CYc2Button.Text = 'Yc2';
            app.C2CestimateClusterstatisticButton_2 = uibutton(app.loadeddataPanel, 'push');
            app.C2CestimateClusterstatisticButton_2.ButtonPushedFcn = createCallbackFcn(app, @C2CestimateClusterstatisticButton_2Pushed, true);
            app.C2CestimateClusterstatisticButton_2.Text = 'estimate Cluster statistic';
            app.C2CloaddataButton_2 = uibutton(app.loadeddataPanel, 'push');
            app.C2CloaddataButton_2.ButtonPushedFcn = createCallbackFcn(app, @C2CloaddataButton_2Pushed, true);
            app.C2CloaddataButton_2.Text = 'load data';
            app.C2CshownetworksButton = uibutton(app.Cluster2Cluster, 'push');
            app.C2CshownetworksButton.ButtonPushedFcn = createCallbackFcn(app, @C2CshownetworksButtonPushed, true);
            app.C2CshownetworksButton.Text = 'show networks';
            app.sbviewresultsPanel = uipanel(app.Cluster2Cluster);
            app.sbviewresultsPanel.Title = 'sb view results';
            app.connectivityresultsPanel = uipanel(app.sbviewresultsPanel);
            app.connectivityresultsPanel.Title = 'connectivity results';
            app.GCviewresultsButton = uibutton(app.connectivityresultsPanel, 'push');
            app.GCviewresultsButton.ButtonPushedFcn = createCallbackFcn(app, @GCviewresultsButtonPushed, true);
            app.GCviewresultsButton.Text = 'GC view results';
            app.ASDFTEviewresultsButton = uibutton(app.connectivityresultsPanel, 'push');
            app.ASDFTEviewresultsButton.ButtonPushedFcn = createCallbackFcn(app, @ASDFTEviewresultsButtonPushed, true);
            app.ASDFTEviewresultsButton.Text = 'ASDF TE view results';
            app.C2CloaddataLamp_GCsb = uilamp(app.connectivityresultsPanel);
            app.C2CloaddataLamp_ASDFsb = uilamp(app.connectivityresultsPanel);
            app.C2CmeanZdiffButton = uibutton(app.connectivityresultsPanel, 'push');
            app.C2CmeanZdiffButton.ButtonPushedFcn = createCallbackFcn(app, @C2CmeanZdiffButtonPushed, true);
            app.C2CmeanZdiffButton.Text = 'Correlation results';
            app.C2CloaddataLamp_Corrsb = uilamp(app.connectivityresultsPanel);
            app.C2CloaddataLamp_schreiber_sb = uilamp(app.connectivityresultsPanel);
            app.TEleeetalviewresultsButton = uibutton(app.connectivityresultsPanel, 'push');
            app.TEleeetalviewresultsButton.ButtonPushedFcn = createCallbackFcn(app, @TEleeetalviewresultsButtonPushed, true);
            app.TEleeetalviewresultsButton.Text = 'TE lee et al view results';
            app.C2CloaddataLamp_TE_trentool_results = uilamp(app.connectivityresultsPanel);
            app.TETRENToolresultsButton = uibutton(app.connectivityresultsPanel, 'push');
            app.TETRENToolresultsButton.ButtonPushedFcn = createCallbackFcn(app, @TETRENToolresultsButtonPushed, true);
            app.TETRENToolresultsButton.Text = 'TE TRENTool results';
            app.settingsButtonGroup = uibuttongroup(app.sbviewresultsPanel);
            app.settingsButtonGroup.Title = 'settings';
            app.sButton = uitogglebutton(app.settingsButtonGroup);
            app.sButton.Text = '2s';
            app.sButton.Value = true;
            app.pairedButton = uitogglebutton(app.settingsButtonGroup);
            app.pairedButton.Text = 'paired';
            app.pvalueEditFieldLabel = uilabel(app.settingsButtonGroup);
            app.pvalueEditFieldLabel.Text = 'pvalue';
            app.pvalue_brain_conn_EditField = uieditfield(app.settingsButtonGroup, 'numeric');
            app.pvalue_brain_conn_EditField.Limits = [0 1];
            app.pvalue_brain_conn_EditField.Value = 0.05;
            app.nonparametricstatisticsCheckBoxsb = uicheckbox(app.settingsButtonGroup);
            app.nonparametricstatisticsCheckBoxsb.Text = 'nonparametric statistics';
            app.PermutationsLabel = uilabel(app.settingsButtonGroup);
            app.PermutationsLabel.Text = 'Permutations: ';
            app.perm_brain_conn_EditField = uieditfield(app.settingsButtonGroup, 'numeric');
            app.perm_brain_conn_EditField.Limits = [1000 1000000];
            app.perm_brain_conn_EditField.Value = 5000;
            app.BehaviorvsBrainconnectivitywithEXELdataPanel = uipanel(app.Cluster2Cluster);
            app.BehaviorvsBrainconnectivitywithEXELdataPanel.Title = 'Behavior vs. Brain connectivity with EXEL data';
            app.ExelFileEditFieldLabel = uilabel(app.BehaviorvsBrainconnectivitywithEXELdataPanel);
            app.ExelFileEditFieldLabel.Text = 'Exel File';
            app.ExelFileEditField = uieditfield(app.BehaviorvsBrainconnectivitywithEXELdataPanel, 'text');
            app.ExelFileEditField.Value = 'G:\Google Drive\paper\02_Vesti_bilateral\Vertigo_Behavioral20180321.xlsx';
            app.C2CchooseexelfileButton = uibutton(app.BehaviorvsBrainconnectivitywithEXELdataPanel, 'push');
            app.C2CchooseexelfileButton.ButtonPushedFcn = createCallbackFcn(app, @C2CchooseexelfileButtonPushed, true);
            app.C2CchooseexelfileButton.Text = '...';
            app.C2CexelreadmeButton = uibutton(app.BehaviorvsBrainconnectivitywithEXELdataPanel, 'push');
            app.C2CexelreadmeButton.ButtonPushedFcn = createCallbackFcn(app, @C2CexelreadmeButtonPushed, true);
            app.C2CexelreadmeButton.Text = 'readme';
            app.loadexelfileButton = uibutton(app.BehaviorvsBrainconnectivitywithEXELdataPanel, 'push');
            app.loadexelfileButton.ButtonPushedFcn = createCallbackFcn(app, @loadexelfileButtonPushed, true);
            app.loadexelfileButton.Text = 'load exel file';
            app.ExelvariablesListBoxLabel = uilabel(app.BehaviorvsBrainconnectivitywithEXELdataPanel);
            app.ExelvariablesListBoxLabel.Text = 'Exel variables';
            app.ExelvariablesListBox = uilistbox(app.BehaviorvsBrainconnectivitywithEXELdataPanel);
            app.ExelvariablesListBox.Items = {};
            app.ExelvariablesListBox.Multiselect = 'on';
            app.ExelvariablesListBox.Value = {};
            app.C2CestimatecorrelationButton = uibutton(app.BehaviorvsBrainconnectivitywithEXELdataPanel, 'push');
            app.C2CestimatecorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @C2CestimatecorrelationButtonPushed, true);
            app.C2CestimatecorrelationButton.Text = 'estimate correlation';
            app.visualizecorrelationButton = uibutton(app.BehaviorvsBrainconnectivitywithEXELdataPanel, 'push');
            app.visualizecorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @visualizecorrelationButtonPushed, true);
            app.visualizecorrelationButton.Text = 'visualize correlation';
            app.C2CestimateexelcorrelationLamp = uilamp(app.BehaviorvsBrainconnectivitywithEXELdataPanel);
            app.C2CvisualizenetworkcorrelationButton = uibutton(app.BehaviorvsBrainconnectivitywithEXELdataPanel, 'push');
            app.C2CvisualizenetworkcorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @C2CvisualizenetworkcorrelationButtonPushed, true);
            app.C2CvisualizenetworkcorrelationButton.Text = 'visualize network correlation';
            app.C2CestimatenetworkcorrelationButton = uibutton(app.BehaviorvsBrainconnectivitywithEXELdataPanel, 'push');
            app.C2CestimatenetworkcorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @C2CestimatenetworkcorrelationButtonPushed, true);
            app.C2CestimatenetworkcorrelationButton.Text = 'estimate network correlation';
            app.C2CvisualizenetworkcorrelationButton_v3 = uibutton(app.BehaviorvsBrainconnectivitywithEXELdataPanel, 'push');
            app.C2CvisualizenetworkcorrelationButton_v3.ButtonPushedFcn = createCallbackFcn(app, @C2CvisualizenetworkcorrelationButton_v3Pushed, true);
            app.C2CvisualizenetworkcorrelationButton_v3.Text = 'visualize v.0.3 experimental';
            app.Cluster2Voxel = uitab(app.TabGroup2);
            app.Cluster2Voxel.Title = 'Cluster2Voxel';
            app.Voxel2Voxel = uitab(app.TabGroup2);
            app.Voxel2Voxel.Title = 'Voxel2Voxel';
            app.Cluster2Cluster_Granger = uitab(app.TabGroup2);
            app.Cluster2Cluster_Granger.Title = 'Cluster2Cluster_Granger';
            app.GlobalParameterPanel = uipanel(app.UIFigure);
            app.GlobalParameterPanel.Title = 'Global Parameter';
            app.resultsrootdirEditFieldLabel = uilabel(app.GlobalParameterPanel);
            app.resultsrootdirEditFieldLabel.Text = 'results root dir';
            app.resultsdirEditField = uieditfield(app.GlobalParameterPanel, 'text');
            app.resultsdirEditField.Value = 'H:\data_ck\Vesti\analyse\out3';
            app.Buttonchooseresultsdir = uibutton(app.GlobalParameterPanel, 'push');
            app.Buttonchooseresultsdir.ButtonPushedFcn = createCallbackFcn(app, @ButtonchooseresultsdirPushed, true);
            app.Buttonchooseresultsdir.Text = '...';
            app.Buttonchooseg1dir = uibutton(app.GlobalParameterPanel, 'push');
            app.Buttonchooseg1dir.ButtonPushedFcn = createCallbackFcn(app, @Buttonchooseg1dirPushed, true);
            app.Buttonchooseg1dir.Text = '...';
            app.group1dirEditFieldLabel = uilabel(app.GlobalParameterPanel);
            app.group1dirEditFieldLabel.Text = 'group 1 dir';
            app.g1dirEditField = uieditfield(app.GlobalParameterPanel, 'text');
            app.g1dirEditField.Value = 'H:\data_ck\Vesti\analyse\out3\VB';
            app.chooseg2dir = uibutton(app.GlobalParameterPanel, 'push');
            app.chooseg2dir.ButtonPushedFcn = createCallbackFcn(app, @chooseg2dirButtonPushed, true);
            app.chooseg2dir.Text = '...';
            app.group2dirEditFieldLabel = uilabel(app.GlobalParameterPanel);
            app.group2dirEditFieldLabel.Text = 'group 2 dir';
            app.g2dirEditField = uieditfield(app.GlobalParameterPanel, 'text');
            app.g2dirEditField.Value = 'H:\data_ck\Vesti\analyse\out3\H';
            app.loadlastButton = uibutton(app.GlobalParameterPanel, 'push');
            app.loadlastButton.ButtonPushedFcn = createCallbackFcn(app, @loadlastButtonPushed, true);
            app.loadlastButton.Text = 'load last';
            app.loadButton = uibutton(app.GlobalParameterPanel, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.saveButton = uibutton(app.GlobalParameterPanel, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.MultiprocessoroptionsCheckBox = uicheckbox(app.GlobalParameterPanel);
            app.MultiprocessoroptionsCheckBox.Text = 'Multiprocessor options';
            app.initnewButton = uibutton(app.GlobalParameterPanel, 'push');
            app.initnewButton.ButtonPushedFcn = createCallbackFcn(app, @initnewButtonPushed, true);
            app.initnewButton.Text = 'init/new';
            app.idleGaugeLabel = uilabel(app.GlobalParameterPanel);
            app.idleGaugeLabel.Text = 'idle';
            app.idleGauge = uigauge(app.GlobalParameterPanel, 'linear');
            app.idleGauge.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.idleGauge.MinorTicks = [];
            app.saveasButton = uibutton(app.GlobalParameterPanel, 'push');
            app.saveasButton.ButtonPushedFcn = createCallbackFcn(app, @saveasButtonPushed, true);
            app.saveasButton.Text = 'save as';
            app.g1ListBox = uilistbox(app.UIFigure);
            app.g1ListBox.Items = {};
            app.g1ListBox.Multiselect = 'on';
            app.g1ListBox.Value = {};
            app.g2ListBox = uilistbox(app.UIFigure);
            app.g2ListBox.Items = {};
            app.g2ListBox.Multiselect = 'on';
            app.g2ListBox.Value = {};
            app.group1directoryLabel = uilabel(app.UIFigure);
            app.group1directoryLabel.Text = 'group 1 directory';
            app.group2directoryLabel = uilabel(app.UIFigure);
            app.group2directoryLabel.Text = 'group 2 directory';
            app.updateButton = uibutton(app.UIFigure, 'push');
            app.updateButton.ButtonPushedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.updateButton.Text = 'update';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ck_rc3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end